classdef PHASE_Preprocessing < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Image2                          matlab.ui.control.Image
        Image                           matlab.ui.control.Image
        Label                           matlab.ui.control.Label
        ConstellationSwitch             matlab.ui.control.Switch
        Sentinel1Panel                  matlab.ui.container.Panel
        TabGroup                        matlab.ui.container.TabGroup
        DownloadTab                     matlab.ui.container.Tab
        DownloaderToolbarPanel          matlab.ui.container.Panel
        LoadLastDownloadButton          matlab.ui.control.Button
        SearchASFButton                 matlab.ui.control.Button
        ClearAOIButton                  matlab.ui.control.Button
        LoadShapefileButton             matlab.ui.control.Button
        DrawPolygonButton               matlab.ui.control.Button
        DrawRectangleButton             matlab.ui.control.Button
        ShowFiltersButton               matlab.ui.control.Button
        SignOutButton                   matlab.ui.control.Button
        SignedInLabel                   matlab.ui.control.Label
        LoginFeedbackLabel              matlab.ui.control.Label
        LoginButton                     matlab.ui.control.Button
        EarthdataPasswordEditField      matlab.ui.control.EditField
        EarthdataUsernameEditField      matlab.ui.control.EditField
        MaxLatitudeEditField            matlab.ui.control.NumericEditField
        MaxLatitudeEditFieldLabel       matlab.ui.control.Label
        MinLatitudeEditField            matlab.ui.control.NumericEditField
        MinLatitudeEditFieldLabel       matlab.ui.control.Label
        MaxLongitudeEditField           matlab.ui.control.NumericEditField
        MaxLongitudeEditFieldLabel      matlab.ui.control.Label
        MinLongitudeEditField           matlab.ui.control.NumericEditField
        MinLongitudeEditFieldLabel      matlab.ui.control.Label
        FilterPanel                     matlab.ui.container.Panel
        SamplingUnitDropDown            matlab.ui.control.DropDown
        SamplingRateEditField           matlab.ui.control.NumericEditField
        SamplingRateEditFieldLabel      matlab.ui.control.Label
        ResetallfiltersButton           matlab.ui.control.Button
        GroupIDEditField                matlab.ui.control.NumericEditField
        GroupIDEditFieldLabel           matlab.ui.control.Label
        SDCheckBox                      matlab.ui.control.CheckBox
        SCCheckBox                      matlab.ui.control.CheckBox
        SBCheckBox                      matlab.ui.control.CheckBox
        SACheckBox                      matlab.ui.control.CheckBox
        SubtypeLabel                    matlab.ui.control.Label
        DualVVCheckBox                  matlab.ui.control.CheckBox
        DualVHCheckBox                  matlab.ui.control.CheckBox
        DualHVCheckBox                  matlab.ui.control.CheckBox
        DualHHCheckBox                  matlab.ui.control.CheckBox
        HHHVCheckBox                    matlab.ui.control.CheckBox
        VVHHCheckBox                    matlab.ui.control.CheckBox
        HHCheckBox                      matlab.ui.control.CheckBox
        VVCheckBox                      matlab.ui.control.CheckBox
        PolarizationLabel               matlab.ui.control.Label
        WVCheckBox                      matlab.ui.control.CheckBox
        S6CheckBox                      matlab.ui.control.CheckBox
        S5CheckBox                      matlab.ui.control.CheckBox
        S4CheckBox                      matlab.ui.control.CheckBox
        S3CheckBox                      matlab.ui.control.CheckBox
        S2CheckBox                      matlab.ui.control.CheckBox
        S1CheckBox                      matlab.ui.control.CheckBox
        EWCheckBox                      matlab.ui.control.CheckBox
        IWCheckBox                      matlab.ui.control.CheckBox
        BeamModeLabel                   matlab.ui.control.Label
        DESCENDINGCheckBox              matlab.ui.control.CheckBox
        ASCENDINGCheckBox               matlab.ui.control.CheckBox
        OrbitDirectionLabel             matlab.ui.control.Label
        XMLMetadataOCNCheckBox          matlab.ui.control.CheckBox
        XMLMetadataSLCCheckBox          matlab.ui.control.CheckBox
        XMLMetadataGRDMDCheckBox        matlab.ui.control.CheckBox
        XMLMetadataGRDHSCheckBox        matlab.ui.control.CheckBox
        XMLMetadataRAWCheckBox          matlab.ui.control.CheckBox
        XMLMetadataGRDHDCheckBox        matlab.ui.control.CheckBox
        XMLMetadataGRDMSCheckBox        matlab.ui.control.CheckBox
        L0RawDataRAWCheckBox            matlab.ui.control.CheckBox
        L2OceanOCNCheckBox              matlab.ui.control.CheckBox
        L1SingleLookComplexSLCCheckBox  matlab.ui.control.CheckBox
        L1DetectedHighResSinglePolGRDHSCheckBox  matlab.ui.control.CheckBox
        L1DetectedMidResSinglePolGRDMSCheckBox  matlab.ui.control.CheckBox
        L1DetectedMidResDualPolGRDMDCheckBox  matlab.ui.control.CheckBox
        L1DetectedHighResDualPolGRDHDCheckBox  matlab.ui.control.CheckBox
        FileTypeLabel                   matlab.ui.control.Label
        ClearPathFrameButton            matlab.ui.control.Button
        EnddateDatePicker               matlab.ui.control.DatePicker
        EnddateDatePickerLabel          matlab.ui.control.Label
        StartdateDatePicker             matlab.ui.control.DatePicker
        StartdateDatePickerLabel        matlab.ui.control.Label
        DatasetDropDown                 matlab.ui.control.DropDown
        DatasetDropDownLabel            matlab.ui.control.Label
        FrameEndEditField               matlab.ui.control.NumericEditField
        FrameEndEditFieldLabel          matlab.ui.control.Label
        FrameStartEditField             matlab.ui.control.NumericEditField
        FrameStartEditFieldLabel        matlab.ui.control.Label
        PathEndEditField                matlab.ui.control.NumericEditField
        PathEndEditFieldLabel           matlab.ui.control.Label
        PathStartEditField              matlab.ui.control.NumericEditField
        PathStartEditFieldLabel         matlab.ui.control.Label
        DownloaderMapPanel              matlab.ui.container.Panel
        GlobalVariablesTab_3            matlab.ui.container.Tab
        SARImagesDownloadCheckBox       matlab.ui.control.CheckBox
        PythonEnvironmentDropDown       matlab.ui.control.DropDown
        PythonEnvironmentDropDownLabel  matlab.ui.control.Label
        Label_2                         matlab.ui.control.Label
        CustomPythonEnvironmentEditField  matlab.ui.control.EditField
        PythonEnvironmentLabel          matlab.ui.control.Label
        AOITab_2                        matlab.ui.container.Tab
        SENMapPanel                     matlab.ui.container.Panel
        ResetSENMapButton               matlab.ui.control.Button
        AOIboxboundariesLabel_3         matlab.ui.control.Label
        MaxlatitudeEditField            matlab.ui.control.NumericEditField
        MaxlatitudeEditFieldLabel       matlab.ui.control.Label
        MinlatitudeEditField            matlab.ui.control.NumericEditField
        MinlatitudeEditFieldLabel       matlab.ui.control.Label
        MaxlongitudeEditField           matlab.ui.control.NumericEditField
        MaxlongitudeEditFieldLabel      matlab.ui.control.Label
        MinlongitudeEditField           matlab.ui.control.NumericEditField
        MinlongitudeEditFieldLabel      matlab.ui.control.Label
        MasterProcessingTab             matlab.ui.container.Tab
        AutoMasterCheckBox              matlab.ui.control.CheckBox
        PolarisationDropDown            matlab.ui.control.DropDown
        PolarisationDropDownLabel       matlab.ui.control.Label
        MasterprocessingCheckBox        matlab.ui.control.CheckBox
        MasterdateDatePicker            matlab.ui.control.DatePicker
        MasterdateDatePickerLabel       matlab.ui.control.Label
        SlavesProcessingTab             matlab.ui.container.Tab
        BrowseButton_2                  matlab.ui.control.Button
        BrowseButton                    matlab.ui.control.Button
        FirststepDropDown               matlab.ui.control.DropDown
        FirststepDropDownLabel          matlab.ui.control.Label
        onlyforExternalDEMLabel_2       matlab.ui.control.Label
        onlyforExternalDEMLabel         matlab.ui.control.Label
        DEMresamplingmethodDropDown     matlab.ui.control.DropDown
        DEMresamplingmethodDropDownLabel  matlab.ui.control.Label
        DEMcoregpathEditField           matlab.ui.control.EditField
        DEMcoregpathEditFieldLabel      matlab.ui.control.Label
        DEMifgpathEditField             matlab.ui.control.EditField
        DEMifgpathEditFieldLabel        matlab.ui.control.Label
        DEMcoregistrationDropDown       matlab.ui.control.DropDown
        DEMcoregistrationDropDownLabel  matlab.ui.control.Label
        DEMinterferogramDropDown        matlab.ui.control.DropDown
        DEMinterferogramDropDownLabel   matlab.ui.control.Label
        SlavesremovalafterprocessingCheckBox  matlab.ui.control.CheckBox
        CoherenceandLIATab              matlab.ui.container.Tab
        Label_3                         matlab.ui.control.Label
        EPSGcodeEditField               matlab.ui.control.NumericEditField
        EPSGcodeEditFieldLabel          matlab.ui.control.Label
        TerraincorrectedCoherenceandLIACheckBox  matlab.ui.control.CheckBox
        ComputationalResourcesTab       matlab.ui.container.Tab
        CacheEditField                  matlab.ui.control.EditField
        CacheEditFieldLabel             matlab.ui.control.Label
        RAMtobeusedintheprocessingGBformatnnGLabel  matlab.ui.control.Label
        CPUEditField                    matlab.ui.control.NumericEditField
        CPUEditFieldLabel               matlab.ui.control.Label
        NumberofcorestobeusedintheprocessingLabel  matlab.ui.control.Label
        PathEditField                   matlab.ui.control.EditField
        PathEditFieldLabel              matlab.ui.control.Label
        FullpathtoSNAPgptfolderLabel    matlab.ui.control.Label
        SaveLoadTab                     matlab.ui.container.Tab
        StatusLamp_3                    matlab.ui.control.Lamp
        StatusLamp_3Label               matlab.ui.control.Label
        LoadButton                      matlab.ui.control.Button
        SavetheconfiguredparametersfortheInSARpreprocessingLabel_3  matlab.ui.control.Label
        StatusLamp                      matlab.ui.control.Lamp
        StatusLampLabel                 matlab.ui.control.Label
        SavetheconfiguredparametersfortheInSARpreprocessingLabel  matlab.ui.control.Label
        SaveButton                      matlab.ui.control.Button
        RunTab                          matlab.ui.container.Tab
        ThecodewillstopattheendofthecurrentstepLabel_2  matlab.ui.control.Label
        StopButton                      matlab.ui.control.Button
        MessagesTextArea                matlab.ui.control.TextArea
        MessagesTextArea_3Label         matlab.ui.control.Label
        PreprocessingstatusLamp         matlab.ui.control.Lamp
        PreprocessingstatusLampLabel    matlab.ui.control.Label
        Label_7                         matlab.ui.control.Label
        StartButton                     matlab.ui.control.Button
        Label_6                         matlab.ui.control.Label
        CosmoSkyMedPanel                matlab.ui.container.Panel
        TabGroup2                       matlab.ui.container.TabGroup
        GlobalVariablesTab_4            matlab.ui.container.Tab
        PythonEnvironmentDropDown_2     matlab.ui.control.DropDown
        PythonEnvironmentDropDown_2Label  matlab.ui.control.Label
        Label_4                         matlab.ui.control.Label
        CustomPythonEnvironmentEditField_2  matlab.ui.control.EditField
        CustomPythonEnvironmentLabel    matlab.ui.control.Label
        AOITab                          matlab.ui.container.Tab
        AOIboxboundariesLabel_2         matlab.ui.control.Label
        MinlatitudeEditField_2          matlab.ui.control.NumericEditField
        MinlatitudeEditField_2Label     matlab.ui.control.Label
        MaxlatitudeEditField_2          matlab.ui.control.NumericEditField
        MaxlatitudeEditField_2Label     matlab.ui.control.Label
        MaxlongitudeEditField_2         matlab.ui.control.NumericEditField
        MaxlongitudeEditField_2Label    matlab.ui.control.Label
        MinlongitudeEditField_2         matlab.ui.control.NumericEditField
        MinlongitudeEditField_2Label    matlab.ui.control.Label
        DrawCSKAOIButton                matlab.ui.control.Button
        Panel                           matlab.ui.container.Panel
        MasterProcessingTab_2           matlab.ui.container.Tab
        AutoMasterCheckBox_2            matlab.ui.control.CheckBox
        MasterprocessingCheckBox_2      matlab.ui.control.CheckBox
        MasterdateDatePicker_2          matlab.ui.control.DatePicker
        MasterdateDatePicker_2Label     matlab.ui.control.Label
        SlavesProcessingTab_2           matlab.ui.container.Tab
        BrowseButton_3                  matlab.ui.control.Button
        CoregistrationGCPsnumberEditField  matlab.ui.control.NumericEditField
        CoregistrationGCPsnumberEditFieldLabel  matlab.ui.control.Label
        FirststepDropDown_2             matlab.ui.control.DropDown
        FirststepDropDown_2Label        matlab.ui.control.Label
        onlyforExternalDEMLabel_3       matlab.ui.control.Label
        DEMifgpathEditField_2           matlab.ui.control.EditField
        DEMifgpathEditField_2Label      matlab.ui.control.Label
        DEMinterferogramDropDown_2      matlab.ui.control.DropDown
        DEMinterferogramDropDown_2Label  matlab.ui.control.Label
        SlavesremovalafterprocessingCheckBox_2  matlab.ui.control.CheckBox
        CoherenceandLIATab_2            matlab.ui.container.Tab
        Label_5                         matlab.ui.control.Label
        EPSGcodeEditField_2             matlab.ui.control.NumericEditField
        EPSGcodeEditField_2Label        matlab.ui.control.Label
        TerraincorrectedCoherenceandLIACheckBox_2  matlab.ui.control.CheckBox
        ComputationalResourcesTab_2     matlab.ui.container.Tab
        CacheEditField_2                matlab.ui.control.EditField
        CacheEditField_2Label           matlab.ui.control.Label
        RAMtobeusedintheprocessingGBformatnnGLabel_2  matlab.ui.control.Label
        CPUEditField_2                  matlab.ui.control.NumericEditField
        CPUEditField_2Label             matlab.ui.control.Label
        NumberofcorestobeusedintheprocessingLabel_2  matlab.ui.control.Label
        PathEditField_2                 matlab.ui.control.EditField
        PathEditField_2Label            matlab.ui.control.Label
        FullpathtoSNAPgptfolderLabel_2  matlab.ui.control.Label
        SaveLoadTab_2                   matlab.ui.container.Tab
        StatusLamp_4                    matlab.ui.control.Lamp
        StatusLamp_4Label               matlab.ui.control.Label
        SavetheconfiguredparametersfortheInSARpreprocessingLabel_4  matlab.ui.control.Label
        LoadButton_2                    matlab.ui.control.Button
        StatusLamp_2                    matlab.ui.control.Lamp
        StatusLamp_2Label               matlab.ui.control.Label
        SavetheconfiguredparametersfortheInSARpreprocessingLabel_2  matlab.ui.control.Label
        SaveButton_2                    matlab.ui.control.Button
        RunTab_2                        matlab.ui.container.Tab
        ThecodewillstopattheendofthecurrentstepLabel  matlab.ui.control.Label
        StopButton_2                    matlab.ui.control.Button
        MessagesTextArea_2              matlab.ui.control.TextArea
        MessagesTextAreaLabel           matlab.ui.control.Label
        PreprocessingstatusLamp_2       matlab.ui.control.Lamp
        PreprocessingstatusLamp_2Label  matlab.ui.control.Label
        Label_9                         matlab.ui.control.Label
        StartButton_2                   matlab.ui.control.Button
        Label_8                         matlab.ui.control.Label
        ConstellationSwitchLabel        matlab.ui.control.Label
        ContextMenu                     matlab.ui.container.ContextMenu
        Menu                            matlab.ui.container.Menu
        Menu2                           matlab.ui.container.Menu
    end

    
    properties (Access = private)
        
        % Variables initialization with a default value
        constellation = 'SEN';
        StopFlag = false;

        roi_CSK  % Stores the red box for Cosmo-SkyMed
        roi_SEN  % Stores the red box for Sentinel-1

        % Sentinel-1 case
        os_SEN = 0;        
        python_SEN = 'python';
        images_download_SEN = 1;
        master_date_SEN = '20200722';
        auto_master_SEN = 1;
        master_processing_SEN = 0;
        polarisation_SEN = 'VV';
        lon_min_SEN = -180.000;
        lat_min_SEN = -90.000;
        lon_max_SEN = 180.000;
        lat_max_SEN = 90.000;
        slaves_removal_SEN = 1;
        dem_name_SEN = 'SRTM 1Sec HGT';
        dem_file_SEN = '';
        dem_name_coreg_SEN = 'SRTM 1Sec HGT';
        dem_file_coreg_SEN = '';
        dem_resampling_SEN = 'NEAREST_NEIGHBOUR';
        first_step_SEN = 1;
        coherence_tc_SEN = 0;
        epsg_code_SEN = 32633;
        gptbin_path_SEN = 'C:\Program Files\snap\bin\gpt';
        cpu_SEN = 8;
        cache_SEN = '26G';

        % Cosmo-SkyMed case
        os_CSK = 0;        
        python_CSK = 'python';
        master_date_CSK = '20200722';
        auto_master_CSK = 1;
        master_processing_CSK = 0;
        lon_min_CSK = -180.000;
        lat_min_CSK = -90.000;
        lon_max_CSK = 180.000;
        lat_max_CSK = 90.000;
        slaves_removal_CSK = 1;
        dem_name_CSK = 'SRTM 1Sec HGT';
        dem_file_CSK = '';
        num_gcp_CSK = 10000;
        first_step_CSK = 1;
        coherence_tc_CSK = 0;
        epsg_code_CSK = 32633;
        gptbin_path_CSK = 'C:\Program Files\snap\bin\gpt';
        cpu_CSK = 8;
        cache_CSK = '26G';

        % Downloader map
        roi_Downloader
        DownloaderAOIType = ""
        DownloaderAOICorners = []
        DownloaderPolygonCoords = []
        DownloaderGeoAxes

    end
    
    methods (Access = private)
        
        function updateOutput(app, message)   % Function to mirror the command window

            if app.constellation == "SEN"

                % Append the message to the existing text in the TextArea
                currentText = app.MessagesTextArea.Value;
                newText = [currentText; message];
                app.MessagesTextArea.Value = newText;

            elseif app.constellation == "CSK"

                % Append the message to the existing text in the TextArea
                currentText = app.MessagesTextArea_2.Value;
                newText = [currentText; message];
                app.MessagesTextArea_2.Value = newText;

            end
            
        end

        % Downloader parameters

        function params = getDownloaderParams(app)

            params.repository = "ASF";
            params.dataset = app.DatasetDropDown.Value;
        
            params.processingLevel = getSelectedFileTypes(app);
            params.beamMode = getSelectedBeamModes(app);
            params.polarization = getSelectedPolarizations(app);
            params.flightDirection = getSelectedOrbitDirections(app);
            params.subtype = getSelectedSubtypes(app);
        
            params.startDate = optionalDate(app, app.StartdateDatePicker.Value);
            params.endDate = optionalDate(app, app.EnddateDatePicker.Value);
        
            params.pathStart = optionalNumber(app, app.PathStartEditField.Value);
            params.pathEnd = optionalNumber(app, app.PathEndEditField.Value);
            params.frameStart = optionalNumber(app, app.FrameStartEditField.Value);
            params.frameEnd = optionalNumber(app, app.FrameEndEditField.Value);
            params.groupID = optionalNumber(app, app.GroupIDEditField.Value);
            params.sampling.rate = optionalNumber(app, app.SamplingRateEditField.Value);

            if app.DownloaderAOIType == "rectangle"
                params.aoi.type = "rectangle";
                params.aoi.corners = app.DownloaderAOICorners; 
            elseif app.DownloaderAOIType == "polygon"
                params.aoi.type = "polygon";
                params.aoi.corners = app.DownloaderPolygonCoords;
            else
                params.aoi.type = "";
                params.aoi.corners = [];
            end


            if isempty(params.sampling.rate)
                params.sampling.unit = [];
            else
                params.sampling.unit = string(app.SamplingUnitDropDown.Value);
            end
        
        end

        % Get filetype

        function selected = getSelectedFileTypes(app)
        
            selected = {};
        
            if app.L1DetectedHighResDualPolGRDHDCheckBox.Value
                selected{end+1} = "GRD-HD";
            end
            if app.L1DetectedMidResDualPolGRDMDCheckBox.Value
                selected{end+1} = "GRD-MD";
            end
            if app.L1DetectedMidResSinglePolGRDMSCheckBox.Value
                selected{end+1} = "GRD-MS";
            end
            if app.L1DetectedHighResSinglePolGRDHSCheckBox.Value
                selected{end+1} = "GRD-HS";
            end
            if app.L1SingleLookComplexSLCCheckBox.Value
                selected{end+1} = "SLC";
            end
            if app.L2OceanOCNCheckBox.Value
                selected{end+1} = "OCN";
            end
            if app.L0RawDataRAWCheckBox.Value
                selected{end+1} = "RAW";
            end
            if app.XMLMetadataGRDMSCheckBox.Value
                selected{end+1} = "XML_GRD-MS";
            end
            if app.XMLMetadataGRDHDCheckBox.Value
                selected{end+1} = "XML_GRD-HD";
            end
            if app.XMLMetadataRAWCheckBox.Value
                selected{end+1} = "XML_RAW";
            end
            if app.XMLMetadataGRDHSCheckBox.Value
                selected{end+1} = "XML_GRD-HS";
            end
            if app.XMLMetadataGRDMDCheckBox.Value
                selected{end+1} = "XML_GRD-MD";
            end
            if app.XMLMetadataSLCCheckBox.Value
                selected{end+1} = "XML_SLC";
            end
            if app.XMLMetadataOCNCheckBox.Value
                selected{end+1} = "XML_OCN";
            end
        
        end

        % Get beam mode

        function selected = getSelectedBeamModes(app)

            selected = {};
        
            if app.IWCheckBox.Value
                selected{end+1} = "IW";
            end
            if app.EWCheckBox.Value
                selected{end+1} = "EW";
            end
            if app.S1CheckBox.Value
                selected{end+1} = "S1";
            end
            if app.S2CheckBox.Value
                selected{end+1} = "S2";
            end
            if app.S3CheckBox.Value
                selected{end+1} = "S3";
            end
            if app.S4CheckBox.Value
                selected{end+1} = "S4";
            end
            if app.S5CheckBox.Value
                selected{end+1} = "S5";
            end
            if app.S6CheckBox.Value
                selected{end+1} = "S6";
            end
            if app.WVCheckBox.Value
                selected{end+1} = "WV";
            end
        
        end

        % Get polarization

        function selected = getSelectedPolarizations(app)
        
            selected = {};
        
            if app.VVCheckBox.Value
                selected{end+1} = "VV";
            end
            if app.HHCheckBox.Value
                selected{end+1} = "HH";
            end
            if app.VVHHCheckBox.Value
                selected{end+1} = "VV+HH";
            end
            if app.HHHVCheckBox.Value
                selected{end+1} = "HH+HV";
            end
            if app.DualHHCheckBox.Value
                selected{end+1} = "Dual HH";
            end
            if app.DualHVCheckBox.Value
                selected{end+1} = "Dual HV";
            end
            if app.DualVHCheckBox.Value
                selected{end+1} = "Dual VH";
            end
            if app.DualVVCheckBox.Value
                selected{end+1} = "Dual VV";
            end
        
        end

        % Get orbit direction

        function selected = getSelectedOrbitDirections(app)

            selected = {};
        
            if app.ASCENDINGCheckBox.Value
                selected{end+1} = "ASCENDING";
            end
            if app.DESCENDINGCheckBox.Value
                selected{end+1} = "DESCENDING";
            end
        
        end

        %Get subtype

        function selected = getSelectedSubtypes(app)

            selected = {};
        
            if app.SACheckBox.Value
                selected{end+1} = "SA";
            end
            if app.SBCheckBox.Value
                selected{end+1} = "SB";
            end
            if app.SCCheckBox.Value
                selected{end+1} = "SC";
            end
            if app.SCCheckBox.Value
                selected{end+1} = "SD";
            end
        
        end
        
        function value = optionalDropdown(app, inputValue)
        
            if strcmpi(string(inputValue), "Any")
                value = [];
            else
                value = string(inputValue);
            end
        
        end
        
        function value = optionalNumber(app, inputValue)
        
            if isempty(inputValue) || inputValue == 0
                value = [];
            else
                value = inputValue;
            end
        
        end
        
        function value = optionalDate(app, inputValue)
        
            if isempty(inputValue) || ismissing(inputValue)
                value = [];
            else
                value = string(inputValue, "yyyy-MM-dd");
            end
        
        end

        % clear all filters
        function resetAllFilters(app)
            
            app.DatasetDropDown.Value = "SENTINEL-1";

            % file type
            app.L1DetectedHighResDualPolGRDHDCheckBox.Value = false;
            app.L1DetectedMidResDualPolGRDMDCheckBox.Value = false;
            app.L1DetectedMidResSinglePolGRDMSCheckBox.Value = false;
            app.L1DetectedHighResSinglePolGRDHSCheckBox.Value = false;
            app.L1SingleLookComplexSLCCheckBox.Value = false;
            app.L2OceanOCNCheckBox.Value = false;
            app.L0RawDataRAWCheckBox.Value = false;
        
            app.XMLMetadataGRDMSCheckBox.Value = false;
            app.XMLMetadataGRDHDCheckBox.Value = false;
            app.XMLMetadataRAWCheckBox.Value = false;
            app.XMLMetadataGRDHSCheckBox.Value = false;
            app.XMLMetadataGRDMDCheckBox.Value = false;
            app.XMLMetadataSLCCheckBox.Value = false;
            app.XMLMetadataOCNCheckBox.Value = false;
        
            % beam mode
            app.IWCheckBox.Value = false;
            app.EWCheckBox.Value = false;
            app.S1CheckBox.Value = false;
            app.S2CheckBox.Value = false;
            app.S3CheckBox.Value = false;
            app.S4CheckBox.Value = false;
            app.S5CheckBox.Value = false;
            app.S6CheckBox.Value = false;
            app.WVCheckBox.Value = false;
        
            % polarization
            app.VVCheckBox.Value = false;
            app.HHCheckBox.Value = false;
            app.VVHHCheckBox.Value = false;
            app.HHHVCheckBox.Value = false;
            app.DualHHCheckBox.Value = false;
            app.DualHVCheckBox.Value = false;
            app.DualVHCheckBox.Value = false;
            app.DualVVCheckBox.Value = false;
        
            % orbit
            app.ASCENDINGCheckBox.Value = false;
            app.DESCENDINGCheckBox.Value = false;
        
            % subtype
            app.SACheckBox.Value = false;
            app.SBCheckBox.Value = false;
            app.SCCheckBox.Value = false;
            app.SDCheckBox.Value = false;

            app.StartdateDatePicker.Value = NaT;
            app.EnddateDatePicker.Value = NaT;

            app.PathStartEditField.Value = 0;
            app.PathEndEditField.Value = 0;
            app.FrameStartEditField.Value = 0;
            app.FrameEndEditField.Value = 0;

            % sampling rate
            app.SamplingRateEditField.Value = 0;
            app.SamplingUnitDropDown.Value = "Month";

        end
        % Function to start Parameter filter with default values
        function initializeParams(app)
            

            app.DatasetDropDown.Value = "SENTINEL-1";

            % file type
            app.L1DetectedHighResDualPolGRDHDCheckBox.Value = false;
            app.L1DetectedMidResDualPolGRDMDCheckBox.Value = false;
            app.L1DetectedMidResSinglePolGRDMSCheckBox.Value = false;
            app.L1DetectedHighResSinglePolGRDHSCheckBox.Value = false;
            app.L1SingleLookComplexSLCCheckBox.Value = true;
            app.L2OceanOCNCheckBox.Value = false;
            app.L0RawDataRAWCheckBox.Value = false;
        
            app.XMLMetadataGRDMSCheckBox.Value = false;
            app.XMLMetadataGRDHDCheckBox.Value = false;
            app.XMLMetadataRAWCheckBox.Value = false;
            app.XMLMetadataGRDHSCheckBox.Value = false;
            app.XMLMetadataGRDMDCheckBox.Value = false;
            app.XMLMetadataSLCCheckBox.Value = false;
            app.XMLMetadataOCNCheckBox.Value = false;
        
            % beam mode
            app.IWCheckBox.Value = true;
            app.EWCheckBox.Value = false;
            app.S1CheckBox.Value = false;
            app.S2CheckBox.Value = false;
            app.S3CheckBox.Value = false;
            app.S4CheckBox.Value = false;
            app.S5CheckBox.Value = false;
            app.S6CheckBox.Value = false;
            app.WVCheckBox.Value = false;
        
            % polarization
            app.VVCheckBox.Value = false;
            app.HHCheckBox.Value = false;
            app.VVHHCheckBox.Value = false;
            app.HHHVCheckBox.Value = false;
            app.DualHHCheckBox.Value = false;
            app.DualHVCheckBox.Value = false;
            app.DualVHCheckBox.Value = false;
            app.DualVVCheckBox.Value = false;
        
            % orbit
            app.ASCENDINGCheckBox.Value = false;
            app.DESCENDINGCheckBox.Value = false;
        
            % subtype
            app.SACheckBox.Value = false;
            app.SBCheckBox.Value = false;
            app.SCCheckBox.Value = false;
            app.SDCheckBox.Value = false;

            app.StartdateDatePicker.Value = NaT;
            app.EnddateDatePicker.Value = NaT;

            app.PathStartEditField.Value = 0;
            app.PathEndEditField.Value = 0;
            app.FrameStartEditField.Value = 0;
            app.FrameEndEditField.Value = 0;

            % sampling rate
            app.SamplingRateEditField.Value = 0;
            app.SamplingUnitDropDown.Value = "Month";

        end

        % Function to initialize map in Downloader tab

        function initializeDownloaderMap(app)

            delete(app.DownloaderMapPanel.Children);

            app.DownloaderGeoAxes = geoaxes(app.DownloaderMapPanel);
            gx = app.DownloaderGeoAxes;
        
            geobasemap(gx, "satellite");
            gx.Interactions = [panInteraction; zoomInteraction];
        
            geolimits(gx, [35 72], [-25 45]);
        
        end

        function updateDownloaderRectangleCoords(app, roi)

            pos = roi.Position;

            % Position format used by PHASE map: [lat lon height width]
            minLat = pos(1);
            minLon = pos(2);
            maxLat = pos(1) + pos(3);
            maxLon = pos(2) + pos(4);
        
            app.MinLongitudeEditField.Value = minLon;
            app.MaxLongitudeEditField.Value = maxLon;
            app.MinLatitudeEditField.Value = minLat;
            app.MaxLatitudeEditField.Value = maxLat;
        
            app.DownloaderAOIType = "rectangle";
        
            app.DownloaderAOICorners = [
                minLon minLat;
                maxLon minLat;
                maxLon maxLat;
                minLon maxLat
            ];
        
            app.DownloaderPolygonCoords = [];
        
        end

        function updateDownloaderPolygonCoords(app, roi)

            pos = roi.Position;
        
            
            lats = pos(:,1);
            lons = pos(:,2);
        
            app.DownloaderAOIType = "polygon";
            app.DownloaderPolygonCoords = [lons lats];
        
            app.DownloaderAOICorners = [];
        
        end

        function AOIBoundaryValueChanged(app, event)

            minLon = app.MinLongitudeEditField.Value;
            maxLon = app.MaxLongitudeEditField.Value;
            minLat = app.MinLatitudeEditField.Value;
            maxLat = app.MaxLatitudeEditField.Value;
        
            if minLon >= maxLon || minLat >= maxLat
                disp("Invalid AOI boundaries.");
                return;
            end
        
            clearDownloaderAOIShape(app);
        
           
            app.roi_Downloader = drawrectangle(app.DownloaderGeoAxes, ...
                'Position', [minLat, minLon, maxLat-minLat, maxLon-minLon], ...
                'Color', 'r', ...
                'FaceAlpha', 0.2);
        
            updateDownloaderRectangleCoords(app, app.roi_Downloader);
        
            addlistener(app.roi_Downloader, ...
                'ROIMoved', ...
                @(src, event) updateDownloaderRectangleCoords(app, src));
        
        end

        function clearDownloaderAOIShape(app)
        
            if ~isempty(app.roi_Downloader) && isvalid(app.roi_Downloader)
                delete(app.roi_Downloader);
            end
        
            app.roi_Downloader = [];
        
        end
        
        % Download confirmation popup
        function confirmed = showDownloadConfirmation(app)

            summaryFile = fullfile(pwd, "download", "search_summary.json");

            if ~exist(summaryFile, "file")
                uialert(app.UIFigure, ...
                    "Could not find search_summary.json.", ...
                    "Search Summary Missing", ...
                    "Icon", "error");
                confirmed = false;
                return;
            end
        
            summary = jsondecode(fileread(summaryFile));
        
            imageCount = summary.product_count;
            totalSizeGB = summary.total_size_gb;
        
            message = sprintf( ...
            "ASF found %d SAR images.%s%sEstimated total download size: %.2f GB%s%sDo you want to continue with the download?", ...
            imageCount, newline, newline, totalSizeGB, newline, newline);
        
            answer = uiconfirm(app.UIFigure, ...
                message, ...
                "Confirm Download", ...
                "Options", ["Download", "Cancel"], ...
                "DefaultOption", "Download", ...
                "CancelOption", "Cancel");
        
            confirmed = strcmp(answer, "Download");
        
        end

        % save last download parameters to use later
        function saveLastDownloadParams(app)

            params = getDownloaderParams(app);
        
            outputFolder = fullfile(pwd, "download");
        
            if ~exist(outputFolder, "dir")
                mkdir(outputFolder);
            end
        
            outputFile = fullfile(outputFolder, "last_download_request.json");
        
            jsonText = jsonencode(params, PrettyPrint=true);
        
            fid = fopen(outputFile, "w");
        
            if fid == -1
                disp("Could not save last download parameters.");
                return;
            end
        
            fprintf(fid, "%s", jsonText);
            fclose(fid);
        
            disp("Last download parameters saved.");
        
        end
            
        % updating the parameters to match last download's
        function applyDownloaderParams(app, params)

            resetAllFilters(app);
        
            app.DatasetDropDown.Value = string(params.dataset);
        
            setFileTypeCheckboxes(app, params.processingLevel);
            setBeamModeCheckboxes(app, params.beamMode);
            setPolarizationCheckboxes(app, params.polarization);
            setOrbitDirectionCheckboxes(app, params.flightDirection);
            setSubtypeCheckboxes(app, params.subtype);
            
        
            if ~isempty(params.startDate)
                app.StartdateDatePicker.Value = datetime(params.startDate);
            end
        
            if ~isempty(params.endDate)
                app.EnddateDatePicker.Value = datetime(params.endDate);
            end
        
            if ~isempty(params.pathStart)
                app.PathStartEditField.Value = params.pathStart;
            end
        
            if ~isempty(params.pathEnd)
                app.PathEndEditField.Value = params.pathEnd;
            end
        
            if ~isempty(params.frameStart)
                app.FrameStartEditField.Value = params.frameStart;
            end
        
            if ~isempty(params.frameEnd)
                app.FrameEndEditField.Value = params.frameEnd;
            end
            if isfield(params, "sampling") && ~isempty(params.sampling.rate)
                app.SamplingRateEditField.Value = params.sampling.rate;
                app.SamplingUnitDropDown.Value = string(params.sampling.unit);
            end
        
        end

        function setFileTypeCheckboxes(app, values)

            values = string(values);
        
            app.L1DetectedHighResDualPolGRDHDCheckBox.Value = any(values == "GRD-HD");
            app.L1DetectedMidResDualPolGRDMDCheckBox.Value = any(values == "GRD-MD");
            app.L1DetectedMidResSinglePolGRDMSCheckBox.Value = any(values == "GRD-MS");
            app.L1DetectedHighResSinglePolGRDHSCheckBox.Value = any(values == "GRD-HS");
        
            app.L1SingleLookComplexSLCCheckBox.Value = any(values == "SLC");
            app.L2OceanOCNCheckBox.Value = any(values == "OCN");
            app.L0RawDataRAWCheckBox.Value = any(values == "RAW");
        
            app.XMLMetadataGRDMSCheckBox.Value = any(values == "XML_GRD-MS");
            app.XMLMetadataGRDHDCheckBox.Value = any(values == "XML_GRD-HD");
            app.XMLMetadataRAWCheckBox.Value = any(values == "XML_RAW");
            app.XMLMetadataGRDHSCheckBox.Value = any(values == "XML_GRD-HS");
            app.XMLMetadataGRDMDCheckBox.Value = any(values == "XML_GRD-MD");
            app.XMLMetadataSLCCheckBox.Value = any(values == "XML_SLC");
            app.XMLMetadataOCNCheckBox.Value = any(values == "XML_OCN");
        
        end

        function setBeamModeCheckboxes(app, values)

            values = string(values);
        
            app.IWCheckBox.Value = any(values == "IW");
            app.EWCheckBox.Value = any(values == "EW");
            app.S1CheckBox.Value = any(values == "S1");
            app.S2CheckBox.Value = any(values == "S2");
            app.S3CheckBox.Value = any(values == "S3");
            app.S4CheckBox.Value = any(values == "S4");
            app.S5CheckBox.Value = any(values == "S5");
            app.S6CheckBox.Value = any(values == "S6");
            app.WVCheckBox.Value = any(values == "WV");
        
        end

        function setPolarizationCheckboxes(app, values)

            values = string(values);
        
            app.VVCheckBox.Value = any(values == "VV");
            app.HHCheckBox.Value = any(values == "HH");
            app.VVHHCheckBox.Value = any(values == "VV+HH");
            app.HHHVCheckBox.Value = any(values == "HH+HV");
            app.DualHHCheckBox.Value = any(values == "Dual HH");
            app.DualHVCheckBox.Value = any(values == "Dual HV");
            app.DualVHCheckBox.Value = any(values == "Dual VH");
            app.DualVVCheckBox.Value = any(values == "Dual VV");
        
        end

        function setOrbitDirectionCheckboxes(app, values)

            values = string(values);
        
            app.ASCENDINGCheckBox.Value = any(values == "ASCENDING");
            app.DESCENDINGCheckBox.Value = any(values == "DESCENDING");
        
        end

        function setSubtypeCheckboxes(app, values)

            values = string(values);
        
            app.SACheckBox.Value = any(values == "SA");
            app.SBCheckBox.Value = any(values == "SB");
            app.SCCheckBox.Value = any(values == "SC");
            app.SCCheckBox.Value = any(values == "SD");

        
        end

        function initializeCSKMap(app)
            % CLEAN & INITIALIZE MAP
            delete(app.Panel.Children); 
            gx = geoaxes(app.Panel);
            geobasemap(gx, 'satellite');
            
            % Enable standard interactions
            gx.Interactions = [panInteraction; zoomInteraction];

            % FETCH FOOTPRINT
            currentFolder = fileparts(mfilename('fullpath'));
            project_path_full = fullfile(currentFolder, 'PHASE_Preprocessing');
            csk_files = dir(fullfile(project_path_full, 'slaves', '**', '*.h5'));
            
            default_pos = []; 
            
            if ~isempty(csk_files)
                first_file = fullfile(csk_files(1).folder, csk_files(1).name);
                try
                    tl = h5readatt(first_file, '/', 'Estimated Top Left Geodetic Coordinates');
                    tr = h5readatt(first_file, '/', 'Estimated Top Right Geodetic Coordinates');
                    br = h5readatt(first_file, '/', 'Estimated Bottom Right Geodetic Coordinates');
                    bl = h5readatt(first_file, '/', 'Estimated Bottom Left Geodetic Coordinates');
                    
                    lats = [tl(1), tr(1), br(1), bl(1)];
                    lons = [tl(2), tr(2), br(2), bl(2)];
                    
                    geoplot(gx, [lats lats(1)], [lons lons(1)], 'Color', 'b', 'LineWidth', 2);
                    geolimits(gx, [min(lats)-0.05, max(lats)+0.05], [min(lons)-0.05, max(lons)+0.05]);
                    
                    lon_min_fp = min(lons);
                    lon_max_fp = max(lons);
                    lat_min_fp = min(lats);
                    lat_max_fp = max(lats);
                    
                    w = (lon_max_fp - lon_min_fp) * 0.8;
                    h = (lat_max_fp - lat_min_fp) * 0.8;
                    start_lon = lon_min_fp + (lon_max_fp - lon_min_fp) * 0.1;
                    start_lat = lat_min_fp + (lat_max_fp - lat_min_fp) * 0.1;
                    
                    % FIXED: GeoAxes expects [Latitude, Longitude, LatHeight, LonWidth]
                    default_pos = [start_lat, start_lon, h, w];
                    
                catch ME
                    disp(ME.message); 
                end
            end

            % INTERACTIVE DRAWING
            if ~isempty(default_pos)
                app.roi_CSK = drawrectangle(gx, 'Position', default_pos, 'Color', 'r', 'FaceAlpha', 0.2);
                app.updateCSKCoords(app.roi_CSK); % Force UI to update immediately
            else
                app.roi_CSK = drawrectangle(gx, 'Color', 'r', 'FaceAlpha', 0.2);
            end
            
            addlistener(app.roi_CSK, 'ROIMoved', @(src, event) app.updateCSKCoords(src));
        end


        function updateCSKCoords(app, roi_source)
            if isvalid(roi_source)
                % FIXED: GeoAxes outputs [LatMin, LonMin, LatHeight, LonWidth]
                pos = roi_source.Position; 
                
                latMin = pos(1);
                lonMin = pos(2);
                latMax = pos(1) + pos(3);
                lonMax = pos(2) + pos(4);
                
                % Update UI Fields immediately
                app.MinlongitudeEditField_2.Value = lonMin;
                app.MinlatitudeEditField_2.Value = latMin;
                app.MaxlongitudeEditField_2.Value = lonMax;
                app.MaxlatitudeEditField_2.Value = latMax;
                
                % Update internal background variables
                app.lon_min_CSK = lonMin;
                app.lat_min_CSK = latMin;
                app.lon_max_CSK = lonMax;
                app.lat_max_CSK = latMax;
            end
        end


        function initializeSENMap(app)
            % CLEAN & INITIALIZE MAP
            delete(app.SENMapPanel.Children); 
            gx = geoaxes(app.SENMapPanel);
            geobasemap(gx, 'satellite');
            
            % Enable standard interactions
            gx.Interactions = [panInteraction; zoomInteraction];

            % FETCH FOOTPRINT
            currentFolder = fileparts(mfilename('fullpath'));
            project_path_full = fullfile(currentFolder, 'PHASE_Preprocessing');
            
            % Look for Sentinel-1 .zip files
            sen_files = dir(fullfile(project_path_full, 'slaves', '**', '*.zip'));
            
            default_pos = []; 
            
            if ~isempty(sen_files)
                first_file = fullfile(sen_files(1).folder, sen_files(1).name);
                try
                    % --- THE FAST JAVA ZIP TRICK ---
                    % This reads the manifest instantly without unzipping the 4GB file
                    zipObj = java.util.zip.ZipFile(first_file);
                    entries = zipObj.entries();
                    manifest_text = '';
                    
                    while entries.hasMoreElements()
                        entry = entries.nextElement();
                        if endsWith(char(entry.getName()), 'manifest.safe')
                            stream = zipObj.getInputStream(entry);
                            scanner = java.util.Scanner(stream).useDelimiter('\A');
                            if scanner.hasNext()
                                manifest_text = char(scanner.next());
                            end
                            scanner.close();
                            break;
                        end
                    end
                    zipObj.close();
                    % -------------------------------
                    
                    % PARSE THE XML COORDINATES
                    % Find the <gml:coordinates> block
                    coords_tokens = regexp(manifest_text, '<gml:coordinates>([^<]+)</gml:coordinates>', 'tokens', 'once');
                    
                    if ~isempty(coords_tokens)
                        coords_str = strtrim(coords_tokens{1});
                        pairs = split(coords_str, ' '); % Split into Lat,Lon chunks
                        
                        lats = zeros(1, length(pairs));
                        lons = zeros(1, length(pairs));
                        
                        for k = 1:length(pairs)
                            latlon = split(pairs{k}, ','); % Split by comma
                            lats(k) = str2double(latlon{1});
                            lons(k) = str2double(latlon{2});
                        end
                        
                        % Plot the bounding box
                        geoplot(gx, [lats lats(1)], [lons lons(1)], 'Color', 'b', 'LineWidth', 2);
                        geolimits(gx, [min(lats)-0.05, max(lats)+0.05], [min(lons)-0.05, max(lons)+0.05]);
                        
                        % Calculate 80% centered starting box
                        lon_min_fp = min(lons);
                        lon_max_fp = max(lons);
                        lat_min_fp = min(lats);
                        lat_max_fp = max(lats);
                        
                        w = (lon_max_fp - lon_min_fp) * 0.8;
                        h = (lat_max_fp - lat_min_fp) * 0.8;
                        start_lon = lon_min_fp + (lon_max_fp - lon_min_fp) * 0.1;
                        start_lat = lat_min_fp + (lat_max_fp - lat_min_fp) * 0.1;
                        
                        % [Lat, Lon, LatHeight, LonWidth]
                        default_pos = [start_lat, start_lon, h, w];
                    end
                    
                catch ME
                    disp('Could not read Sentinel-1 metadata from ZIP:');
                    disp(ME.message); 
                end
            end

            % INTERACTIVE DRAWING
            if ~isempty(default_pos)
                app.roi_SEN = drawrectangle(gx, 'Position', default_pos, 'Color', 'r', 'FaceAlpha', 0.2);
                app.updateSENCoords(app.roi_SEN); % Force UI to update immediately
            else
                app.roi_SEN = drawrectangle(gx, 'Color', 'r', 'FaceAlpha', 0.2);
            end
            
            addlistener(app.roi_SEN, 'ROIMoved', @(src, event) app.updateSENCoords(src));
        end


        function updateSENCoords(app, roi_source)
            if isvalid(roi_source)
                % GeoAxes outputs [LatMin, LonMin, LatHeight, LonWidth]
                pos = roi_source.Position; 
                
                latMin = pos(1);
                lonMin = pos(2);
                latMax = pos(1) + pos(3);
                lonMax = pos(2) + pos(4);
                
                % Update Sentinel-1 UI Fields immediately
                app.MinlongitudeEditField.Value = lonMin;
                app.MinlatitudeEditField.Value = latMin;
                app.MaxlongitudeEditField.Value = lonMax;
                app.MaxlatitudeEditField.Value = latMax;
                
                % Update Sentinel-1 internal variables
                app.lon_min_SEN = lonMin;
                app.lat_min_SEN = latMin;
                app.lon_max_SEN = lonMax;
                app.lat_max_SEN = latMax;
            end
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            % Set the working directory to the folder where the .mlapp file is located
            currentFolder = fileparts(mfilename('fullpath'));
            cd(currentFolder);

            % Ensure Mapping Toolbox is installed
            installedToolboxes = ver;
            if ~any(strcmp({installedToolboxes.Name}, 'Mapping Toolbox'))
                msg = 'The Mapping Toolbox is not installed. Please install it before running this app.';
                uialert(app.UIFigure, msg, 'Missing Dependencies');
                
                % Display message in the log windows just in case
                app.MessagesTextArea.Value = {'ERROR: Missing Mapping Toolbox.'};
                app.MessagesTextArea_2.Value = {'ERROR: Missing Mapping Toolbox.'};
                return; % Exit the startup function early
            end

            % Initially hide both the label and the edit field
            app.PythonEnvironmentLabel.Visible = 'off';
            app.CustomPythonEnvironmentEditField.Visible = 'off';
            app.CustomPythonEnvironmentLabel.Visible = 'off';
            app.CustomPythonEnvironmentEditField_2.Visible = 'off';

            % Render the CSK map
            drawnow;
            app.initializeCSKMap();
            app.initializeSENMap();

            % Default values for params
            app.FilterPanel.Visible = "off";
            app.SignOutButton.Visible = "off";
            app.SignedInLabel.Visible = "off";
            app.initializeParams();

            % Initialize Downloader map
            initializeDownloaderMap(app);

        end

        % Value changed function: ConstellationSwitch
        function ConstellationSwitchValueChanged(app, event)
           selectedOption = app.ConstellationSwitch.Value;

            if selectedOption == "Sentinel1"
                app.Sentinel1Panel.Visible = 'on';
                app.CosmoSkyMedPanel.Visible = 'off';
                app.constellation = 'SEN';
            elseif selectedOption == "CosmoSkyMed"
                app.Sentinel1Panel.Visible = 'off';
                app.CosmoSkyMedPanel.Visible = 'on';
                app.constellation = 'CSK';
            end

        end

        % Callback function
        function RunningOperativeSystemButtonGroupSelectionChanged(app, event)
            selectedButtonText = app.RunningOperativeSystemButtonGroup.SelectedObject.Text;
            
            if strcmp(selectedButtonText, "Windows")
                app.os_SEN = 0;
            elseif strcmp(selectedButtonText, "Linux")
                app.os_SEN = 1;
            end

        end

        % Value changed function: CustomPythonEnvironmentEditField
        function CustomPythonEnvironmentEditFieldValueChanged(app, event)
            value = app.CustomPythonEnvironmentEditField.Value;

            app.python_SEN = value;

            selectedValue = app.PythonEnvironmentDropDown.Value;

            if strcmp(selectedValue, 'Other')
                % If "other" is selected, use the value from the EditField
                app.python_SEN = app.CustomPythonEnvironmentEditField.Value;
            else
                % Use the selected value from the dropdown menu
                app.python_SEN = selectedValue;
            end

        end

        % Button pushed function: SaveButton, SaveButton_2
        function SaveButtonPushed(app, event)
            
            % Specify the filename for saving
            constellation = app.constellation;
            filename = './PHASE_Preprocessing/input_preprocessing.mat';  % specify the filename for saving

            if app.constellation == "SEN"

                python = app.python_SEN;
                images_download = app.images_download_SEN;
                master_date = app.master_date_SEN;
                auto_master = app.auto_master_SEN;
                master_processing = app.master_processing_SEN;
                polarisation = app.polarisation_SEN;
                lon_min = app.lon_min_SEN;
                lat_min = app.lat_min_SEN;
                lon_max = app.lon_max_SEN;
                lat_max = app.lat_max_SEN;
                slaves_removal = app.slaves_removal_SEN;
                dem_name = app.dem_name_SEN;
                dem_file = app.dem_file_SEN;
                dem_name_coreg = app.dem_name_coreg_SEN;
                dem_file_coreg = app.dem_file_coreg_SEN;
                dem_resampling = app.dem_resampling_SEN;
                first_step = app.first_step_SEN;
                coherence_tc = app.coherence_tc_SEN;
                epsg_code = app.epsg_code_SEN;
                gptbin_path = app.gptbin_path_SEN;
                cpu = app.cpu_SEN;
                cache = app.cache_SEN;

                try

                    % Delete the existing .mat file, if it exists
                    if exist(filename, 'file') == 2
                        delete(filename);
                    end

                    save(filename, 'constellation', 'python', 'images_download', ...
                        'master_date', 'auto_master', 'master_processing', ...
                        'polarisation', 'lon_min', ...
                        'lat_min', 'lon_max', 'lat_max', 'slaves_removal', ...
                        'dem_name', 'dem_file', ...
                        'dem_name_coreg', 'dem_file_coreg', 'dem_resampling', 'first_step', 'coherence_tc', ...
                        'epsg_code', 'gptbin_path', 'cpu', 'cache', '-mat');

                    % If saving is successful, set the lamp to green
                    app.StatusLamp.Color = [0, 1, 0]; % Green color
    
                catch exception

                    % If saving fails, set the lamp to red
                    app.StatusLamp.Color = [1, 0, 0]; % Red color
                    % Display the error message
                    updateOutput(app, ['Error saving file: ' exception.message]);

                end

            elseif app.constellation == "CSK"

                python = app.python_CSK;
                master_date = app.master_date_CSK;
                auto_master = app.auto_master_CSK;
                master_processing = app.master_processing_CSK;
                lon_min = app.lon_min_CSK;
                lat_min = app.lat_min_CSK;
                lon_max = app.lon_max_CSK;
                lat_max = app.lat_max_CSK;
                slaves_removal = app.slaves_removal_CSK;                
                dem_name = app.dem_name_CSK;
                dem_file = app.dem_file_CSK;
                first_step = app.first_step_CSK;
                num_gcp = app.num_gcp_CSK;
                coherence_tc = app.coherence_tc_CSK;
                epsg_code = app.epsg_code_CSK;
                gptbin_path = app.gptbin_path_CSK;
                cpu = app.cpu_CSK;
                cache = app.cache_CSK;

                try

                    % Delete the existing .mat file, if it exists
                    if exist(filename, 'file') == 2
                        delete(filename);
                    end

                    save(filename, 'constellation', 'python', ...
                        'master_date', 'auto_master', 'master_processing', 'lon_min', ...
                        'lat_min', 'lon_max', 'lat_max', 'slaves_removal', 'dem_name', 'dem_file', ...
                        'first_step', 'num_gcp', 'coherence_tc', 'epsg_code', 'gptbin_path', 'cpu', 'cache', '-mat');

                    % If saving is successful, set the lamp to green
                    app.StatusLamp_2.Color = [0, 1, 0]; % Green color
    
                catch exception

                    % If saving fails, set the lamp to red
                    app.StatusLamp_2.Color = [1, 0, 0]; % Red color
                    % Display the error message
                    updateOutput(app, ['Error saving file: ' exception.message]);

                end

            end


        end

        % Value changed function: SARImagesDownloadCheckBox
        function SARImagesDownloadCheckBoxValueChanged(app, event)
            value = app.SARImagesDownloadCheckBox.Value;
            
            if value
                app.images_download_SEN = 0;
            else
                app.images_download_SEN = 1;
            end

        end

        % Value changed function: MasterprocessingCheckBox
        function MasterprocessingCheckBoxValueChanged(app, event)
            value = app.MasterprocessingCheckBox.Value;

            if value
                app.master_processing_SEN = 0;
            else
                app.master_processing_SEN = 1;
            end
            
        end

        % Value changed function: PolarisationDropDown
        function PolarisationDropDownValueChanged(app, event)
            value = app.PolarisationDropDown.Value;

            app.polarisation_SEN = value;

        end

        % Value changed function: MinlongitudeEditField
        function MinlongitudeEditFieldValueChanged(app, event)
            value = app.MinlongitudeEditField.Value;
            
            app.lon_min_SEN = value;

        end

        % Value changed function: MaxlongitudeEditField
        function MaxlongitudeEditFieldValueChanged(app, event)
            value = app.MaxlongitudeEditField.Value;
            
            app.lon_max_SEN = value;

        end

        % Value changed function: MaxlatitudeEditField
        function MaxlatitudeEditFieldValueChanged(app, event)
            value = app.MaxlatitudeEditField.Value;
            
            app.lat_max_SEN = value;

        end

        % Value changed function: MinlatitudeEditField
        function MinlatitudeEditFieldValueChanged(app, event)
            value = app.MinlatitudeEditField.Value;
            
            app.lat_min_SEN = value;

        end

        % Value changed function: MasterdateDatePicker
        function MasterdateDatePickerValueChanged(app, event)
            value = app.MasterdateDatePicker.Value;
            
            date_txt = datetime(value, 'Format', 'ddMMyyyy');
            app.master_date_SEN = date_txt;

        end

        % Value changed function: FirststepDropDown
        function FirststepDropDownValueChanged(app, event)
            value = app.FirststepDropDown.Value;
            
            app.first_step_SEN = value;

        end

        % Value changed function: DEMresamplingmethodDropDown
        function DEMresamplingmethodDropDownValueChanged(app, event)
            value = app.DEMresamplingmethodDropDown.Value;
            
            app.dem_resampling_SEN = value;

        end

        % Value changed function: DEMcoregpathEditField
        function DEMcoregpathEditFieldValueChanged(app, event)
            value = app.DEMcoregpathEditField.Value;
            
            app.dem_file_coreg_SEN = value;

        end

        % Value changed function: DEMifgpathEditField
        function DEMifgpathEditFieldValueChanged(app, event)
            value = app.DEMifgpathEditField.Value;
            
            app.dem_file_SEN = value;

        end

        % Value changed function: DEMcoregistrationDropDown
        function DEMcoregistrationDropDownValueChanged(app, event)
            value = app.DEMcoregistrationDropDown.Value;
            
            app.dem_name_coreg_SEN = value;

        end

        % Value changed function: DEMinterferogramDropDown
        function DEMinterferogramDropDownValueChanged(app, event)
            value = app.DEMinterferogramDropDown.Value;
            
            app.dem_name_SEN = value;

        end

        % Value changed function: SlavesremovalafterprocessingCheckBox
        function SlavesremovalafterprocessingCheckBoxValueChanged(app, event)
            value = app.SlavesremovalafterprocessingCheckBox.Value;
            
            if value
                app.slaves_removal_SEN = 0;
            else
                app.slaves_removal_SEN = 1;
            end

        end

        % Value changed function: EPSGcodeEditField
        function EPSGcodeEditFieldValueChanged(app, event)
            value = app.EPSGcodeEditField.Value;
            
            app.epsg_code_SEN = value;

        end

        % Value changed function: TerraincorrectedCoherenceandLIACheckBox
        function TerraincorrectedCoherenceandLIACheckBoxValueChanged(app, event)
            value = app.TerraincorrectedCoherenceandLIACheckBox.Value;
            
            if value
                app.coherence_tc_SEN = 0;
            else
                app.coherence_tc_SEN = 1;
            end

        end

        % Value changed function: CacheEditField
        function CacheEditFieldValueChanged(app, event)
            value = app.CacheEditField.Value;
            
            app.cache_SEN = value;

        end

        % Value changed function: CPUEditField
        function CPUEditFieldValueChanged(app, event)
            value = app.CPUEditField.Value;
            
            app.cpu_SEN = value;

        end

        % Value changed function: PathEditField
        function PathEditFieldValueChanged(app, event)
            value = app.PathEditField.Value;
            
            app.gptbin_path_SEN = value;

        end

        % Callback function
        function RunningOperativeSystemButtonGroup_2SelectionChanged(app, event)
            selectedButtonText = app.RunningOperativeSystemButtonGroup_2.SelectedObject.Text;
            
            if strcmp(selectedButtonText, "Windows")
                app.os_CSK = 0;
            elseif strcmp(selectedButtonText, "Linux")
                app.os_CSK = 1;
            end

        end

        % Value changed function: CustomPythonEnvironmentEditField_2
        function CustomPythonEnvironmentEditField_2ValueChanged(app, event)
            value = app.CustomPythonEnvironmentEditField_2.Value;

            app.python_CSK = value;

            selectedValue = app.PythonEnvironmentDropDown_2.Value;

            if strcmp(selectedValue, 'Other')
                % If "other" is selected, use the value from the EditField
                app.python_CSK = app.CustomPythonEnvironmentEditField_2.Value;
            else
                % Use the selected value from the dropdown menu
                app.python_CSK = selectedValue;
            end

        end

        % Value changed function: MinlatitudeEditField_2
        function MinlatitudeEditField_2ValueChanged(app, event)
            value = app.MinlatitudeEditField_2.Value;
            
            app.lat_min_CSK = value;
            
        end

        % Value changed function: MaxlatitudeEditField_2
        function MaxlatitudeEditField_2ValueChanged(app, event)
            value = app.MaxlatitudeEditField_2.Value;
            
            app.lat_max_CSK = value;

        end

        % Value changed function: MaxlongitudeEditField_2
        function MaxlongitudeEditField_2ValueChanged(app, event)
            value = app.MaxlongitudeEditField_2.Value;
            
            app.lon_max_CSK = value;

        end

        % Value changed function: MinlongitudeEditField_2
        function MinlongitudeEditField_2ValueChanged(app, event)
            value = app.MinlongitudeEditField_2.Value;
            
            app.lon_min_CSK = value;
            
        end

        % Value changed function: MasterprocessingCheckBox_2
        function MasterprocessingCheckBox_2ValueChanged(app, event)
            value = app.MasterprocessingCheckBox_2.Value;
            
            if value
                app.master_processing_CSK = 0;
            else
                app.master_processing_CSK = 1;
            end

        end

        % Value changed function: MasterdateDatePicker_2
        function MasterdateDatePicker_2ValueChanged(app, event)
            value = app.MasterdateDatePicker_2.Value;
            
            date_txt = datetime(value, 'Format', 'ddMMyyyy');
            app.master_date_CSK = date_txt;

        end

        % Value changed function: CoregistrationGCPsnumberEditField
        function CoregistrationGCPsnumberEditFieldValueChanged(app, event)
            value = app.CoregistrationGCPsnumberEditField.Value;
            
            app.num_gcp_CSK = value;

        end

        % Value changed function: FirststepDropDown_2
        function FirststepDropDown_2ValueChanged(app, event)
            value = app.FirststepDropDown_2.Value;
            
            app.first_step_CSK = value;

        end

        % Value changed function: DEMifgpathEditField_2
        function DEMifgpathEditField_2ValueChanged(app, event)
            value = app.DEMifgpathEditField_2.Value;

            app.dem_file_CSK = value;

        end

        % Value changed function: DEMinterferogramDropDown_2
        function DEMinterferogramDropDown_2ValueChanged(app, event)
            value = app.DEMinterferogramDropDown_2.Value;
            
            app.dem_name_CSK = value;

        end

        % Value changed function: SlavesremovalafterprocessingCheckBox_2
        function SlavesremovalafterprocessingCheckBox_2ValueChanged(app, event)
            value = app.SlavesremovalafterprocessingCheckBox_2.Value;
            
            if value
                app.slaves_removal_CSK = 0;
            else
                app.slaves_removal_CSK = 1;
            end

        end

        % Value changed function: TerraincorrectedCoherenceandLIACheckBox_2
        function TerraincorrectedCoherenceandLIACheckBox_2ValueChanged(app, event)
            value = app.TerraincorrectedCoherenceandLIACheckBox_2.Value;
            
            if value
                app.coherence_tc_CSK = 0;
            else
                app.coherence_tc_CSK = 1;
            end

        end

        % Value changed function: EPSGcodeEditField_2
        function EPSGcodeEditField_2ValueChanged(app, event)
            value = app.EPSGcodeEditField_2.Value;
            
            app.epsg_code_CSK = value;
            
        end

        % Value changed function: CacheEditField_2
        function CacheEditField_2ValueChanged(app, event)
            value = app.CacheEditField_2.Value;
            
            app.cache_CSK = value;

        end

        % Value changed function: CPUEditField_2
        function CPUEditField_2ValueChanged(app, event)
            value = app.CPUEditField_2.Value;
            
            app.cpu_CSK = value;

        end

        % Value changed function: PathEditField_2
        function PathEditField_2ValueChanged(app, event)
            value = app.PathEditField_2.Value;
            
            app.gptbin_path_CSK = value;

        end

        % Button pushed function: StartButton, StartButton_2
        function StartButtonPushed(app, event)
            
            app.MessagesTextArea.Value = '';
            app.MessagesTextArea_2.Value = '';
            SaveButtonPushed(app,event);

            if app.constellation == "SEN"

                condition = 0;

                while condition == 0

                    % Display a message in the UIAxes
                    updateOutput(app, 'Button pressed! Running the script...');
    
                    % Change lamp color to yellow (running)
                    app.PreprocessingstatusLamp.Color = [1, 1, 0]; % Yellow
                
                    try
    
                        %% SENTINEL-1 PREPROCESSING CODE ----------------------
    
                        pwd;
                        prep_folder = pwd;
                        addpath(genpath(prep_folder));
                        
                        %% ------------------ IMPORT OF THE REQUIRED VARIABLES --------------------
                        
                           
                        % DEFINE THE TYPE OF SLASH
                        par = filesep;
    
                        % READ OF THE INPUT VARIABLES FROM .MAT FILE
                        load(strcat('.', par, 'PHASE_Preprocessing', par, 'input_preprocessing.mat'), 'python', 'images_download', ...
                                                'master_date', 'master_processing', 'auto_master', ...
                                                'polarisation', 'lon_min', 'lat_min', 'lon_max', 'lat_max', 'slaves_removal', 'dem_name', ...
                                                'dem_file', 'dem_name_coreg', 'dem_file_coreg', 'dem_resampling', 'first_step', 'coherence_tc', ...
                                                'epsg_code', 'gptbin_path', 'cpu', 'cache');
                        
                        % PROJECT FOLDER
                        project_path_full = strcat(prep_folder, par, 'PHASE_Preprocessing');
                        
                        % GENERAL VARIABLES
                        space = (' ');
                        python = (python);
                        path_1_download = strcat(project_path_full, par, 'slaves');
    
                        % CONVERT VARIABLES FORMAT
                        if isa(master_date, 'datetime')
                            master_date = datestr(master_date, 'yyyymmdd');
                        end
                        
                        
                        %% ------------- DOWNLOAD THE SAR IMAGES FROM ALASKA.EDU ------------------
                        
                        mkdir(project_path_full, 'slaves'); % create slaves folder
                        mkdir(project_path_full, 'master'); % create master folder
                        
                        if images_download == 0 % check if the download of the images has to be done or not
                        
                            download_search_path = strcat(project_path_full, par, 'download-all*'); % search of the download file
                            download_struct = dir(download_search_path);
                            download_date = download_struct.name;
                            download_source = strcat(project_path_full, par, download_date);
                            download_destination = strcat(project_path_full, par, 'slaves', par, download_date);
                            download_proof = strcat(project_path_full, par, 'slaves', par, 'dummyDownload.txt');
                            
                            % CREATION OF THE BATCH/BASH FILE
                            download_images = [python space download_date]; % download command
                            
                            if ispc
                                f_download_images = fopen(strcat(project_path_full, '\download_all_images.bat'),'w');
                                j = 0;
                                while f_download_images == -1 && j < 20
                                    f_download_images = fopen(strcat(project_path_full, '\download_all_images.bat'),'w');
                                    j = j+1;
                                end
                                fprintf(f_download_images,'@echo off \r\n');
                                fprintf(f_download_images,'cd "%s"\r\n',path_1_download);
                                fprintf(f_download_images,'%s\r\n',download_images);
                                fprintf(f_download_images,'type nul > dummyDownload.txt \r\n');
                                fprintf(f_download_images,'exit \r\n');
                                f_download_images = fclose(f_download_images);
                            elseif isunix && ~ismac
                                f_download_images = fopen(strcat(project_path_full, '/download_all_images.sh'),'w');
                                j = 0;
                                while f_download_images == -1 && j < 20
                                    f_download_images = fopen(strcat(project_path_full, '/download_all_images.sh'),'w');
                                    j = j+1;
                                end
                                fprintf(f_download_images,'#!/bin/bash \n');
                                fprintf(f_download_images,'cd "%s"\n',path_1_download);
                                fprintf(f_download_images,'%s\n',download_images);
                                fprintf(f_download_images,'sleep 5 \n');
                                fprintf(f_download_images,'touch dummyDownload.txt \n');
                                f_download_images = fclose(f_download_images);
                            elseif ismac
                                f_download_images = fopen(strcat(project_path_full, '/download_all_images.sh'),'w');
                                j = 0;
                                while f_download_images == -1 && j < 20
                                    f_download_images = fopen(strcat(project_path_full, '/download_all_images.sh'),'w');
                                    j = j+1;
                                end
                                fprintf(f_download_images,'#!/bin/bash \n');
                                fprintf(f_download_images,'cd "%s"\n',path_1_download);
                                fprintf(f_download_images,'%s\n',download_images);
                                fprintf(f_download_images,'sleep 5 \n');
                                fprintf(f_download_images,'touch dummyDownload.txt \n');
                                f_download_images = fclose(f_download_images);
                            end
                        
                        end
                        
                        % SELECTED SAR IMAGES DOWNLOAD
                        if images_download == 0 % check if the download of the images has to be done or not
                        
                            copyfile(download_source, download_destination);
                        
                            updateOutput(app, '----------------------- STEP 1: Images download started -----------------------');
                        
                            if ispc
                                path_2_download = strcat(project_path_full, '\download_all_images.bat &');
                                system(path_2_download);
                            elseif isunix && ~ismac
                                path_2_download = (strcat(project_path_full, '/download_all_images.sh'));
                                chmod_in = ('chmod +x');
                                chmod = [chmod_in space path_2_download];
                                xterm = ('sudo xterm -e');
                                path_2_download = [xterm space path_2_download];
                                system(chmod);
                                system(path_2_download, '-echo');
                            elseif ismac
                                path_2_download = strcat(project_path_full, '/download_all_images.sh');
                                chmod_in = 'chmod +x';
                                chmod = [chmod_in space path_2_download];
                                path_2_download = ['open -a Terminal ' path_2_download];
                                system(chmod);
                                system(path_2_download, '-echo');
                            end
                        
                            % wait until the cmd has finished the work
                            while exist(download_proof,'file') == 0
                                  pause(1);
                            end
                            delete(download_proof);
                            delete(download_destination);
                        
                            updateOutput(app, '----------------------- STEP 1: Images download finished -----------------------');
                        
                        else 
                        
                            updateOutput(app, '----------------------- STEP1: Images download skipped -----------------------');
                        
                        end
    
                        if app.StopFlag
                            break;
                        end
                        
                                                
                        %% ------------------------ MASTER PROCESSING -----------------------------
                        
                        if master_processing == 0 % check if the processing of the master has to be done or not
                        
                            updateOutput(app, '----------------------- STEP 2: Master processing started -----------------------');
                        
                            % 1. THE NUCLEAR RESET: Flatten all .zip files back to the root of /slaves
                            
                            % Sweep 1: Rescue any Master .zip files from anywhere in /master
                            master_zips = dir(fullfile(project_path_full, 'master', '**', '*.zip'));
                            for i = 1:length(master_zips)
                                movefile(fullfile(master_zips(i).folder, master_zips(i).name), fullfile(project_path_full, 'slaves'));
                            end
                            
                            % Sweep 2: Rescue all Slave .zip files from their date subfolders
                            slave_zips = dir(fullfile(project_path_full, 'slaves', '**', '*.zip'));
                            for i = 1:length(slave_zips)
                                % Only move it if it is NOT already in the root of /slaves
                                if ~strcmp(slave_zips(i).folder, fullfile(project_path_full, 'slaves'))
                                    movefile(fullfile(slave_zips(i).folder, slave_zips(i).name), fullfile(project_path_full, 'slaves'));
                                end
                            end
                            
                            % Sweep 3: Delete all the now-empty date subfolders inside /slaves
                            slave_subdirs = dir(fullfile(project_path_full, 'slaves'));
                            for i = 1:length(slave_subdirs)
                                if slave_subdirs(i).isdir && ~strcmp(slave_subdirs(i).name, '.') && ~strcmp(slave_subdirs(i).name, '..')
                                    rmdir(fullfile(slave_subdirs(i).folder, slave_subdirs(i).name), 's');
                                end
                            end
                            
                            % Clean old processing folders to prevent conflicts
                            folders_to_delete = {'split', 'coreg', 'ifg', 'lia', 'coherence', 'intensity'};
                            for i=1:length(folders_to_delete)
                                if exist(fullfile(project_path_full, folders_to_delete{i}), 'dir')
                                    rmdir(fullfile(project_path_full, folders_to_delete{i}), 's');
                                end
                                mkdir(fullfile(project_path_full, folders_to_delete{i}));
                            end
                            
                            % Wipe master folder internals and recreate
                            if exist(fullfile(project_path_full, 'master'), 'dir')
                                rmdir(fullfile(project_path_full, 'master'), 's');
                            end
                            mkdir(fullfile(project_path_full, 'master'));
                        
                            % 2. CREATE project_master.conf
                            projectfolder = strcat('PROJECTFOLDER=',project_path_full); 
                            graphsfolder = strcat('GRAPHSFOLDER=', project_path_full, par, 'snap2stamps', par, 'graphs'); 
                            
                            if ispc
                                f_project_conf_master = fopen(strcat(project_path_full, '\snap2stamps\bin\project_master.conf'),'w');
                                j = 0;
                                while f_project_conf_master == -1 && j < 20
                                    f_project_conf_master = fopen(strcat(project_path_full, '\snap2stamps\bin\project_master.conf'),'w');
                                    j = j+1;
                                end
                                fprintf(f_project_conf_master,'%s\r\n', '######### CONFIGURATION FILE ######');
                                fprintf(f_project_conf_master,'%s\r\n', '# PROJECT DEFINITION');
                                fprintf(f_project_conf_master,'%s\r\n', projectfolder);
                                fprintf(f_project_conf_master,'%s\r\n', graphsfolder);
                                fprintf(f_project_conf_master,'%s\r\n', '# PROCESSING PARAMETERS');
                                fprintf(f_project_conf_master,'SWATHS=IW1,IW2,IW3\r\n');
                                fprintf(f_project_conf_master,'POLARISATION=%s\r\n', polarisation);
                                fprintf(f_project_conf_master,'AUTO_MASTER=%.0f\r\n', auto_master);
                                fprintf(f_project_conf_master,'MASTER_DATE=%s\r\n', master_date);
                                fprintf(f_project_conf_master,'%s\r\n', '# AOI BBOX DEFINITION');
                                fprintf(f_project_conf_master,'LONMIN=%.3f\r\n', lon_min);
                                fprintf(f_project_conf_master,'LATMIN=%.3f\r\n', lat_min);
                                fprintf(f_project_conf_master,'LONMAX=%.3f\r\n', lon_max);
                                fprintf(f_project_conf_master,'LATMAX=%.3f\r\n', lat_max);
                                fprintf(f_project_conf_master,'%s\r\n', '# SNAP GPT');
                                fprintf(f_project_conf_master,'GPTBIN_PATH=%s\r\n', gptbin_path);
                                fprintf(f_project_conf_master,'%s\r\n', '# COMPUTING RESOURCES');
                                fprintf(f_project_conf_master,'CPU=%.0f\r\n', cpu);
                                fprintf(f_project_conf_master,'CACHE=%s\r\n', cache);
                                fclose(f_project_conf_master);
                                
                            elseif isunix && ~ismac
                                f_project_conf_master = fopen(strcat(project_path_full, '/snap2stamps/bin/project_master.conf'),'w');
                                j = 0;
                                while f_project_conf_master == -1 && j < 20
                                    f_project_conf_master = fopen(strcat(project_path_full, '/snap2stamps/bin/project_master.conf'),'w');
                                    j = j+1;
                                end
                                fprintf(f_project_conf_master,'%s\n', '######### CONFIGURATION FILE ######');
                                fprintf(f_project_conf_master,'%s\n', '# PROJECT DEFINITION');
                                fprintf(f_project_conf_master,'%s\n', projectfolder);
                                fprintf(f_project_conf_master,'%s\n', graphsfolder);
                                fprintf(f_project_conf_master,'%s\n', '# PROCESSING PARAMETERS');
                                fprintf(f_project_conf_master,'SWATHS=IW1,IW2,IW3\n');
                                fprintf(f_project_conf_master,'POLARISATION=%s\n', polarisation);
                                fprintf(f_project_conf_master,'AUTO_MASTER=%.0f\n', auto_master);
                                fprintf(f_project_conf_master,'MASTER_DATE=%s\n', master_date);
                                fprintf(f_project_conf_master,'%s\n', '# AOI BBOX DEFINITION');
                                fprintf(f_project_conf_master,'LONMIN=%.3f\n', lon_min);
                                fprintf(f_project_conf_master,'LATMIN=%.3f\n', lat_min);
                                fprintf(f_project_conf_master,'LONMAX=%.3f\n', lon_max);
                                fprintf(f_project_conf_master,'LATMAX=%.3f\n', lat_max);
                                fprintf(f_project_conf_master,'%s\n', '# SNAP GPT');
                                fprintf(f_project_conf_master,'GPTBIN_PATH=%s\n', gptbin_path);
                                fprintf(f_project_conf_master,'%s\n', '# COMPUTING RESOURCES');
                                fprintf(f_project_conf_master,'CPU=%.0f\n', cpu);
                                fprintf(f_project_conf_master,'CACHE=%s\n', cache);
                                fclose(f_project_conf_master);
                                
                            elseif ismac
                                f_project_conf_master = fopen(strcat(project_path_full, '/snap2stamps/bin/project_master.conf'),'w');
                                j = 0;
                                while f_project_conf_master == -1 && j < 20
                                    f_project_conf_master = fopen(strcat(project_path_full, '/snap2stamps/bin/project_master.conf'),'w');
                                    j = j+1;
                                end
                                fprintf(f_project_conf_master,'%s\n', '######### CONFIGURATION FILE ######');
                                fprintf(f_project_conf_master,'%s\n', '# PROJECT DEFINITION');
                                fprintf(f_project_conf_master,'%s\n', projectfolder);
                                fprintf(f_project_conf_master,'%s\n', graphsfolder);
                                fprintf(f_project_conf_master,'%s\n', '# PROCESSING PARAMETERS');
                                fprintf(f_project_conf_master,'SWATHS=IW1,IW2,IW3\n');
                                fprintf(f_project_conf_master,'POLARISATION=%s\n', polarisation);
                                fprintf(f_project_conf_master,'AUTO_MASTER=%.0f\n', auto_master);
                                fprintf(f_project_conf_master,'MASTER_DATE=%s\n', master_date);
                                fprintf(f_project_conf_master,'%s\n', '# AOI BBOX DEFINITION');
                                fprintf(f_project_conf_master,'LONMIN=%.3f\n', lon_min);
                                fprintf(f_project_conf_master,'LATMIN=%.3f\n', lat_min);
                                fprintf(f_project_conf_master,'LONMAX=%.3f\n', lon_max);
                                fprintf(f_project_conf_master,'LATMAX=%.3f\n', lat_max);
                                fprintf(f_project_conf_master,'%s\n', '# SNAP GPT');
                                fprintf(f_project_conf_master,'GPTBIN_PATH=%s\n', gptbin_path);
                                fprintf(f_project_conf_master,'%s\n', '# COMPUTING RESOURCES');
                                fprintf(f_project_conf_master,'CPU=%.0f\n', cpu);
                                fprintf(f_project_conf_master,'CACHE=%s\n', cache);
                                fclose(f_project_conf_master);
                            end
                        
                            % 3. EXECUTE MASTER SELECTOR AND MASTER PREP BATCH SCRIPT
                            updateOutput(app, 'Running automated Master Auto-Selector and Splitter...');
                            step_selector = ('SEN_master_selector.py project_master.conf');
                            step_selector_cmd = [python space step_selector]; 
                            step1_master = ('SEN_splitting_master.py project_master.conf');
                            step_master_2 = [python space step1_master]; 
                            
                            path_1_master = fullfile(project_path_full, par, 'snap2stamps', par, 'bin');
                            
                            if ispc
                                f_snap2stamps_master = fopen(strcat(project_path_full, '\snap2stamps\bin\snap2stamps_master.bat'),'w');
                                j = 0;
                                while f_snap2stamps_master == -1 && j < 20
                                    f_snap2stamps_master = fopen(strcat(project_path_full, '\snap2stamps\bin\snap2stamps_master.bat'),'w');
                                    j = j+1;
                                end
                                fprintf(f_snap2stamps_master,'@echo off \r\n');
                                fprintf(f_snap2stamps_master,'cd "%s"\r\n',path_1_master);
                                fprintf(f_snap2stamps_master,'%s\r\n',step_selector_cmd);
                                fprintf(f_snap2stamps_master,'%s\r\n',step_master_2);
                                fclose(f_snap2stamps_master);
                                
                                path_2_master = fullfile(project_path_full, '\snap2stamps\bin\snap2stamps_master.bat');
                                system(path_2_master);
                                
                            elseif isunix && ~ismac
                                f_snap2stamps_master = fopen(strcat(project_path_full, '/snap2stamps/bin/snap2stamps_master.sh'),'w');
                                j = 0;
                                while f_snap2stamps_master == -1 && j < 20
                                    f_snap2stamps_master = fopen(strcat(project_path_full, '/snap2stamps/bin/snap2stamps_master.sh'),'w');
                                    j = j+1;
                                end
                                fprintf(f_snap2stamps_master,'#!/bin/bash \n');
                                fprintf(f_snap2stamps_master,'cd "%s"\n',path_1_master);
                                fprintf(f_snap2stamps_master,'%s\n',step_selector_cmd);
                                fprintf(f_snap2stamps_master,'%s\n',step_master_2);
                                fclose(f_snap2stamps_master);
                                
                                path_2_master = (strcat(project_path_full, '/snap2stamps/bin/snap2stamps_master.sh'));
                                chmod = ['chmod +x' space path_2_master];
                                system(strjoin({chmod, path_2_master}, ';'));
                                
                            elseif ismac
                                f_snap2stamps_master = fopen(strcat(project_path_full, '/snap2stamps/bin/snap2stamps_master.sh'),'w');
                                j = 0;
                                while f_snap2stamps_master == -1 && j < 20
                                    f_snap2stamps_master = fopen(strcat(project_path_full, '/snap2stamps/bin/snap2stamps_master.sh'),'w');
                                    j = j+1;
                                end
                                fprintf(f_snap2stamps_master,'#!/bin/bash \n');
                                fprintf(f_snap2stamps_master,'cd "%s"\n',path_1_master);
                                fprintf(f_snap2stamps_master,'%s\n',step_selector_cmd);
                                fprintf(f_snap2stamps_master,'%s\n',step_master_2);
                                fclose(f_snap2stamps_master);
                                
                                path_2_master = (strcat(project_path_full, '/snap2stamps/bin/snap2stamps_master.sh'));
                                chmod = ['chmod +x' space path_2_master];
                                system(strjoin({chmod, path_2_master}, ';'));
                            end

                            % FLATTEN DIRECTORY: Move files up one level to clean the structure
                            master_subfolders = dir(fullfile(project_path_full, 'master', 'S1*'));
                            for idx = 1:length(master_subfolders)
                                if master_subfolders(idx).isdir
                                    subfolder_path = fullfile(master_subfolders(idx).folder, master_subfolders(idx).name);
                                    
                                    % Move all contents (.zip, .dim, .data) up to /master
                                    all_contents = dir(fullfile(subfolder_path, '*'));
                                    for j = 1:length(all_contents)
                                        if ~strcmp(all_contents(j).name, '.') && ~strcmp(all_contents(j).name, '..')
                                            movefile(fullfile(all_contents(j).folder, all_contents(j).name), fullfile(project_path_full, 'master'));
                                        end
                                    end
                                    % Delete the now-empty 67-character folder
                                    rmdir(subfolder_path, 's');
                                end
                            end
                            
                            % 4. VALIDATE OUTPUT
                            master_dim_struct = dir(fullfile(project_path_full, 'master', '*_split_*_Orb.dim'));
                            
                            if isempty(master_dim_struct)
                                msg = 'ERROR: the master image has not been correctly processed. Check the snap2stamps logs.';
                                error(msg);
                            end        
                            master_file_destination = fullfile(master_dim_struct(1).folder, master_dim_struct(1).name);
                    
                            updateOutput(app, '----------------------- STEP 2: Master processing finished -----------------------');
                    
                        else
                            % IF MASTER PROCESSING IS SKIPPED, DYNAMICALLY FIND THE EXISTING MASTER
                            master_dim_struct = dir(fullfile(project_path_full, 'master', '*_split_*_Orb.dim'));
                            
                            if isempty(master_dim_struct)
                                msg = 'ERROR: No processed master found in /master folder! You must run Master Processing first.';
                                error(msg);
                            end        
                            master_file_destination = fullfile(master_dim_struct(1).folder, master_dim_struct(1).name);
                            
                            updateOutput(app, '----------------------- STEP 2: Master processing skipped -----------------------');
                    
                        end
    
                        if app.StopFlag
                            break;
                        end
                        
                        
                        %% ------------------------ SLAVES PROCESSING -----------------------------
                        
                        % CREATE THE PROJECT.CONF FILE FOR SNAP2STAMPS SLAVES PROCESSING
                        projectfolder = strcat('PROJECTFOLDER=',project_path_full); % path of the project folder
                        graphsfolder = strcat('GRAPHSFOLDER=', project_path_full, par, 'snap2stamps', par, 'graphs'); % path of the graphs folder of snap2stamps
                        masterfolder = strcat('MASTER=', master_file_destination); % master image path
                        
                        if ispc
                            f_project_conf_slaves = fopen(strcat(project_path_full, '\snap2stamps\bin\project.conf'),'w');
                            j = 0;
                            while f_project_conf_slaves == -1 && j < 20
                                f_project_conf_slaves = fopen(strcat(project_path_full, '\snap2stamps\bin\project.conf'),'w');
                                j = j+1;
                            end
                            fprintf(f_project_conf_slaves,'%s\r\n', '######### CONFIGURATION FILE ######');
                            fprintf(f_project_conf_slaves,'%s\r\n', '###################################');
                            fprintf(f_project_conf_slaves,'%s\r\n', '# PROJECT DEFINITION');
                            fprintf(f_project_conf_slaves,'%s\r\n', projectfolder);
                            fprintf(f_project_conf_slaves,'%s\r\n', graphsfolder);
                            fprintf(f_project_conf_slaves,'%s\r\n', '##################################');
                            fprintf(f_project_conf_slaves,'%s\r\n', '# PROCESSING PARAMETERS');
                            fprintf(f_project_conf_slaves,'SWATHS=IW1,IW2,IW3\r\n');
                            fprintf(f_project_conf_slaves,'%s\r\n', masterfolder);
                            fprintf(f_project_conf_slaves,'POLARISATION=%s\r\n', polarisation);
                            fprintf(f_project_conf_slaves,'TC_COHERENCE=%.0f\n', coherence_tc);
                            fprintf(f_project_conf_slaves,'%s\r\n', '##################################');
                            fprintf(f_project_conf_slaves,'%s\r\n', '# DEM DEFINITION');
                            fprintf(f_project_conf_slaves,'DEMNAME=%s\r\n', dem_name);
                            fprintf(f_project_conf_slaves,'DEMFILE=%s\r\n', dem_file);
                            fprintf(f_project_conf_slaves,'COREGDTMNAME=%s\r\n', dem_name_coreg);
                            fprintf(f_project_conf_slaves,'COREGDTMFILE=%s\r\n', dem_file_coreg);
                            fprintf(f_project_conf_slaves,'DEMRESAMPLING=%s\r\n', dem_resampling);
                            fprintf(f_project_conf_slaves,'%s\r\n', '##################################');
                            fprintf(f_project_conf_slaves,'%s\r\n', '# AOI BBOX DEFINITION');
                            fprintf(f_project_conf_slaves,'LONMIN=%.3f\r\n', lon_min);
                            fprintf(f_project_conf_slaves,'LATMIN=%.3f\r\n', lat_min);
                            fprintf(f_project_conf_slaves,'LONMAX=%.3f\r\n', lon_max);
                            fprintf(f_project_conf_slaves,'LATMAX=%.3f\r\n', lat_max);
                            fprintf(f_project_conf_slaves,'%s\r\n', '##################################');
                            fprintf(f_project_conf_slaves,'%s\r\n', '# SNAP GPT');
                            fprintf(f_project_conf_slaves,'GPTBIN_PATH=%s\r\n', gptbin_path);
                            fprintf(f_project_conf_slaves,'%s\r\n', '##################################');
                            fprintf(f_project_conf_slaves,'%s\r\n', '# COMPUTING RESOURCES TO EMPLOY');
                            fprintf(f_project_conf_slaves,'CPU=%.0f\r\n', cpu);
                            fprintf(f_project_conf_slaves,'CACHE=%s\r\n', cache);
                            fprintf(f_project_conf_slaves,'%s\r\n', '##################################');
                            f_project_conf_slaves = fclose(f_project_conf_slaves);
                        elseif isunix && ~ismac
                            f_project_conf_slaves = fopen(strcat(project_path_full, '/snap2stamps/bin/project.conf'),'w');
                            j = 0;
                            while f_project_conf_slaves == -1 && j < 20
                                f_project_conf_slaves = fopen(strcat(project_path_full, '/snap2stamps/bin/project.conf'),'w');
                                j = j+1;
                            end
                            fprintf(f_project_conf_slaves,'%s\n', '######### CONFIGURATION FILE ######');
                            fprintf(f_project_conf_slaves,'%s\n', '###################################');
                            fprintf(f_project_conf_slaves,'%s\n', '# PROJECT DEFINITION');
                            fprintf(f_project_conf_slaves,'%s\n', projectfolder);
                            fprintf(f_project_conf_slaves,'%s\n', graphsfolder);
                            fprintf(f_project_conf_slaves,'%s\n', '##################################');
                            fprintf(f_project_conf_slaves,'%s\n', '# PROCESSING PARAMETERS');
                            fprintf(f_project_conf_slaves,'SWATHS=IW1,IW2,IW3\r\n');
                            fprintf(f_project_conf_slaves,'%s\r\n', masterfolder);
                            fprintf(f_project_conf_slaves,'POLARISATION=%s\r\n', polarisation);
                            fprintf(f_project_conf_slaves,'TC_COHERENCE=%.0f\n', coherence_tc);
                            fprintf(f_project_conf_slaves,'%s\r\n', '##################################');
                            fprintf(f_project_conf_slaves,'%s\r\n', '# DEM DEFINITION');
                            fprintf(f_project_conf_slaves,'DEMNAME=%s\r\n', dem_name);
                            fprintf(f_project_conf_slaves,'DEMFILE=%s\r\n', dem_file);
                            fprintf(f_project_conf_slaves,'COREGDTMNAME=%s\r\n', dem_name_coreg);
                            fprintf(f_project_conf_slaves,'COREGDTMFILE=%s\r\n', dem_file_coreg);
                            fprintf(f_project_conf_slaves,'DEMRESAMPLING=%s\r\n', dem_resampling);
                            fprintf(f_project_conf_slaves,'%s\n', '##################################');
                            fprintf(f_project_conf_slaves,'%s\n', '# AOI BBOX DEFINITION');
                            fprintf(f_project_conf_slaves,'LONMIN=%.3f\n', lon_min);
                            fprintf(f_project_conf_slaves,'LATMIN=%.3f\n', lat_min);
                            fprintf(f_project_conf_slaves,'LONMAX=%.3f\n', lon_max);
                            fprintf(f_project_conf_slaves,'LATMAX=%.3f\n', lat_max);
                            fprintf(f_project_conf_slaves,'%s\n', '##################################');
                            fprintf(f_project_conf_slaves,'%s\n', '# SNAP GPT');
                            fprintf(f_project_conf_slaves,'GPTBIN_PATH=%s\n', gptbin_path);
                            fprintf(f_project_conf_slaves,'%s\n', '##################################');
                            fprintf(f_project_conf_slaves,'%s\n', '# COMPUTING RESOURCES TO EMPLOY');
                            fprintf(f_project_conf_slaves,'CPU=%.0f\n', cpu);
                            fprintf(f_project_conf_slaves,'CACHE=%s\n', cache);
                            fprintf(f_project_conf_slaves,'%s\n', '##################################');
                            f_project_conf_slaves = fclose(f_project_conf_slaves);
                        elseif ismac
                            f_project_conf_slaves = fopen(strcat(project_path_full, '/snap2stamps/bin/project.conf'),'w');
                            j = 0;
                            while f_project_conf_slaves == -1 && j < 20
                                f_project_conf_slaves = fopen(strcat(project_path_full, '/snap2stamps/bin/project.conf'),'w');
                                j = j+1;
                            end
                            fprintf(f_project_conf_slaves,'%s\n', '######### CONFIGURATION FILE ######');
                            fprintf(f_project_conf_slaves,'%s\n', '###################################');
                            fprintf(f_project_conf_slaves,'%s\n', '# PROJECT DEFINITION');
                            fprintf(f_project_conf_slaves,'%s\n', projectfolder);
                            fprintf(f_project_conf_slaves,'%s\n', graphsfolder);
                            fprintf(f_project_conf_slaves,'%s\n', '##################################');
                            fprintf(f_project_conf_slaves,'%s\n', '# PROCESSING PARAMETERS');
                            fprintf(f_project_conf_slaves,'SWATHS=IW1,IW2,IW3\r\n');
                            fprintf(f_project_conf_slaves,'%s\r\n', masterfolder);
                            fprintf(f_project_conf_slaves,'POLARISATION=%s\r\n', polarisation);
                            fprintf(f_project_conf_slaves,'TC_COHERENCE=%.0f\n', coherence_tc);
                            fprintf(f_project_conf_slaves,'%s\r\n', '##################################');
                            fprintf(f_project_conf_slaves,'%s\r\n', '# DEM DEFINITION');
                            fprintf(f_project_conf_slaves,'DEMNAME=%s\r\n', dem_name);
                            fprintf(f_project_conf_slaves,'DEMFILE=%s\r\n', dem_file);
                            fprintf(f_project_conf_slaves,'COREGDTMNAME=%s\r\n', dem_name_coreg);
                            fprintf(f_project_conf_slaves,'COREGDTMFILE=%s\r\n', dem_file_coreg);
                            fprintf(f_project_conf_slaves,'DEMRESAMPLING=%s\r\n', dem_resampling);
                            fprintf(f_project_conf_slaves,'%s\n', '##################################');
                            fprintf(f_project_conf_slaves,'%s\n', '# AOI BBOX DEFINITION');
                            fprintf(f_project_conf_slaves,'LONMIN=%.3f\n', lon_min);
                            fprintf(f_project_conf_slaves,'LATMIN=%.3f\n', lat_min);
                            fprintf(f_project_conf_slaves,'LONMAX=%.3f\n', lon_max);
                            fprintf(f_project_conf_slaves,'LATMAX=%.3f\n', lat_max);
                            fprintf(f_project_conf_slaves,'%s\n', '##################################');
                            fprintf(f_project_conf_slaves,'%s\n', '# SNAP GPT');
                            fprintf(f_project_conf_slaves,'GPTBIN_PATH=%s\n', gptbin_path);
                            fprintf(f_project_conf_slaves,'%s\n', '##################################');
                            fprintf(f_project_conf_slaves,'%s\n', '# COMPUTING RESOURCES TO EMPLOY');
                            fprintf(f_project_conf_slaves,'CPU=%.0f\n', cpu);
                            fprintf(f_project_conf_slaves,'CACHE=%s\n', cache);
                            fprintf(f_project_conf_slaves,'%s\n', '##################################');
                            f_project_conf_slaves = fclose(f_project_conf_slaves);
                        end
                        
                        % EXECUTE THE .CONF FILE VIA A BATCH/BASH FILE
                        dp = ('::');
                        step1_slaves = ('SEN_slaves_prep.py project.conf'); % slaves preparation
                        step2_slaves = ('SEN_splitting_slaves.py project.conf'); % slaves splitting & apply orbits
                        step3_slaves = ('SEN_coreg_ifg_topsar.py project.conf'); % coregistration & interferogram
                        step4_slaves = ('SEN_stamps_export.py project.conf'); % StaMPS export
                        step5_slaves = ('SEN_average_intensity.py project.conf'); % average instensity
                        step6_slaves = ('SEN_terrain_correction.py project.conf'); % terrain corrected coherence and lia
                        
                            % CASES DEFENDING ON FIRST STEP
                            if isnumeric(first_step)
                                first_step_num = first_step;
                            elseif ischar(first_step)
                                first_step_num = str2double(first_step);
                            end
                            if first_step_num == 1
                                step_slaves_1 = [python space step1_slaves];
                                step_slaves_2 = [python space step2_slaves];
                                step_slaves_3 = [python space step3_slaves];
                                step_slaves_4 = [python space step4_slaves];
                                step_slaves_5 = [python space step5_slaves];
                                if coherence_tc == 0
                                    step_slaves_6 = [python space step6_slaves];
                                end
                            elseif first_step_num == 2
                                step_slaves_1 = [dp python space step1_slaves];
                                step_slaves_2 = [python space step2_slaves];
                                step_slaves_3 = [python space step3_slaves];
                                step_slaves_4 = [python space step4_slaves];
                                step_slaves_5 = [python space step5_slaves];
                                if coherence_tc == 0
                                    step_slaves_6 = [python space step6_slaves];
                                end
                            elseif first_step_num == 3
                                step_slaves_1 = [dp python space step1_slaves];
                                step_slaves_2 = [dp python space step2_slaves];
                                step_slaves_3 = [python space step3_slaves];
                                step_slaves_4 = [python space step4_slaves];
                                step_slaves_5 = [python space step5_slaves];
                                if coherence_tc == 0
                                    step_slaves_6 = [python space step6_slaves];
                                end
                            elseif first_step_num == 4
                                step_slaves_1 = [dp python space step1_slaves];
                                step_slaves_2 = [dp python space step2_slaves];
                                step_slaves_3 = [dp python space step3_slaves];
                                step_slaves_4 = [python space step4_slaves];
                                step_slaves_5 = [python space step5_slaves];
                                if coherence_tc == 0
                                    step_slaves_6 = [python space step6_slaves];
                                end
                             elseif first_step_num == 5
                                step_slaves_1 = [dp python space step1_slaves];
                                step_slaves_2 = [dp python space step2_slaves];
                                step_slaves_3 = [dp python space step3_slaves];
                                step_slaves_4 = [dp python space step4_slaves];
                                step_slaves_5 = [python space step5_slaves];
                                if coherence_tc == 0
                                    step_slaves_6 = [python space step6_slaves];
                                end
                            elseif first_step_num == 6
                                step_slaves_1 = [dp python space step1_slaves];
                                step_slaves_2 = [dp python space step2_slaves];
                                step_slaves_3 = [dp python space step3_slaves];
                                step_slaves_4 = [dp python space step4_slaves];
                                step_slaves_5 = [dp python space step5_slaves];
                                if coherence_tc == 0
                                    step_slaves_6 = [python space step6_slaves];
                                else
                                    updateOutput(app, ['You are running just the terrain correction for coherence and LIA bands, but you have set tc_coherence = 1. ' ...
                                        'Please change it to 0 to perform this step.'])
                                end
                            end
                        
                        path_1_slaves = fullfile(project_path_full, par, 'snap2stamps', par, 'bin');
                        slaves_proof = strcat(project_path_full, par, 'slaves', par, 'dummyDownload.txt');
                        
                        if ispc
                            f_snap2stamps_slaves = fopen(strcat(project_path_full, '\snap2stamps\bin\snap2stamps_slaves.bat'),'w');
                            j = 0;
                            while f_snap2stamps_slaves == -1 && j < 20
                                f_snap2stamps_slaves = fopen(strcat(project_path_full, '\snap2stamps\bin\snap2stamps_slaves.bat'),'w');
                                j = j+1;
                            end
                            fprintf(f_snap2stamps_slaves,'@echo off \r\n');
                            fprintf(f_snap2stamps_slaves,'cd "%s"\r\n',path_1_slaves);
                            fprintf(f_snap2stamps_slaves,'%s\r\n',step_slaves_1);
                            fprintf(f_snap2stamps_slaves,'%s\r\n',step_slaves_2);
                            fprintf(f_snap2stamps_slaves,'%s\r\n',step_slaves_3);
                            fprintf(f_snap2stamps_slaves,'%s\r\n',step_slaves_4);
                            fprintf(f_snap2stamps_slaves,'%s\r\n',step_slaves_5);
                            if coherence_tc == 0
                                fprintf(f_snap2stamps_slaves,'%s\r\n',step_slaves_6);
                            end
                            fprintf(f_snap2stamps_slaves,'cd "%s"\r\n',path_1_download);
                            fprintf(f_snap2stamps_slaves,'type nul > dummyDownload.txt \r\n');
                            fprintf(f_snap2stamps_slaves,'exit \r\n');
                            f_snap2stamps_slaves = fclose(f_snap2stamps_slaves);
                        elseif isunix && ~ismac
                            f_snap2stamps_slaves = fopen(strcat(project_path_full, '/snap2stamps/bin/snap2stamps_slaves.sh'),'w');
                            j = 0;
                            while f_snap2stamps_slaves == -1 && j < 20
                                f_snap2stamps_slaves = fopen(strcat(project_path_full, '/snap2stamps/bin/snap2stamps_slaves.sh'),'w');
                                j = j+1;
                            end
                            fprintf(f_snap2stamps_slaves,'#!/bin/bash \n');
                            fprintf(f_snap2stamps_slaves,'cd "%s"\n',path_1_slaves);
                            fprintf(f_snap2stamps_slaves,'%s\n',step_slaves_1);
                            fprintf(f_snap2stamps_slaves,'%s\n',step_slaves_2);
                            fprintf(f_snap2stamps_slaves,'%s\n',step_slaves_3);
                            fprintf(f_snap2stamps_slaves,'%s\n',step_slaves_4);
                            fprintf(f_snap2stamps_slaves,'%s\n',step_slaves_5);
                            if coherence_tc == 0
                                fprintf(f_snap2stamps_slaves,'%s\n',step_slaves_6);
                            end
                            fprintf(f_snap2stamps_slaves,'sleep 5 \n');
                            fprintf(f_snap2stamps_slaves,'cd "%s"\n',path_1_download);
                            fprintf(f_snap2stamps_slaves,'touch dummyDownload.txt \n');
                            f_snap2stamps_slaves = fclose(f_snap2stamps_slaves);
                        elseif ismac
                            f_snap2stamps_slaves = fopen(strcat(project_path_full, '/snap2stamps/bin/snap2stamps_slaves.sh'),'w');
                            j = 0;
                            while f_snap2stamps_slaves == -1 && j < 20
                                f_snap2stamps_slaves = fopen(strcat(project_path_full, '/snap2stamps/bin/snap2stamps_slaves.sh'),'w');
                                j = j+1;
                            end
                            fprintf(f_snap2stamps_slaves,'#!/bin/bash \n');
                            fprintf(f_snap2stamps_slaves,'cd "%s"\n',path_1_slaves);
                            fprintf(f_snap2stamps_slaves,'%s\n',step_slaves_1);
                            fprintf(f_snap2stamps_slaves,'%s\n',step_slaves_2);
                            fprintf(f_snap2stamps_slaves,'%s\n',step_slaves_3);
                            fprintf(f_snap2stamps_slaves,'%s\n',step_slaves_4);
                            fprintf(f_snap2stamps_slaves,'%s\n',step_slaves_5);
                            if coherence_tc == 0
                                fprintf(f_snap2stamps_slaves,'%s\n',step_slaves_6);
                            end
                            fprintf(f_snap2stamps_slaves,'sleep 5 \n');
                            fprintf(f_snap2stamps_slaves,'cd "%s"\n',path_1_download);
                            fprintf(f_snap2stamps_slaves,'touch dummyDownload.txt \n');
                            f_snap2stamps_slaves = fclose(f_snap2stamps_slaves);
                        end
                        
                        updateOutput(app, '----------------------- STEP 3: Slaves processing started -----------------------');
                        
                        if ispc
                            path_2_slaves = fullfile(project_path_full, '\snap2stamps\bin\snap2stamps_slaves.bat &');
                            system(path_2_slaves); % execute the batch file
                        elseif isunix && ~ismac
                            path_2_slaves = (strcat(project_path_full, '/snap2stamps/bin/snap2stamps_slaves.sh'));
                            chmod_in = ('chmod +x');
                            chmod = [chmod_in space path_2_slaves];
                            path_2_slaves = [xterm space path_2_slaves];
                            system(chmod);
                            system(path_2_slaves, '-echo');
                        elseif ismac
                            path_2_slaves = strcat(project_path_full, '/snap2stamps/bin/snap2stamps_slaves.sh');
                            chmod_in = 'chmod +x';
                            chmod = [chmod_in space path_2_slaves];
                            path_2_slaves = ['open -a Terminal ' path_2_slaves]; 
                            system(chmod);
                            system(path_2_slaves, '-echo');
                        end
                        
                        % wait until the cmd has finished the work
                        while exist(slaves_proof,'file') == 0
                              pause(1);
                        end
                        delete(slaves_proof);

                        % DYNAMIC WAIT FOR GEO-TIFF CREATION (Timeout: 5 minutes)
                        %updateOutput(app, 'Waiting for .tif files to be written on disk...');
                        timeout = 300; % Maximum wait time in seconds
                        elapsed = 0;
                        intensity_folder = fullfile(project_path_full, 'intensity');
                        
                        % Wait until the folder exists and is not empty, but do not exceed the timeout
                        while (~exist(intensity_folder, 'dir') || isempty(dir(fullfile(intensity_folder, '*.tif')))) && elapsed < timeout
                            pause(5); % Check every 5 seconds
                            elapsed = elapsed + 5;
                        end
                        
                        if elapsed >= timeout
                            updateOutput(app, 'WARNING: Timeout exceeded. The .tif files were not found.');
                        else
                            %updateOutput(app, '.tif files found, starting reprojection...');
                            % Leave a 2-second safety margin for the OS to release the file lock
                            pause(2); 
                        end
                        
                        % APPLY THE CORRECT PROJECTION TO THE AVERAGE INTENSITY GEOTIFF
                        if first_step_num <= 5
                            
                            % --- 1. INTENSITY ---
                            if exist(intensity_folder, 'dir')
                                intensity_files = dir(fullfile(intensity_folder, '*.tif'));
                                for i = 1:length(intensity_files)
                                    file_path = fullfile(intensity_folder, intensity_files(i).name);
                                    info = geotiffinfo(file_path);
                                    [image_data, R_int] = geotiffread(file_path);
                                    
                                    image_data = double(image_data);
                                    
                                    height = info.Height; 
                                    width = info.Width; 
                                    [rows,cols] = meshgrid(1:width,1:height);
                                    R = info.SpatialRef;
                                    
                                    lon_step = (R.LongitudeLimits(2)-R.LongitudeLimits(1))/(R.RasterSize(2)-1);
                                    lat_step = (R.LatitudeLimits(2)-R.LatitudeLimits(1))/(R.RasterSize(1)-1);
                                    lon = repmat(R.LongitudeLimits(1):lon_step:R.LongitudeLimits(2), R.RasterSize(1), 1);
                                    lat = repmat((fliplr(R.LatitudeLimits(1):lat_step:R.LatitudeLimits(2)))', 1, R.RasterSize(2));
                                    
                                    [x, y] = projfwd(projcrs(epsg_code), lat, lon);
                                    
                                    R_proj = maprefcells([min(min(x)), max(max(x))], [min(min(y)), max(max(y))], size(image_data), 'ColumnsStartFrom', 'north');
                                    R_proj.ProjectedCRS = projcrs(epsg_code);
                                    
                                    geotiffwrite(file_path, image_data, R_proj, "CoordRefSysCode", strcat('EPSG:', num2str(epsg_code)));
                                    clear file_path image_data
                                end
                            end
                        end
                        
                        % --- 2. COHERENCE ---
                        coherence_folder = fullfile(project_path_full, 'coherence');
                        if exist(coherence_folder, 'dir')
                            coherence_files = dir(fullfile(coherence_folder, '*.tif'));
                            for i = 1:length(coherence_files) 
                                file_path = fullfile(coherence_folder, coherence_files(i).name); 
                                info = geotiffinfo(file_path);
                                [image_data, R_coh] = geotiffread(file_path);
                                
                                image_data = double(image_data);
                                
                                height = info.Height; 
                                width = info.Width; 
                                [rows,cols] = meshgrid(1:width,1:height);
                                R = info.SpatialRef;
                                
                                lon_step = (R.LongitudeLimits(2)-R.LongitudeLimits(1))/(R.RasterSize(2)-1);
                                lat_step = (R.LatitudeLimits(2)-R.LatitudeLimits(1))/(R.RasterSize(1)-1);
                                lon = repmat(R.LongitudeLimits(1):lon_step:R.LongitudeLimits(2), R.RasterSize(1), 1);
                                lat = repmat((fliplr(R.LatitudeLimits(1):lat_step:R.LatitudeLimits(2)))', 1, R.RasterSize(2));
                                
                                [x, y] = projfwd(projcrs(epsg_code), lat, lon);
                                
                                R_proj = maprefcells([min(min(x)), max(max(x))], [min(min(y)), max(max(y))], size(image_data), 'ColumnsStartFrom', 'north');
                                R_proj.ProjectedCRS = projcrs(epsg_code);
                                
                                geotiffwrite(file_path, image_data, R_proj, "CoordRefSysCode", strcat('EPSG:', num2str(epsg_code)));
                                clear file_path image_data
                            end
                        end
                        
                        % --- 3. LIA (Local Incidence Angle) ---
                        lia_folder = fullfile(project_path_full, 'lia');
                        if exist(lia_folder, 'dir')
                            lia_files = dir(fullfile(lia_folder, '*.tif')); 
                            for i = 1:length(lia_files) 
                                file_path = fullfile(lia_folder, lia_files(i).name); 
                                info = geotiffinfo(file_path);
                                [image_data, R_lia] = geotiffread(file_path);
                                
                                if size(image_data, 3) >= 2
                                    image_data = image_data(:,:,2); % Keep only the second band
                                    image_data = double(image_data);
                                    
                                    height = info.Height; 
                                    width = info.Width; 
                                    [rows,cols] = meshgrid(1:width,1:height);
                                    R = info.SpatialRef;
                                    
                                    lon_step = (R.LongitudeLimits(2)-R.LongitudeLimits(1))/(R.RasterSize(2)-1);
                                    lat_step = (R.LatitudeLimits(2)-R.LatitudeLimits(1))/(R.RasterSize(1)-1);
                                    lon = repmat(R.LongitudeLimits(1):lon_step:R.LongitudeLimits(2), R.RasterSize(1), 1);
                                    lat = repmat((fliplr(R.LatitudeLimits(1):lat_step:R.LatitudeLimits(2)))', 1, R.RasterSize(2));
                                    
                                    [x, y] = projfwd(projcrs(epsg_code), lat, lon);
                                    
                                    R_proj = maprefcells([min(min(x)), max(max(x))], [min(min(y)), max(max(y))], size(image_data), 'ColumnsStartFrom', 'north');
                                    R_proj.ProjectedCRS = projcrs(epsg_code);
                                    
                                    geotiffwrite(file_path, image_data, R_proj, "CoordRefSysCode", strcat('EPSG:', num2str(epsg_code)));
                                end
                                clear file_path image_data
                            end
                        end
                        
                        updateOutput(app, '----------------------- STEP 3: Slaves processing finished -----------------------');
    
                        if app.StopFlag
                            break;
                        end

                        % DYNAMIC STAMPS FOLDER AUTOMATION (ORBIT & DATE EXTRACTION)
                        coreg_dir = fullfile(project_path_full, 'coreg');
                        dim_files = dir(fullfile(coreg_dir, '*.dim'));
                        
                        if isempty(dim_files)
                            updateOutput(app, 'ERROR: No coregistered files found to extract dates/orbit!');
                            return;
                        end
                        
                        % 1. Extract all dates from the filenames to find absolute Min and Max
                        all_dates = NaT(0); % Initialize empty datetime array
                        for idx = 1:length(dim_files)
                            % Parse the YYYYMMDD_YYYYMMDD.dim filename structure
                            tokens = regexp(dim_files(idx).name, '(\d{8})_(\d{8})\.dim', 'tokens');
                            if ~isempty(tokens)
                                all_dates(end+1, 1) = datetime(tokens{1}{1}, 'InputFormat', 'yyyyMMdd');
                                all_dates(end+1, 1) = datetime(tokens{1}{2}, 'InputFormat', 'yyyyMMdd');
                            end
                        end
                        
                        % Sort and format to English MmmYY (e.g., Jul19, Aug23)
                        min_date = min(all_dates);
                        max_date = max(all_dates);
                        date_str_start = char(datetime(min_date, 'Format', 'MMMyy', 'Locale', 'en_US'));
                        date_str_end   = char(datetime(max_date, 'Format', 'MMMyy', 'Locale', 'en_US'));
                        
                        % 2. Read the Ground Truth Orbit from the Master .dim XML
                        dim_path = fullfile(dim_files(1).folder, dim_files(1).name);
                        dim_text = fileread(dim_path);
                        
                        orbit_type = 'UNKNOWN';
                        if contains(upper(dim_text), 'ASCENDING')
                            orbit_type = 'ASC';
                        elseif contains(upper(dim_text), 'DESCENDING')
                            orbit_type = 'DES';
                        end
                        
                        % 3. Automatically Construct the Target Folder Name!
                        stamps_folder = sprintf('%s_%s_%s', orbit_type, date_str_start, date_str_end);
                        updateOutput(app, ['Dynamically generated StaMPS folder name: ' stamps_folder]);
                        
                        project_parent_path_full = prep_folder;
                        exist_StaMPS_A = exist(strcat(project_path_full, par, 'PHASE_StaMPS.mlapp'), "file");
                        if exist_StaMPS_A == 2 % the code automatically checks if the folder for the StaMPS processing has already been created or not
                            
                            project_parent_path_full = prep_folder;
                            mkdir(strcat(project_parent_path_full, par, stamps_folder));
                            movefile(strcat(project_path_full, par, 'PHASE_StaMPS.mlapp'), strcat(project_parent_path_full, par, stamps_folder, par, 'PHASE_StaMPS.mlapp'));
                        
                        else
                            
                            stamps_folder_full = fullfile(project_parent_path_full, stamps_folder);
                            dest_mlapp = fullfile(stamps_folder_full, 'PHASE_StaMPS.mlapp');
                            
                            % Create the StaMPS folder
                            if ~isfolder(stamps_folder_full)
                                mkdir(stamps_folder_full);
                                updateOutput(app, ['Created new StaMPS folder: ' stamps_folder]);
                            else
                                updateOutput(app, 'The StaMPS processing folder already exists - results will be overwritten!');
                            end
                            
                            copied = false;

                            % 1. Check if the file is ALREADY in the folder
                            if isfile(dest_mlapp)
                                updateOutput(app, 'PHASE_StaMPS.mlapp is already present in the folder.');
                                copied = true;
                            else
                                % 2. Try to copy PHASE_StaMPS.mlapp from a previous orbit folder
                                previous_folders = dir(fullfile(project_parent_path_full, [orbit_type '_*']));
                                
                                if ~isempty(previous_folders)
                                    for idx = 1:length(previous_folders)
                                        % Skip the loop if this folder is the one we are currently working in
                                        if strcmp(previous_folders(idx).name, stamps_folder)
                                            continue; 
                                        end
                                        
                                        source_mlapp = fullfile(project_parent_path_full, previous_folders(idx).name, 'PHASE_StaMPS.mlapp');
                                        
                                        if isfile(source_mlapp)
                                            copyfile(source_mlapp, dest_mlapp);
                                            updateOutput(app, ['Copied PHASE_StaMPS.mlapp from previous folder: ' previous_folders(idx).name]);
                                            copied = true;
                                            break; % Stop searching once successfully copied
                                        end
                                    end
                                end
                            end
                            
                            % If still couldn't copy it, give a clear warning but DO NOT crash
                            if ~copied
                                updateOutput(app, 'WARNING: PHASE_StaMPS.mlapp was not found in any previous folder.');
                                updateOutput(app, 'Please manually copy PHASE_StaMPS.mlapp into the folder:');
                                updateOutput(app, stamps_folder_full);
                            end
                        end
                        
                        % EVENTUAL REMOVAL OF THE SLAVES IMAGES TO SAVE DISK SPACE
                        if slaves_removal == 0 % check if the removal of the slaves images has to be done or not
                        
                            updateOutput(app, '----------------------- STEP 4: Slaves removal started -----------------------');
                        
                            rmdir(strcat(project_path_full, par, 'slaves'), 's'); % removal of slaves folder to save disk space
                            mkdir(project_path_full, 'slaves'); % create slaves folder
                        
                            updateOutput(app, '----------------------- STEP 4: Slaves removal finished -----------------------');
                        
                            else
                        
                            updateOutput(app, '----------------------- STEP 4: Slaves removal skipped -----------------------');
                            
                        end
                        
                        % OPEN THE PHASE_StaMPS.mlapp APPLICATION ONLY IF THE USER IS USING LINUX
                        if ispc
                            updateOutput(app, '-- Now go in Linux, configure the input file and open the PHASE_StaMPS.mlapp --');
                        elseif isunix && ~ismac
                            edit(strcat(project_parent_path_full, par, stamps_folder, par, 'PHASE_StaMPS.mlapp'));
                        elseif ismac
                            updateOutput(app, '-- Now go in Linux, configure the input file and open the PHASE_StaMPS.mlapp --');
                        end

                        %% ----------------------------------------------------
                       
                        % Display a message when the script is done
                        updateOutput(app, 'Script SEN_Preprocessing.m completed.');
        
                        % Change lamp color to green (success)
                        app.PreprocessingstatusLamp.Color = [0, 1, 0]; % Green
    
                    catch ME
    
                        % If an error occurs
                        updateOutput(app, 'An error occurred during script execution. Please check each step log file!');
    
                        % Change lamp color to red (error)
                        app.PreprocessingstatusLamp.Color = [1, 0, 0]; % Red

                        % Show the actual error
                        rethrow(ME)
    
                    end

                    condition = 1;
                end

            elseif app.constellation == "CSK"

                condition = 0;

                while condition == 0

                    app.MessagesTextArea.Value = '';
    
                    % Display a message in the UIAxes
                    updateOutput(app, 'Button pressed! Running the script...');
    
                    % Change lamp color to yellow (running)
                    app.PreprocessingstatusLamp_2.Color = [1, 1, 0]; % Yellow
                
                    try
    
                        %% COSMO-SKYMED PROCESSING ----------------------------
    
                        pwd;
                        prep_folder = pwd;
                        addpath(genpath(prep_folder));
                        
                        %% ------------------ IMPORT OF THE REQUIRED VARIABLES --------------------
                        
                        % READ OF THE INPUT VARIABLES
                        par = filesep;
    
                        load(strcat('.', par, 'PHASE_Preprocessing', par, 'input_preprocessing.mat'), 'python', ...
                                                'master_date', 'auto_master', 'master_processing', 'lon_min', ...
                                                'lat_min', 'lon_max', 'lat_max', 'slaves_removal', 'dem_name', 'dem_file', ...
                                                'first_step', 'num_gcp', 'coherence_tc', 'epsg_code', 'gptbin_path', 'cpu', 'cache');
                        
                        % PROJECT FOLDER
                        project_path_full = strcat(prep_folder, par, 'PHASE_Preprocessing');
                        mkdir(project_path_full, 'slaves'); % create slaves folder
                        mkdir(project_path_full, 'master'); % create master folder
                        
                        % GENERAL VARIABLES
                        space = (' ');
                        python = (python);
                        path_1_download = strcat(project_path_full, par, 'slaves');
    
                        % CONVERT VARIABLES FORMAT
                        if isa(master_date, 'datetime')
                            master_date = datestr(master_date, 'yyyymmdd');
                        end
                        
                        
                        %% ------------------------ MASTER PROCESSING -----------------------------
                        
                        if master_processing == 0 % check if the processing of the master has to be done or not
                        
                            updateOutput(app, '----------------------- STEP 1: Master processing started -----------------------');
                        
                            % 1. THE NUCLEAR RESET: Flatten all .h5 files back to the root of /slaves
                            
                            % Sweep 1: Rescue any Master .h5 files from anywhere in /master
                            master_h5 = dir(fullfile(project_path_full, 'master', '**', '*.h5'));
                            for i = 1:length(master_h5)
                                movefile(fullfile(master_h5(i).folder, master_h5(i).name), fullfile(project_path_full, 'slaves'));
                            end
                            
                            % Sweep 2: Rescue all Slave .h5 files from their date subfolders
                            slave_h5 = dir(fullfile(project_path_full, 'slaves', '**', '*.h5'));
                            for i = 1:length(slave_h5)
                                % Only move it if it is NOT already in the root of /slaves
                                if ~strcmp(slave_h5(i).folder, fullfile(project_path_full, 'slaves'))
                                    movefile(fullfile(slave_h5(i).folder, slave_h5(i).name), fullfile(project_path_full, 'slaves'));
                                end
                            end
                            
                            % Sweep 3: Delete all the now-empty date subfolders inside /slaves
                            slave_subdirs = dir(fullfile(project_path_full, 'slaves'));
                            for i = 1:length(slave_subdirs)
                                if slave_subdirs(i).isdir && ~strcmp(slave_subdirs(i).name, '.') && ~strcmp(slave_subdirs(i).name, '..')
                                    rmdir(fullfile(slave_subdirs(i).folder, slave_subdirs(i).name), 's');
                                end
                            end
                            
                            % Clean old processing folders to prevent conflicts
                            folders_to_delete = {'subset', 'coreg', 'ifg', 'lia', 'coherence', 'intensity'};
                            for i=1:length(folders_to_delete)
                                if exist(fullfile(project_path_full, folders_to_delete{i}), 'dir')
                                    rmdir(fullfile(project_path_full, folders_to_delete{i}), 's');
                                end
                                mkdir(fullfile(project_path_full, folders_to_delete{i}));
                            end
                            
                            % Wipe master folder internals and recreate
                            if exist(fullfile(project_path_full, 'master'), 'dir')
                                rmdir(fullfile(project_path_full, 'master'), 's');
                            end
                            mkdir(fullfile(project_path_full, 'master'));
                        
                            % 2. CREATE project_master.conf
                            projectfolder = strcat('PROJECTFOLDER=',project_path_full); 
                            graphsfolder = strcat('GRAPHSFOLDER=', project_path_full, par, 'snap2stamps', par, 'graphs'); 
                            
                            if ispc
                                f_project_conf_master = fopen(strcat(project_path_full, '\snap2stamps\bin\project_master.conf'),'w');
                                j = 0;
                                while f_project_conf_master == -1 && j < 20
                                    f_project_conf_master = fopen(strcat(project_path_full, '\snap2stamps\bin\project_master.conf'),'w');
                                    j = j+1;
                                end
                                fprintf(f_project_conf_master,'%s\r\n', '######### CONFIGURATION FILE ######');
                                fprintf(f_project_conf_master,'%s\r\n', '# PROJECT DEFINITION');
                                fprintf(f_project_conf_master,'%s\r\n', projectfolder);
                                fprintf(f_project_conf_master,'%s\r\n', graphsfolder);
                                fprintf(f_project_conf_master,'%s\r\n', '# SPLIT BBOX DEFINITION');
                                fprintf(f_project_conf_master,'LONMIN=%.3f\r\n', lon_min);
                                fprintf(f_project_conf_master,'LATMIN=%.3f\r\n', lat_min);
                                fprintf(f_project_conf_master,'LONMAX=%.3f\r\n', lon_max);
                                fprintf(f_project_conf_master,'LATMAX=%.3f\r\n', lat_max);
                                fprintf(f_project_conf_master,'AUTO_MASTER=%.0f\r\n', auto_master);
                                fprintf(f_project_conf_master,'MASTER_DATE=%s\r\n', master_date);
                                fprintf(f_project_conf_master,'%s\r\n', '# SNAP GPT');
                                fprintf(f_project_conf_master,'GPTBIN_PATH=%s\r\n', gptbin_path);
                                fprintf(f_project_conf_master,'%s\r\n', '# COMPUTING RESOURCES');
                                fprintf(f_project_conf_master,'CPU=%.0f\r\n', cpu);
                                fprintf(f_project_conf_master,'CACHE=%s\r\n', cache);
                                fclose(f_project_conf_master);
                                
                            elseif isunix && ~ismac
                                f_project_conf_master = fopen(strcat(project_path_full, '/snap2stamps/bin/project_master.conf'),'w');
                                j = 0;
                                while f_project_conf_master == -1 && j < 20
                                    f_project_conf_master = fopen(strcat(project_path_full, '/snap2stamps/bin/project_master.conf'),'w');
                                    j = j+1;
                                end
                                fprintf(f_project_conf_master,'%s\n', '######### CONFIGURATION FILE ######');
                                fprintf(f_project_conf_master,'%s\n', '# PROJECT DEFINITION');
                                fprintf(f_project_conf_master,'%s\n', projectfolder);
                                fprintf(f_project_conf_master,'%s\n', graphsfolder);
                                fprintf(f_project_conf_master,'%s\n', '# SPLIT BBOX DEFINITION');
                                fprintf(f_project_conf_master,'LONMIN=%.3f\n', lon_min);
                                fprintf(f_project_conf_master,'LATMIN=%.3f\n', lat_min);
                                fprintf(f_project_conf_master,'LONMAX=%.3f\n', lon_max);
                                fprintf(f_project_conf_master,'LATMAX=%.3f\n', lat_max);
                                fprintf(f_project_conf_master,'AUTO_MASTER=%.0f\n', auto_master);
                                fprintf(f_project_conf_master,'MASTER_DATE=%s\n', master_date);
                                fprintf(f_project_conf_master,'%s\n', '# SNAP GPT');
                                fprintf(f_project_conf_master,'GPTBIN_PATH=%s\n', gptbin_path);
                                fprintf(f_project_conf_master,'%s\n', '# COMPUTING RESOURCES');
                                fprintf(f_project_conf_master,'CPU=%.0f\n', cpu);
                                fprintf(f_project_conf_master,'CACHE=%s\n', cache);
                                fclose(f_project_conf_master);
                                
                            elseif ismac
                                f_project_conf_master = fopen(strcat(project_path_full, '/snap2stamps/bin/project_master.conf'),'w');
                                j = 0;
                                while f_project_conf_master == -1 && j < 20
                                    f_project_conf_master = fopen(strcat(project_path_full, '/snap2stamps/bin/project_master.conf'),'w');
                                    j = j+1;
                                end
                                fprintf(f_project_conf_master,'%s\n', '######### CONFIGURATION FILE ######');
                                fprintf(f_project_conf_master,'%s\n', '# PROJECT DEFINITION');
                                fprintf(f_project_conf_master,'%s\n', projectfolder);
                                fprintf(f_project_conf_master,'%s\n', graphsfolder);
                                fprintf(f_project_conf_master,'%s\n', '# SPLIT BBOX DEFINITION');
                                fprintf(f_project_conf_master,'LONMIN=%.3f\n', lon_min);
                                fprintf(f_project_conf_master,'LATMIN=%.3f\n', lat_min);
                                fprintf(f_project_conf_master,'LONMAX=%.3f\n', lon_max);
                                fprintf(f_project_conf_master,'LATMAX=%.3f\n', lat_max);
                                fprintf(f_project_conf_master,'AUTO_MASTER=%.0f\n', auto_master);
                                fprintf(f_project_conf_master,'MASTER_DATE=%s\n', master_date);
                                fprintf(f_project_conf_master,'%s\n', '# SNAP GPT');
                                fprintf(f_project_conf_master,'GPTBIN_PATH=%s\n', gptbin_path);
                                fprintf(f_project_conf_master,'%s\n', '# COMPUTING RESOURCES');
                                fprintf(f_project_conf_master,'CPU=%.0f\n', cpu);
                                fprintf(f_project_conf_master,'CACHE=%s\n', cache);
                                fclose(f_project_conf_master);
                            end
                        
                            % 3. EXECUTE MASTER SELECTOR AND MASTER PREP BATCH SCRIPT
                            updateOutput(app, 'Running automated CSK Master Auto-Selector and Subsetting...');
                            step_selector = ('CSK_master_selector.py project_master.conf');
                            step_selector_cmd = [python space step_selector]; 
                            step1_master = ('CSK_subset_master.py project_master.conf');
                            step_master_2 = [python space step1_master]; 
                            
                            path_1_master = fullfile(project_path_full, par, 'snap2stamps', par, 'bin');
                            
                            if ispc
                                f_snap2stamps_master = fopen(strcat(project_path_full, '\snap2stamps\bin\snap2stamps_master.bat'),'w');
                                j = 0;
                                while f_snap2stamps_master == -1 && j < 20
                                    f_snap2stamps_master = fopen(strcat(project_path_full, '\snap2stamps\bin\snap2stamps_master.bat'),'w');
                                    j = j+1;
                                end
                                fprintf(f_snap2stamps_master,'@echo off \r\n');
                                fprintf(f_snap2stamps_master,'cd "%s"\r\n',path_1_master);
                                fprintf(f_snap2stamps_master,'%s\r\n',step_selector_cmd);
                                fprintf(f_snap2stamps_master,'%s\r\n',step_master_2);
                                fclose(f_snap2stamps_master);
                                
                                path_2_master = fullfile(project_path_full, '\snap2stamps\bin\snap2stamps_master.bat');
                                system(path_2_master);
                                
                            elseif isunix && ~ismac
                                f_snap2stamps_master = fopen(strcat(project_path_full, '/snap2stamps/bin/snap2stamps_master.sh'),'w');
                                j = 0;
                                while f_snap2stamps_master == -1 && j < 20
                                    f_snap2stamps_master = fopen(strcat(project_path_full, '/snap2stamps/bin/snap2stamps_master.sh'),'w');
                                    j = j+1;
                                end
                                fprintf(f_snap2stamps_master,'#!/bin/bash \n');
                                fprintf(f_snap2stamps_master,'cd "%s"\n',path_1_master);
                                fprintf(f_snap2stamps_master,'%s\n',step_selector_cmd);
                                fprintf(f_snap2stamps_master,'%s\n',step_master_2);
                                fclose(f_snap2stamps_master);
                                
                                path_2_master = (strcat(project_path_full, '/snap2stamps/bin/snap2stamps_master.sh'));
                                chmod = ['chmod +x' space path_2_master];
                                system(strjoin({chmod, path_2_master}, ';'));
                                
                            elseif ismac
                                f_snap2stamps_master = fopen(strcat(project_path_full, '/snap2stamps/bin/snap2stamps_master.sh'),'w');
                                j = 0;
                                while f_snap2stamps_master == -1 && j < 20
                                    f_snap2stamps_master = fopen(strcat(project_path_full, '/snap2stamps/bin/snap2stamps_master.sh'),'w');
                                    j = j+1;
                                end
                                fprintf(f_snap2stamps_master,'#!/bin/bash \n');
                                fprintf(f_snap2stamps_master,'cd "%s"\n',path_1_master);
                                fprintf(f_snap2stamps_master,'%s\n',step_selector_cmd);
                                fprintf(f_snap2stamps_master,'%s\n',step_master_2);
                                fclose(f_snap2stamps_master);
                                
                                path_2_master = (strcat(project_path_full, '/snap2stamps/bin/snap2stamps_master.sh'));
                                chmod = ['chmod +x' space path_2_master];
                                system(strjoin({chmod, path_2_master}, ';'));
                            end

                            % FLATTEN DIRECTORY: Move the .h5 file up one level to clean the structure
                            master_subdirs = dir(fullfile(project_path_full, 'master'));
                            for idx = 1:length(master_subdirs)
                                % Check for valid subdirectories, specifically EXCLUDING '.data' folders
                                if master_subdirs(idx).isdir && ~strcmp(master_subdirs(idx).name, '.') && ~strcmp(master_subdirs(idx).name, '..') && ~endsWith(master_subdirs(idx).name, '.data')
                                    subfolder_path = fullfile(master_subdirs(idx).folder, master_subdirs(idx).name);
                                    
                                    % Move .h5 up to the root of /master
                                    h5_contents = dir(fullfile(subfolder_path, '*.h5'));
                                    for j = 1:length(h5_contents)
                                        movefile(fullfile(h5_contents(j).folder, h5_contents(j).name), fullfile(project_path_full, 'master'));
                                    end
                                    
                                    % Delete the now-empty date folder
                                    rmdir(subfolder_path, 's');
                                end
                            end
                            
                            % 4. VALIDATE OUTPUT
                            master_dim_struct = dir(fullfile(project_path_full, 'master', '*_sub.dim'));
                            if isempty(master_dim_struct)
                                msg = 'ERROR: the master image has not been correctly processed. Check the snap2stamps logs.';
                                error(msg);
                            end        
                            master_file_destination = fullfile(master_dim_struct(1).folder, master_dim_struct(1).name);
                    
                            updateOutput(app, '----------------------- STEP 1: Master processing finished -----------------------');
                    
                        else
                            % IF MASTER PROCESSING IS SKIPPED, DYNAMICALLY FIND THE EXISTING MASTER
                            master_dim_struct = dir(fullfile(project_path_full, 'master', '*_sub.dim'));
                            if isempty(master_dim_struct)
                                msg = 'ERROR: No processed master found in /master folder! You must run Master Processing first.';
                                error(msg);
                            end        
                            master_file_destination = fullfile(master_dim_struct(1).folder, master_dim_struct(1).name);
                            
                            updateOutput(app, '----------------------- STEP 1: Master processing skipped -----------------------');
                    
                        end
    
                        if app.StopFlag
                            break;
                        end
                        
                        
                        %% ------------------------ SLAVES PROCESSING -----------------------------
                        
                        % CREATE THE PROJECT.CONF FILE FOR SNAP2STAMPS SLAVES PROCESSING
                        projectfolder = strcat('PROJECTFOLDER=',project_path_full); % path of the project folder
                        graphsfolder = strcat('GRAPHSFOLDER=', project_path_full, par, 'snap2stamps', par, 'graphs'); % path of the graphs folder of snap2stamps
                        masterfolder = strcat('MASTER=', master_file_destination); % master image path
                        
                        if ispc
                            f_project_conf_slaves = fopen(strcat(project_path_full, '\snap2stamps\bin\project.conf'),'w');
                            j = 0;
                            while f_project_conf_slaves == -1 && j < 20
                                f_project_conf_slaves = fopen(strcat(project_path_full, '\snap2stamps\bin\project.conf'),'w');
                                j = j+1;
                            end
                            fprintf(f_project_conf_slaves,'%s\r\n', '######### CONFIGURATION FILE ######');
                            fprintf(f_project_conf_slaves,'%s\r\n', '###################################');
                            fprintf(f_project_conf_slaves,'%s\r\n', '# PROJECT DEFINITION');
                            fprintf(f_project_conf_slaves,'%s\r\n', projectfolder);
                            fprintf(f_project_conf_slaves,'%s\r\n', graphsfolder);
                            fprintf(f_project_conf_slaves,'%s\r\n', '##################################');
                            fprintf(f_project_conf_slaves,'%s\r\n', '# PROCESSING PARAMETERS');
                            fprintf(f_project_conf_slaves,'%s\r\n', masterfolder);
                            fprintf(f_project_conf_slaves,'TC_COHERENCE=%.0f\n', coherence_tc);
                            fprintf(f_project_conf_slaves,'%s\r\n', '##################################');
                            fprintf(f_project_conf_slaves,'%s\r\n', '# DEM DEFINITION');
                            fprintf(f_project_conf_slaves,'DEMNAME=%s\r\n', dem_name);
                            fprintf(f_project_conf_slaves,'DEMFILE=%s\r\n', dem_file);
                            fprintf(f_project_conf_slaves,'NUMGCP=%.0f\n', num_gcp);
                            fprintf(f_project_conf_slaves,'%s\r\n', '##################################');
                            fprintf(f_project_conf_slaves,'%s\r\n', '# SPLIT BBOX DEFINITION');
                            fprintf(f_project_conf_slaves,'LONMIN=%.3f\r\n', lon_min);
                            fprintf(f_project_conf_slaves,'LATMIN=%.3f\r\n', lat_min);
                            fprintf(f_project_conf_slaves,'LONMAX=%.3f\r\n', lon_max);
                            fprintf(f_project_conf_slaves,'LATMAX=%.3f\r\n', lat_max);
                            fprintf(f_project_conf_slaves,'%s\r\n', '##################################');
                            fprintf(f_project_conf_slaves,'%s\r\n', '# SNAP GPT');
                            fprintf(f_project_conf_slaves,'GPTBIN_PATH=%s\r\n', gptbin_path);
                            fprintf(f_project_conf_slaves,'%s\r\n', '##################################');
                            fprintf(f_project_conf_slaves,'%s\r\n', '# COMPUTING RESOURCES TO EMPLOY');
                            fprintf(f_project_conf_slaves,'CPU=%.0f\r\n', cpu);
                            fprintf(f_project_conf_slaves,'CACHE=%s\r\n', cache);
                            fprintf(f_project_conf_slaves,'%s\r\n', '##################################');
                            f_project_conf_slaves = fclose(f_project_conf_slaves);
                        elseif isunix && ~ismac
                            f_project_conf_slaves = fopen(strcat(project_path_full, '/snap2stamps/bin/project.conf'),'w');
                            j = 0;
                            while f_project_conf_slaves == -1 && j < 20
                                f_project_conf_slaves = fopen(strcat(project_path_full, '/snap2stamps/bin/project.conf'),'w');
                                j = j+1;
                            end
                            fprintf(f_project_conf_slaves,'%s\r\n', '######### CONFIGURATION FILE ######');
                            fprintf(f_project_conf_slaves,'%s\r\n', '###################################');
                            fprintf(f_project_conf_slaves,'%s\r\n', '# PROJECT DEFINITION');
                            fprintf(f_project_conf_slaves,'%s\r\n', projectfolder);
                            fprintf(f_project_conf_slaves,'%s\r\n', graphsfolder);
                            fprintf(f_project_conf_slaves,'%s\r\n', '##################################');
                            fprintf(f_project_conf_slaves,'%s\r\n', '# PROCESSING PARAMETERS');
                            fprintf(f_project_conf_slaves,'%s\r\n', masterfolder);
                            fprintf(f_project_conf_slaves,'TC_COHERENCE=%.0f\n', coherence_tc);
                            fprintf(f_project_conf_slaves,'%s\r\n', '##################################');
                            fprintf(f_project_conf_slaves,'%s\r\n', '# DEM DEFINITION');
                            fprintf(f_project_conf_slaves,'DEMNAME=%s\r\n', dem_name);
                            fprintf(f_project_conf_slaves,'DEMFILE=%s\r\n', dem_file);
                            fprintf(f_project_conf_slaves,'NUMGCP=%.0f\n', num_gcp);
                            fprintf(f_project_conf_slaves,'%s\r\n', '##################################');
                            fprintf(f_project_conf_slaves,'%s\r\n', '# SPLIT BBOX DEFINITION');
                            fprintf(f_project_conf_slaves,'LONMIN=%.3f\r\n', lon_min);
                            fprintf(f_project_conf_slaves,'LATMIN=%.3f\r\n', lat_min);
                            fprintf(f_project_conf_slaves,'LONMAX=%.3f\r\n', lon_max);
                            fprintf(f_project_conf_slaves,'LATMAX=%.3f\r\n', lat_max);
                            fprintf(f_project_conf_slaves,'%s\r\n', '##################################');
                            fprintf(f_project_conf_slaves,'%s\r\n', '# SNAP GPT');
                            fprintf(f_project_conf_slaves,'GPTBIN_PATH=%s\r\n', gptbin_path);
                            fprintf(f_project_conf_slaves,'%s\r\n', '##################################');
                            fprintf(f_project_conf_slaves,'%s\r\n', '# COMPUTING RESOURCES TO EMPLOY');
                            fprintf(f_project_conf_slaves,'CPU=%.0f\r\n', cpu);
                            fprintf(f_project_conf_slaves,'CACHE=%s\r\n', cache);
                            fprintf(f_project_conf_slaves,'%s\r\n', '##################################');
                            f_project_conf_slaves = fclose(f_project_conf_slaves);
                        elseif ismac
                            f_project_conf_slaves = fopen(strcat(project_path_full, '/snap2stamps/bin/project.conf'),'w');
                            j = 0;
                            while f_project_conf_slaves == -1 && j < 20
                                f_project_conf_slaves = fopen(strcat(project_path_full, '/snap2stamps/bin/project.conf'),'w');
                                j = j+1;
                            end
                            fprintf(f_project_conf_slaves,'%s\r\n', '######### CONFIGURATION FILE ######');
                            fprintf(f_project_conf_slaves,'%s\r\n', '###################################');
                            fprintf(f_project_conf_slaves,'%s\r\n', '# PROJECT DEFINITION');
                            fprintf(f_project_conf_slaves,'%s\r\n', projectfolder);
                            fprintf(f_project_conf_slaves,'%s\r\n', graphsfolder);
                            fprintf(f_project_conf_slaves,'%s\r\n', '##################################');
                            fprintf(f_project_conf_slaves,'%s\r\n', '# PROCESSING PARAMETERS');
                            fprintf(f_project_conf_slaves,'%s\r\n', masterfolder);
                            fprintf(f_project_conf_slaves,'TC_COHERENCE=%.0f\n', coherence_tc);
                            fprintf(f_project_conf_slaves,'%s\r\n', '##################################');
                            fprintf(f_project_conf_slaves,'%s\r\n', '# DEM DEFINITION');
                            fprintf(f_project_conf_slaves,'DEMNAME=%s\r\n', dem_name);
                            fprintf(f_project_conf_slaves,'DEMFILE=%s\r\n', dem_file);
                            fprintf(f_project_conf_slaves,'NUMGCP=%.0f\n', num_gcp);
                            fprintf(f_project_conf_slaves,'%s\r\n', '##################################');
                            fprintf(f_project_conf_slaves,'%s\r\n', '# SPLIT BBOX DEFINITION');
                            fprintf(f_project_conf_slaves,'LONMIN=%.3f\r\n', lon_min);
                            fprintf(f_project_conf_slaves,'LATMIN=%.3f\r\n', lat_min);
                            fprintf(f_project_conf_slaves,'LONMAX=%.3f\r\n', lon_max);
                            fprintf(f_project_conf_slaves,'LATMAX=%.3f\r\n', lat_max);
                            fprintf(f_project_conf_slaves,'%s\r\n', '##################################');
                            fprintf(f_project_conf_slaves,'%s\r\n', '# SNAP GPT');
                            fprintf(f_project_conf_slaves,'GPTBIN_PATH=%s\r\n', gptbin_path);
                            fprintf(f_project_conf_slaves,'%s\r\n', '##################################');
                            fprintf(f_project_conf_slaves,'%s\r\n', '# COMPUTING RESOURCES TO EMPLOY');
                            fprintf(f_project_conf_slaves,'CPU=%.0f\r\n', cpu);
                            fprintf(f_project_conf_slaves,'CACHE=%s\r\n', cache);
                            fprintf(f_project_conf_slaves,'%s\r\n', '##################################');
                            f_project_conf_slaves = fclose(f_project_conf_slaves);
                        end
                        
                        % EXECUTE THE .CONF FILE VIA A BATCH/BASH FILE
                        dp = ('::');
                        step1_slaves = ('CSK_slaves_prep.py project.conf'); % slaves preparation
                        step2_slaves = ('CSK_subset_slaves.py project.conf'); % slaves splitting & apply orbits
                        step3_slaves = ('CSK_coreg_ifg.py project.conf'); % coregistration & interferogram
                        step4_slaves = ('CSK_stamps_export.py project.conf'); % StaMPS export
                        step5_slaves = ('CSK_average_intensity.py project.conf'); % terrain corrected coherence and lia
                        step6_slaves = ('CSK_terrain_correction.py project.conf'); % terrain corrected coherence and lia
                        

                            % CASES DEFENDING ON FIRST STEP
                            if isnumeric(first_step)
                                first_step_num = first_step;
                            elseif ischar(first_step)
                                first_step_num = str2double(first_step);
                            end
                            if first_step_num == 1
                                step_slaves_1 = [python space step1_slaves];
                                step_slaves_2 = [python space step2_slaves];
                                step_slaves_3 = [python space step3_slaves];
                                step_slaves_4 = [python space step4_slaves];
                                step_slaves_5 = [python space step5_slaves];
                                if coherence_tc == 0
                                    step_slaves_6 = [python space step6_slaves];
                                end
                            elseif first_step_num == 2
                                step_slaves_1 = [dp python space step1_slaves];
                                step_slaves_2 = [python space step2_slaves];
                                step_slaves_3 = [python space step3_slaves];
                                step_slaves_4 = [python space step4_slaves];
                                step_slaves_5 = [python space step5_slaves];
                                if coherence_tc == 0
                                    step_slaves_6 = [python space step6_slaves];
                                end
                            elseif first_step_num == 3
                                step_slaves_1 = [dp python space step1_slaves];
                                step_slaves_2 = [dp python space step2_slaves];
                                step_slaves_3 = [python space step3_slaves];
                                step_slaves_4 = [python space step4_slaves];
                                step_slaves_5 = [python space step5_slaves];
                                if coherence_tc == 0
                                    step_slaves_6 = [python space step6_slaves];
                                end
                            elseif first_step_num == 4
                                step_slaves_1 = [dp python space step1_slaves];
                                step_slaves_2 = [dp python space step2_slaves];
                                step_slaves_3 = [dp python space step3_slaves];
                                step_slaves_4 = [python space step4_slaves];
                                step_slaves_5 = [python space step5_slaves];
                                if coherence_tc == 0
                                    step_slaves_6 = [python space step6_slaves];
                                end
                            elseif first_step_num == 5
                                step_slaves_1 = [dp python space step1_slaves];
                                step_slaves_2 = [dp python space step2_slaves];
                                step_slaves_3 = [dp python space step3_slaves];
                                step_slaves_4 = [dp python space step4_slaves];
                                step_slaves_5 = [python space step5_slaves];
                                if coherence_tc == 0
                                    step_slaves_6 = [python space step6_slaves];
                                end
                            elseif first_step_num == 6
                                step_slaves_1 = [dp python space step1_slaves];
                                step_slaves_2 = [dp python space step2_slaves];
                                step_slaves_3 = [dp python space step3_slaves];
                                step_slaves_4 = [dp python space step4_slaves];
                                step_slaves_5 = [dp python space step5_slaves];
                                if coherence_tc == 0
                                    step_slaves_6 = [python space step6_slaves];
                                else
                                    updateOutput(app, ['You are running just the terrain correction for coherence and LIA bands, but you have set tc_coherence = 1. ' ...
                                        'Please change it to 0 to perform this step.'])
                                end
                            end
                        
                        path_1_slaves = fullfile(project_path_full, par, 'snap2stamps', par, 'bin');
                        slaves_proof = strcat(project_path_full, par, 'slaves', par, 'dummyDownload.txt');
                        
                        if ispc
                            f_snap2stamps_slaves = fopen(strcat(project_path_full, '\snap2stamps\bin\snap2stamps_slaves.bat'),'w');
                            j = 0;
                            while f_snap2stamps_slaves == -1 && j < 20
                                f_snap2stamps_slaves = fopen(strcat(project_path_full, '\snap2stamps\bin\snap2stamps_slaves.bat'),'w');
                                j = j+1;
                            end
                            fprintf(f_snap2stamps_slaves,'@echo off \r\n');
                            fprintf(f_snap2stamps_slaves,'cd "%s"\r\n',path_1_slaves);
                            fprintf(f_snap2stamps_slaves,'%s\r\n',step_slaves_1);
                            fprintf(f_snap2stamps_slaves,'%s\r\n',step_slaves_2);
                            fprintf(f_snap2stamps_slaves,'%s\r\n',step_slaves_3);
                            fprintf(f_snap2stamps_slaves,'%s\r\n',step_slaves_4);
                            fprintf(f_snap2stamps_slaves,'%s\r\n',step_slaves_5);
                            if coherence_tc == 0
                                fprintf(f_snap2stamps_slaves,'%s\r\n',step_slaves_6);
                            end
                            fprintf(f_snap2stamps_slaves,'cd "%s"\r\n',path_1_download);
                            fprintf(f_snap2stamps_slaves,'type nul > dummyDownload.txt \r\n');
                            fprintf(f_snap2stamps_slaves,'exit \r\n');
                            f_snap2stamps_slaves = fclose(f_snap2stamps_slaves);
                        elseif isunix && ~ismac
                            f_snap2stamps_slaves = fopen(strcat(project_path_full, '/snap2stamps/bin/snap2stamps_slaves.sh'),'w');
                            j = 0;
                            while f_snap2stamps_slaves == -1 && j < 20
                                f_snap2stamps_slaves = fopen(strcat(project_path_full, '/snap2stamps/bin/snap2stamps_slaves.sh'),'w');
                                j = j+1;
                            end
                            fprintf(f_snap2stamps_slaves,'#!/bin/bash \n');
                            fprintf(f_snap2stamps_slaves,'cd "%s"\n',path_1_slaves);
                            fprintf(f_snap2stamps_slaves,'%s\n',step_slaves_1);
                            fprintf(f_snap2stamps_slaves,'%s\n',step_slaves_2);
                            fprintf(f_snap2stamps_slaves,'%s\n',step_slaves_3);
                            fprintf(f_snap2stamps_slaves,'%s\n',step_slaves_4);
                            fprintf(f_snap2stamps_slaves,'%s\n',step_slaves_5);
                            if coherence_tc == 0
                                fprintf(f_snap2stamps_slaves,'%s\n',step_slaves_6);
                            end
                            fprintf(f_snap2stamps_slaves,'sleep 5 \n');
                            fprintf(f_snap2stamps_slaves,'cd "%s"\n',path_1_download);
                            fprintf(f_snap2stamps_slaves,'touch dummyDownload.txt \n');
                            f_snap2stamps_slaves = fclose(f_snap2stamps_slaves);
                        elseif ismac
                            f_snap2stamps_slaves = fopen(strcat(project_path_full, '/snap2stamps/bin/snap2stamps_slaves.sh'),'w');
                            j = 0;
                            while f_snap2stamps_slaves == -1 && j < 20
                                f_snap2stamps_slaves = fopen(strcat(project_path_full, '/snap2stamps/bin/snap2stamps_slaves.sh'),'w');
                                j = j+1;
                            end
                            fprintf(f_snap2stamps_slaves,'#!/bin/bash \n');
                            fprintf(f_snap2stamps_slaves,'cd "%s"\n',path_1_slaves);
                            fprintf(f_snap2stamps_slaves,'%s\n',step_slaves_1);
                            fprintf(f_snap2stamps_slaves,'%s\n',step_slaves_2);
                            fprintf(f_snap2stamps_slaves,'%s\n',step_slaves_3);
                            fprintf(f_snap2stamps_slaves,'%s\n',step_slaves_4);
                            fprintf(f_snap2stamps_slaves,'%s\n',step_slaves_5);
                            if coherence_tc == 0
                                fprintf(f_snap2stamps_slaves,'%s\n',step_slaves_6);
                            end
                            fprintf(f_snap2stamps_slaves,'sleep 5 \n');
                            fprintf(f_snap2stamps_slaves,'cd "%s"\n',path_1_download);
                            fprintf(f_snap2stamps_slaves,'touch dummyDownload.txt \n');
                            f_snap2stamps_slaves = fclose(f_snap2stamps_slaves);
                        end
                        
                        updateOutput(app, '----------------------- STEP 2: Slaves processing started -----------------------');
                        
                        if ispc
                            path_2_slaves = fullfile(project_path_full, '\snap2stamps\bin\snap2stamps_slaves.bat &');
                            system(path_2_slaves); % execute the batch file
                        elseif isunix && ~ismac
                            path_2_slaves = (strcat(project_path_full, '/snap2stamps/bin/snap2stamps_slaves.sh'));
                            chmod_in = ('chmod +x');
                            chmod = [chmod_in space path_2_slaves];
                            xterm = ('sudo xterm -e');
                            path_2_slaves = [xterm space path_2_slaves];
                            system(chmod);
                            system(path_2_slaves, '-echo');
                        elseif ismac
                            path_2_slaves = strcat(project_path_full, '/snap2stamps/bin/snap2stamps_slaves.sh');
                            chmod_in = 'chmod +x';
                            chmod = [chmod_in space path_2_slaves];
                            path_2_slaves = ['open -a Terminal ' path_2_slaves];
                            system(chmod);
                            system(path_2_slaves, '-echo');
                        end
                        
                        % wait until the cmd has finished the work
                        while exist(slaves_proof,'file') == 0
                              pause(1);
                        end
                        delete(slaves_proof);

                        % DYNAMIC WAIT FOR GEO-TIFF CREATION (Timeout: 5 minutes)
                        %updateOutput(app, 'Waiting for .tif files to be written on disk...');
                        timeout = 300; % Maximum wait time in seconds
                        elapsed = 0;
                        intensity_folder = fullfile(project_path_full, 'intensity');
                        
                        % Wait until the folder exists and is not empty, but do not exceed the timeout
                        while (~exist(intensity_folder, 'dir') || isempty(dir(fullfile(intensity_folder, '*.tif')))) && elapsed < timeout
                            pause(5); % Check every 5 seconds
                            elapsed = elapsed + 5;
                        end
                        
                        if elapsed >= timeout
                            updateOutput(app, 'WARNING: Timeout exceeded. The .tif files were not found.');
                        else
                            %updateOutput(app, '.tif files found, starting reprojection...');
                            % Leave a 2-second safety margin for the OS to release the file lock
                            pause(2); 
                        end
                        
                        % APPLY THE CORRECT PROJECTION TO THE AVERAGE INTENSITY GEOTIFF
                        if first_step_num <= 5
                            
                            % --- 1. INTENSITY ---
                            if exist(intensity_folder, 'dir')
                                intensity_files = dir(fullfile(intensity_folder, '*.tif'));
                                for i = 1:length(intensity_files)
                                    file_path = fullfile(intensity_folder, intensity_files(i).name);
                                    info = geotiffinfo(file_path);
                                    [image_data, R_int] = geotiffread(file_path);
                                    
                                    image_data = double(image_data);
                                    
                                    height = info.Height; 
                                    width = info.Width; 
                                    [rows,cols] = meshgrid(1:width,1:height);
                                    R = info.SpatialRef;
                                    
                                    lon_step = (R.LongitudeLimits(2)-R.LongitudeLimits(1))/(R.RasterSize(2)-1);
                                    lat_step = (R.LatitudeLimits(2)-R.LatitudeLimits(1))/(R.RasterSize(1)-1);
                                    lon = repmat(R.LongitudeLimits(1):lon_step:R.LongitudeLimits(2), R.RasterSize(1), 1);
                                    lat = repmat((fliplr(R.LatitudeLimits(1):lat_step:R.LatitudeLimits(2)))', 1, R.RasterSize(2));
                                    
                                    [x, y] = projfwd(projcrs(epsg_code), lat, lon);
                                    
                                    R_proj = maprefcells([min(min(x)), max(max(x))], [min(min(y)), max(max(y))], size(image_data), 'ColumnsStartFrom', 'north');
                                    R_proj.ProjectedCRS = projcrs(epsg_code);
                                    
                                    geotiffwrite(file_path, image_data, R_proj, "CoordRefSysCode", strcat('EPSG:', num2str(epsg_code)));
                                    clear file_path image_data
                                end
                            end
                        end
                        
                        % --- 2. COHERENCE ---
                        coherence_folder = fullfile(project_path_full, 'coherence');
                        if exist(coherence_folder, 'dir')
                            coherence_files = dir(fullfile(coherence_folder, '*.tif'));
                            for i = 1:length(coherence_files) 
                                file_path = fullfile(coherence_folder, coherence_files(i).name); 
                                info = geotiffinfo(file_path);
                                [image_data, R_coh] = geotiffread(file_path);
                                
                                image_data = double(image_data);
                                
                                height = info.Height; 
                                width = info.Width; 
                                [rows,cols] = meshgrid(1:width,1:height);
                                R = info.SpatialRef;
                                
                                lon_step = (R.LongitudeLimits(2)-R.LongitudeLimits(1))/(R.RasterSize(2)-1);
                                lat_step = (R.LatitudeLimits(2)-R.LatitudeLimits(1))/(R.RasterSize(1)-1);
                                lon = repmat(R.LongitudeLimits(1):lon_step:R.LongitudeLimits(2), R.RasterSize(1), 1);
                                lat = repmat((fliplr(R.LatitudeLimits(1):lat_step:R.LatitudeLimits(2)))', 1, R.RasterSize(2));
                                
                                [x, y] = projfwd(projcrs(epsg_code), lat, lon);
                                
                                R_proj = maprefcells([min(min(x)), max(max(x))], [min(min(y)), max(max(y))], size(image_data), 'ColumnsStartFrom', 'north');
                                R_proj.ProjectedCRS = projcrs(epsg_code);
                                
                                geotiffwrite(file_path, image_data, R_proj, "CoordRefSysCode", strcat('EPSG:', num2str(epsg_code)));
                                clear file_path image_data
                            end
                        end
                        
                        % --- 3. LIA (Local Incidence Angle) ---
                        lia_folder = fullfile(project_path_full, 'lia');
                        if exist(lia_folder, 'dir')
                            lia_files = dir(fullfile(lia_folder, '*.tif')); 
                            for i = 1:length(lia_files) 
                                file_path = fullfile(lia_folder, lia_files(i).name); 
                                info = geotiffinfo(file_path);
                                [image_data, R_lia] = geotiffread(file_path);
                                
                                if size(image_data, 3) >= 2
                                    image_data = image_data(:,:,2); % Keep only the second band
                                    image_data = double(image_data);
                                    
                                    height = info.Height; 
                                    width = info.Width; 
                                    [rows,cols] = meshgrid(1:width,1:height);
                                    R = info.SpatialRef;
                                    
                                    lon_step = (R.LongitudeLimits(2)-R.LongitudeLimits(1))/(R.RasterSize(2)-1);
                                    lat_step = (R.LatitudeLimits(2)-R.LatitudeLimits(1))/(R.RasterSize(1)-1);
                                    lon = repmat(R.LongitudeLimits(1):lon_step:R.LongitudeLimits(2), R.RasterSize(1), 1);
                                    lat = repmat((fliplr(R.LatitudeLimits(1):lat_step:R.LatitudeLimits(2)))', 1, R.RasterSize(2));
                                    
                                    [x, y] = projfwd(projcrs(epsg_code), lat, lon);
                                    
                                    R_proj = maprefcells([min(min(x)), max(max(x))], [min(min(y)), max(max(y))], size(image_data), 'ColumnsStartFrom', 'north');
                                    R_proj.ProjectedCRS = projcrs(epsg_code);
                                    
                                    geotiffwrite(file_path, image_data, R_proj, "CoordRefSysCode", strcat('EPSG:', num2str(epsg_code)));
                                end
                                clear file_path image_data
                            end
                        end
                        
                        updateOutput(app, '----------------------- STEP 2: Slaves processing finished -----------------------');
    
                        if app.StopFlag
                            break;
                        end

                        % DYNAMIC STAMPS FOLDER AUTOMATION (ORBIT & DATE EXTRACTION)
                        coreg_dir = fullfile(project_path_full, 'coreg');
                        dim_files = dir(fullfile(coreg_dir, '*.dim'));
                        
                        if isempty(dim_files)
                            updateOutput(app, 'ERROR: No coregistered files found to extract dates/orbit!');
                            return;
                        end
                        
                        % 1. Extract all dates from the filenames to find absolute Min and Max
                        all_dates = NaT(0); % Initialize empty datetime array
                        for idx = 1:length(dim_files)
                            % Parse the YYYYMMDD_YYYYMMDD.dim filename structure
                            tokens = regexp(dim_files(idx).name, '(\d{8})_(\d{8})\.dim', 'tokens');
                            if ~isempty(tokens)
                                all_dates(end+1, 1) = datetime(tokens{1}{1}, 'InputFormat', 'yyyyMMdd');
                                all_dates(end+1, 1) = datetime(tokens{1}{2}, 'InputFormat', 'yyyyMMdd');
                            end
                        end
                        
                        % Sort and format to English MmmYY (e.g., Jul19, Aug23)
                        min_date = min(all_dates);
                        max_date = max(all_dates);
                        date_str_start = char(datetime(min_date, 'Format', 'MMMyy', 'Locale', 'en_US'));
                        date_str_end   = char(datetime(max_date, 'Format', 'MMMyy', 'Locale', 'en_US'));
                        
                        % 2. Read the Ground Truth Orbit from the Master .dim XML
                        dim_path = fullfile(dim_files(1).folder, dim_files(1).name);
                        dim_text = fileread(dim_path);
                        
                        orbit_type = 'UNKNOWN';
                        if contains(upper(dim_text), 'ASCENDING')
                            orbit_type = 'ASC';
                        elseif contains(upper(dim_text), 'DESCENDING')
                            orbit_type = 'DES';
                        end
                        
                        % 3. Automatically Construct the Target Folder Name!
                        stamps_folder = sprintf('%s_%s_%s', orbit_type, date_str_start, date_str_end);
                        updateOutput(app, ['Dynamically generated StaMPS folder name: ' stamps_folder]);
                        
                        project_parent_path_full = prep_folder;
                        exist_StaMPS_A = exist(strcat(project_path_full, par, 'PHASE_StaMPS.mlapp'), "file");
                        if exist_StaMPS_A == 2 % the code automatically checks if the folder for the StaMPS processing has already been created or not
                            
                            project_parent_path_full = prep_folder;
                            mkdir(strcat(project_parent_path_full, par, stamps_folder));
                            movefile(strcat(project_path_full, par, 'PHASE_StaMPS.mlapp'), strcat(project_parent_path_full, par, stamps_folder, par, 'PHASE_StaMPS.mlapp'));
                        
                        else
                            
                            stamps_folder_full = fullfile(project_parent_path_full, stamps_folder);
                            dest_mlapp = fullfile(stamps_folder_full, 'PHASE_StaMPS.mlapp');
                            
                            % Create the StaMPS folder
                            if ~isfolder(stamps_folder_full)
                                mkdir(stamps_folder_full);
                                updateOutput(app, ['Created new StaMPS folder: ' stamps_folder]);
                            else
                                updateOutput(app, 'The StaMPS processing folder already exists - results will be overwritten!');
                            end
                            
                            copied = false;

                            % 1. Check if the file is ALREADY in the folder
                            if isfile(dest_mlapp)
                                updateOutput(app, 'PHASE_StaMPS.mlapp is already present in the folder.');
                                copied = true;
                            else
                                % 2. Try to copy PHASE_StaMPS.mlapp from a previous orbit folder
                                previous_folders = dir(fullfile(project_parent_path_full, [orbit_type '_*']));
                                
                                if ~isempty(previous_folders)
                                    for idx = 1:length(previous_folders)
                                        % Skip the loop if this folder is the one we are currently working in
                                        if strcmp(previous_folders(idx).name, stamps_folder)
                                            continue; 
                                        end
                                        
                                        source_mlapp = fullfile(project_parent_path_full, previous_folders(idx).name, 'PHASE_StaMPS.mlapp');
                                        
                                        if isfile(source_mlapp)
                                            copyfile(source_mlapp, dest_mlapp);
                                            updateOutput(app, ['Copied PHASE_StaMPS.mlapp from previous folder: ' previous_folders(idx).name]);
                                            copied = true;
                                            break; % Stop searching once successfully copied
                                        end
                                    end
                                end
                            end
                            
                            % If still couldn't copy it, give a clear warning but DO NOT crash
                            if ~copied
                                updateOutput(app, 'WARNING: PHASE_StaMPS.mlapp was not found in any previous folder.');
                                updateOutput(app, 'Please manually copy PHASE_StaMPS.mlapp into the folder:');
                                updateOutput(app, stamps_folder_full);
                            end
                        end
                        
                        % EVENTUAL REMOVAL OF THE SLAVES IMAGES TO SAVE DISK SPACE
                        if slaves_removal == 0 % check if the removal of the slaves images has to be done or not
                        
                            updateOutput(app, '----------------------- STEP 3: Slaves removal started -----------------------');
                        
                            rmdir(strcat(project_path_full, par, 'slaves'), 's'); % removal of slaves folder to save disk space
                            mkdir(project_path_full, 'slaves'); % create slaves folder
                        
                            updateOutput(app, '----------------------- STEP 3: Slaves removal finished -----------------------');
                        
                            else
                        
                            updateOutput(app, '----------------------- STEP 3: Slaves removal skipped -----------------------');
                            
                        end
                        
                        % OPEN THE PHASE_StaMPS.mlapp APPLICATION ONLY IF THE USER IS USING LINUX
                        if ispc
                            updateOutput(app, '-- Now go in Linux, configure the input file and open the PHASE_StaMPS.mlapp --');
                        elseif isunix && ~ismac
                            edit(strcat(project_parent_path_full, par, stamps_folder, par, 'PHASE_StaMPS.mlapp'));
                        elseif ismac
                            updateOutput(app, '-- Now go in Linux, configure the input file and open the PHASE_StaMPS.mlapp --');
                        end
    
                        %% ----------------------------------------------------
                        
                        % Display a message when the script is done
                        updateOutput(app, 'Script CSK_Preprocessing.m completed.');
        
                        % Change lamp color to green (success)
                        app.PreprocessingstatusLamp_2.Color = [0, 1, 0]; % Green
    
                    catch ME
    
                        % If an error occurs
                        updateOutput(app, 'An error occurred during script execution. Please check each step log file!');
    
                        % Change lamp color to red (error)
                        app.PreprocessingstatusLamp_2.Color = [1, 0, 0]; % Red

                        % Show the actual error
                        rethrow(ME)
                        
                    end

                    condition = 1;

                end

            end

        end

        % Button pushed function: LoadButton, LoadButton_2
        function LoadButtonPushed(app, event)
            
            filename = './PHASE_Preprocessing/input_preprocessing.mat';  % Specify the filename
            
            if exist(filename, 'file') == 2
                data = load(filename);
                app.constellation = data.constellation;
        
                if app.constellation == "SEN"
                    % Update SEN-related properties
                    if data.python == "python"   % python
                        app.PythonEnvironmentDropDown.Value = 'python';
                    elseif data.python == "python2"
                        app.PythonEnvironmentDropDown.Value = 'python2';
                    elseif data.python == "python2.7"
                        app.PythonEnvironmentDropDown.Value = 'python2.7';
                    else
                        app.PythonEnvironmentDropDown.Value = 'Other';
                        app.PythonEnvironmentLabel.Visible = 'on';
                        app.CustomPythonEnvironmentEditField.Visible = 'on';
                    end
                    app.CustomPythonEnvironmentEditField.Value = data.python;
                    if data.images_download == 0   % images download
                        app.SARImagesDownloadCheckBox.Value = true;
                    elseif data.images_download == 1
                        app.SARImagesDownloadCheckBox.Value = false;
                    end 
                    date_txt = data.master_date;   % master date
                    date_dt = datetime(date_txt, 'InputFormat', 'yyyyMMdd', 'Format', 'dd-MMM-uuuu');
                    app.MasterdateDatePicker.Value = date_dt;
                    if isfield(data, 'auto_master')
                        app.AutoMasterCheckBox.Value = logical(data.auto_master);
                        app.auto_master_SEN = data.auto_master;
                        if data.auto_master == 1
                            app.MasterdateDatePicker.Enable = 'off';
                        else
                            app.MasterdateDatePicker.Enable = 'on';
                        end
                    end
                    if data.master_processing == 0   % master processing
                        app.MasterprocessingCheckBox.Value = true;
                    elseif data.master_processing == 1
                        app.MasterprocessingCheckBox.Value = false;
                    end
                    app.PolarisationDropDown.Value = data.polarisation;
                    app.MinlongitudeEditField.Value = data.lon_min;
                    app.MinlatitudeEditField.Value = data.lat_min;
                    app.MaxlongitudeEditField.Value = data.lon_max;
                    app.MaxlatitudeEditField.Value = data.lat_max;
                    % Move the red box on the Sentinel-1 Map
                    if isvalid(app.roi_SEN)
                        w = data.lon_max - data.lon_min;
                        h = data.lat_max - data.lat_min;
                        % GeoAxes expects [LatMin, LonMin, LatHeight, LonWidth]
                        app.roi_SEN.Position = [data.lat_min, data.lon_min, h, w];
                    end
                    if data.slaves_removal == 0   % slaves removal
                        app.SlavesremovalafterprocessingCheckBox.Value = true;
                    elseif data.slaves_removal == 1
                        app.SlavesremovalafterprocessingCheckBox.Value = false;
                    end
                    app.DEMinterferogramDropDown.Value = data.dem_name;
                    app.DEMifgpathEditField.Value = data.dem_file;
                    app.DEMcoregistrationDropDown.Value = data.dem_name_coreg;
                    app.DEMcoregpathEditField.Value = data.dem_file_coreg;
                    app.DEMresamplingmethodDropDown.Value = data.dem_resampling;
                    app.FirststepDropDown.Value = num2str(data.first_step);
                    if data.coherence_tc == 0   % coherence tc
                        app.TerraincorrectedCoherenceandLIACheckBox.Value = true;
                    elseif data.coherence_tc == 1
                        app.TerraincorrectedCoherenceandLIACheckBox.Value = false;
                    end
                    app.EPSGcodeEditField.Value = data.epsg_code;
                    app.PathEditField.Value = data.gptbin_path;
                    app.CPUEditField.Value = data.cpu;
                    app.CacheEditField.Value = data.cache;
                    drawnow;

                    % Set the lamp color to green to indicate successful loading
                    app.StatusLamp_3.Color = [0, 1, 0];  % Green

                    % Update also the variables for the saving
                    app.python_SEN = app.CustomPythonEnvironmentEditField.Value;
                    app.images_download_SEN = data.images_download;
                    app.master_date_SEN = data.master_date;
                    app.auto_master_SEN = data.auto_master;
                    app.master_processing_SEN = data.master_processing;
                    app.polarisation_SEN = app.PolarisationDropDown.Value;
                    app.lon_min_SEN = app.MinlongitudeEditField.Value;
                    app.lat_min_SEN = app.MinlatitudeEditField.Value;
                    app.lon_max_SEN = app.MaxlongitudeEditField.Value;
                    app.lat_max_SEN = app.MaxlatitudeEditField.Value;
                    app.slaves_removal_SEN = data.slaves_removal;
                    app.dem_name_SEN = app.DEMinterferogramDropDown.Value;
                    app.dem_file_SEN = app.DEMifgpathEditField.Value;
                    app.dem_name_coreg_SEN = app.DEMcoregistrationDropDown.Value;
                    app.dem_file_coreg_SEN = app.DEMcoregpathEditField.Value;
                    app.dem_resampling_SEN = app.DEMresamplingmethodDropDown.Value;
                    app.first_step_SEN = app.FirststepDropDown.Value;
                    app.coherence_tc_SEN = data.coherence_tc;
                    app.epsg_code_SEN = app.EPSGcodeEditField.Value;
                    app.gptbin_path_SEN = app.PathEditField.Value;
                    app.cpu_SEN = app.CPUEditField.Value;
                    app.cache_SEN = app.CacheEditField.Value;
                    
                elseif app.constellation == "CSK"
                    % Update CSK-related properties
                    if data.python == "python"   % python
                        app.PythonEnvironmentDropDown_2.Value = 'python';
                    elseif data.python == "python2"
                        app.PythonEnvironmentDropDown_2.Value = 'python2';
                    elseif data.python == "python2.7"
                        app.PythonEnvironmentDropDown_2.Value = 'python2.7';
                    else
                        app.PythonEnvironmentDropDown_2.Value = 'Other';
                        app.CustomPythonEnvironmentLabel.Visible = 'off';
                        app.CustomPythonEnvironmentEditField_2.Visible = 'off';
                    end
                    app.CustomPythonEnvironmentEditField_2.Value = data.python;
                    date_txt = data.master_date;   % master date
                    date_dt = datetime(date_txt, 'InputFormat', 'yyyyMMdd', 'Format', 'dd-MMM-uuuu');
                    app.MasterdateDatePicker_2.Value = date_dt;
                    if isfield(data, 'auto_master')
                        app.AutoMasterCheckBox_2.Value = logical(data.auto_master);
                        app.auto_master_CSK = data.auto_master;
                        if data.auto_master == 1
                            app.MasterdateDatePicker_2.Enable = 'off';
                        else
                            app.MasterdateDatePicker_2.Enable = 'on';
                        end
                    end
                    if data.master_processing == 0   % master processing
                        app.MasterprocessingCheckBox_2.Value = true;
                    elseif data.master_processing == 1
                        app.MasterprocessingCheckBox_2.Value = false;
                    end
                    app.MinlongitudeEditField_2.Value = data.lon_min;
                    app.MinlatitudeEditField_2.Value = data.lat_min;
                    app.MaxlongitudeEditField_2.Value = data.lon_max;
                    app.MaxlatitudeEditField_2.Value = data.lat_max;
                    % Move the red box on the Cosmo-SkyMed Map
                    if isvalid(app.roi_CSK)
                        w = data.lon_max - data.lon_min;
                        h = data.lat_max - data.lat_min;
                        % GeoAxes expects [LatMin, LonMin, LatHeight, LonWidth]
                        app.roi_CSK.Position = [data.lat_min, data.lon_min, h, w];
                    end
                    if data.slaves_removal == 0   % slaves removal
                        app.SlavesremovalafterprocessingCheckBox_2.Value = true;
                    elseif data.slaves_removal == 1
                        app.SlavesremovalafterprocessingCheckBox_2.Value = false;
                    end
                    app.DEMinterferogramDropDown_2.Value = data.dem_name;
                    app.DEMifgpathEditField_2.Value = data.dem_file;
                    app.FirststepDropDown_2.Value = num2str(data.first_step);
                    app.CoregistrationGCPsnumberEditField.Value = data.num_gcp;
                    if data.coherence_tc == 0   % coherence tc
                        app.TerraincorrectedCoherenceandLIACheckBox_2.Value = true;
                    elseif data.coherence_tc == 1
                        app.TerraincorrectedCoherenceandLIACheckBox_2.Value = false;
                    end
                    app.EPSGcodeEditField_2.Value = data.epsg_code;
                    app.PathEditField_2.Value = data.gptbin_path;
                    app.CPUEditField_2.Value = data.cpu;
                    app.CacheEditField_2.Value = data.cache;
                    drawnow;

                    % Set the lamp color to green to indicate successful loading
                    app.StatusLamp_4.Color = [0, 1, 0];  % Green

                    % Update also the variables for the saving
                    app.python_CSK = app.CustomPythonEnvironmentEditField_2.Value;
                    app.master_date_CSK = data.master_date;
                    app.auto_master_CSK = data.auto_master;
                    app.master_processing_CSK = data.master_processing;
                    app.lon_min_CSK = app.MinlongitudeEditField_2.Value;
                    app.lat_min_CSK = app.MinlatitudeEditField_2.Value;
                    app.lon_max_CSK = app.MaxlongitudeEditField_2.Value;
                    app.lat_max_CSK = app.MaxlatitudeEditField_2.Value;
                    app.slaves_removal_CSK = data.slaves_removal;
                    app.dem_name_CSK = app.DEMinterferogramDropDown_2.Value;
                    app.dem_file_CSK = app.DEMifgpathEditField_2.Value;
                    app.num_gcp_CSK = app.CoregistrationGCPsnumberEditField.Value;
                    app.first_step_CSK = app.FirststepDropDown_2.Value;
                    app.coherence_tc_CSK = data.coherence_tc;
                    app.epsg_code_CSK = app.EPSGcodeEditField_2.Value;
                    app.gptbin_path_CSK = app.PathEditField_2.Value;
                    app.cpu_CSK = app.CPUEditField_2.Value;
                    app.cache_CSK = app.CacheEditField_2.Value;
                    
                end
        
                
            else
                % Handle the case when the file doesn't exist
                % Set the lamp color to red to indicate an error
                app.StatusLamp_3.Color = [1, 0, 0];  % Red
                app.StatusLamp_4.Color = [1, 0, 0];  % Red
            end

        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            
            % Reset lamp colors to red
            app.StatusLamp.Color = [1, 0, 0]; % Red color for Save lamp
            app.StatusLamp_3.Color = [1, 0, 0]; % Red color for Load lamp

        end

        % Selection change function: TabGroup2
        function TabGroup2SelectionChanged(app, event)
            
            % Reset lamp colors to red
            app.StatusLamp_2.Color = [1, 0, 0]; % Red color for Save lamp
            app.StatusLamp_4.Color = [1, 0, 0]; % Red color for Load lamp

        end

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            
            % Open a file dialog for the user to select the external DEM file
            [filename, filepath] = uigetfile('*.tif', 'Select .tif DEM file');
            
            % Check if the user selected a file (clicked "OK" in the file dialog)
            if isequal(filename, 0)
                % User canceled the file selection, do nothing
            else
                % User selected a file, update the EditField with the selected file path
                selectedFilePath = fullfile(filepath, filename);
                app.DEMifgpathEditField.Value = selectedFilePath;
                app.dem_file_SEN = selectedFilePath;
            end

        end

        % Button pushed function: BrowseButton_2
        function BrowseButton_2Pushed(app, event)
            
            % Open a file dialog for the user to select the external DEM file
            [filename, filepath] = uigetfile('*.tif', 'Select .tif DEM file');
            
            % Check if the user selected a file (clicked "OK" in the file dialog)
            if isequal(filename, 0)
                % User canceled the file selection, do nothing
            else
                % User selected a file, update the EditField with the selected file path
                selectedFilePath = fullfile(filepath, filename);
                app.DEMcoregpathEditField.Value = selectedFilePath;
                app.dem_file_coreg_SEN = selectedFilePath;
            end

        end

        % Button pushed function: BrowseButton_3
        function BrowseButton_3Pushed(app, event)
            
            % Open a file dialog for the user to select the external DEM file
            [filename, filepath] = uigetfile('*.tif', 'Select .tif DEM file');
            
            % Check if the user selected a file (clicked "OK" in the file dialog)
            if isequal(filename, 0)
                % User canceled the file selection, do nothing
            else
                % User selected a file, update the EditField with the selected file path
                selectedFilePath = fullfile(filepath, filename);
                app.DEMifgpathEditField_2.Value = selectedFilePath;
                app.dem_file_CSK = selectedFilePath;
            end

        end

        % Value changed function: PythonEnvironmentDropDown
        function PythonEnvironmentDropDownValueChanged(app, event)
            value = app.PythonEnvironmentDropDown.Value;

            % Check if "Other" is selected
            if strcmp(value, 'Other')
                app.PythonEnvironmentLabel.Visible = 'on'; % Show the Label
                app.CustomPythonEnvironmentEditField.Visible = 'on'; % Show the EditField
                app.python_SEN = app.CustomPythonEnvironmentEditField.Value;
            else
                app.PythonEnvironmentLabel.Visible = 'off'; % Hide the Label
                app.CustomPythonEnvironmentEditField.Visible = 'off'; % Hide the EditField
                app.python_SEN = value;
            end

        end

        % Value changed function: PythonEnvironmentDropDown_2
        function PythonEnvironmentDropDown_2ValueChanged(app, event)
            value = app.PythonEnvironmentDropDown_2.Value;

            % Check if "Other" is selected
            if strcmp(value, 'Other')
                app.CustomPythonEnvironmentLabel.Visible = 'on'; % Show the Label
                app.CustomPythonEnvironmentEditField_2.Visible = 'on'; % Show the EditField
                app.python_CSK = app.CustomPythonEnvironmentEditField_2.Value;
            else
                app.CustomPythonEnvironmentLabel.Visible = 'off'; % Hide the Label
                app.CustomPythonEnvironmentEditField_2.Visible = 'off'; % Hide the EditField
                app.python_CSK = value;
            end
            
        end

        % Button pushed function: StopButton, StopButton_2
        function StopButtonPushed(app, event)
            
            % Set a flag to indicate that the button has been pressed
            app.StopFlag = true;

        end

        % Value changed function: AutoMasterCheckBox
        function AutoMasterCheckBoxValueChanged(app, event)
            value = app.AutoMasterCheckBox.Value;
            if value
                app.auto_master_SEN = 1;
                app.MasterdateDatePicker.Enable = 'off';
            else
                app.auto_master_SEN = 0;
                app.MasterdateDatePicker.Enable = 'on';
            end
        end

        % Value changed function: AutoMasterCheckBox_2
        function AutoMasterCheckBox_2ValueChanged(app, event)
            value = app.AutoMasterCheckBox_2.Value;
            if value
                app.auto_master_CSK = 1;
                app.MasterdateDatePicker_2.Enable = 'off';
            else
                app.auto_master_CSK = 0;
                app.MasterdateDatePicker_2.Enable = 'on';
            end
        end

        % Button pushed function: DrawCSKAOIButton
        function DrawCSKAOIButtonPushed(app, event)
            app.initializeCSKMap();
            updateOutput(app, 'Map and AOI reset to default footprint.');
        end

        % Button pushed function: ResetSENMapButton
        function ResetSENMapButtonPushed(app, event)
            app.initializeSENMap();
            updateOutput(app, 'Sentinel-1 Map and AOI reset to default footprint.');
        end

        % Callback function
        function SaveParametersButtonPushed(app, event)

        
            params = getDownloaderParams(app);
        
            outputFolder = fullfile(pwd, "download");
        
            if ~exist(outputFolder, "dir")
                mkdir(outputFolder);
            end
        
            outputFile = fullfile(outputFolder, "search_request.json");
        
            jsonText = jsonencode(params, PrettyPrint=true);
        
            fid = fopen(outputFile, "w");


            fprintf(fid, "%s", jsonText);
            fclose(fid);
        
            disp("Parameters saved to download/search_request.json");
        
            disp(jsonText);
        end

        % Button pushed function: ClearPathFrameButton
        function ClearPathFrameButtonPushed(app, event)
            app.PathStartEditField.Value = 0;
            app.PathEndEditField.Value = 0;
            app.FrameStartEditField.Value = 0;
            app.FrameEndEditField.Value = 0;
        end

        % Button pushed function: ShowFiltersButton
        function ShowFiltersButtonPushed(app, event)
            if strcmp(app.FilterPanel.Visible, "on")
                app.FilterPanel.Visible = "off";
                app.ShowFiltersButton.Text = "Show Filters";
            else
                app.FilterPanel.Visible = "on";
                app.ShowFiltersButton.Text = "Hide Filters";
            end
        end

        % Button pushed function: ClearAOIButton
        function ClearAOIButtonPushed(app, event)
            clearDownloaderAOIShape(app);

            app.DownloaderAOIType = "";
            app.DownloaderAOICorners = [];
            app.DownloaderPolygonCoords = [];
        
            app.MinLongitudeEditField.Value = -180;
            app.MaxLongitudeEditField.Value = 180;
            app.MinLatitudeEditField.Value = -90;
            app.MaxLatitudeEditField.Value = 90;
        end

        % Button pushed function: DrawRectangleButton
        function DrawRectangleButtonPushed(app, event)
            ClearAOIButtonPushed(app);
        
            app.roi_Downloader = drawrectangle(app.DownloaderGeoAxes, ...
                'Color', 'r', ...
                'FaceAlpha', 0.2);
        
            app.updateDownloaderRectangleCoords(app.roi_Downloader);
        
            addlistener(app.roi_Downloader, ...
                'ROIMoved', ...
                @(src, event) app.updateDownloaderRectangleCoords(src));
        
        end

        % Button pushed function: SearchASFButton
        function SearchASFButtonPushed(app, event)
            SaveParametersButtonPushed(app, event);
            disp("Search ASF button pressed");
        
            appPath = fileparts(mfilename("fullpath"));
            controllerPath = fullfile(appPath, "download", "controller.py");
        
            pythonEnv = pyenv;
            pythonExe = string(pythonEnv.Executable);
        
            command = """" + pythonExe + """ """ + controllerPath + """ search";
        
            disp("Command being run:");
            disp(command);
        
            [status, cmdout] = system(command);
        
            disp("Python status:");
            disp(status);
        
            disp("Python output:");
            disp(cmdout);
        
            if status ~= 0
                uialert(app.UIFigure, cmdout, "Search Failed", "Icon", "error");
                return;
            end
        
            confirmed = showDownloadConfirmation(app);
        
            if confirmed
                saveLastDownloadParams(app);
                disp("User confirmed download.");


                % backend call starting download
                command = """" + pythonExe + """ """ + controllerPath + """ download";
                [~, cmdout] = system(command);
                disp(cmdout);
            else
                disp("User cancelled download.");
            end
        end

        % Button pushed function: ResetallfiltersButton
        function ResetallfiltersButtonPushed(app, event)
            initializeParams(app);
        end

        % Button pushed function: DrawPolygonButton
        function DrawPolygonButtonPushed(app, event)
            ClearAOIButtonPushed(app);

            app.roi_Downloader = drawpolygon(app.DownloaderGeoAxes, ...
                'Color', 'r', ...
                'FaceAlpha', 0.2);
        
            updateDownloaderPolygonCoords(app, app.roi_Downloader);
        
            addlistener(app.roi_Downloader, ...
                'ROIMoved', ...
                @(src, event) updateDownloaderPolygonCoords(app, src));
        end

        % Value changed function: MaxLatitudeEditField
        function MaxLatitudeEditFieldValueChanged(app, event)
            value = app.MaxLatitudeEditField.Value;
            AOIBoundaryValueChanged(app, event);
        end

        % Value changed function: MinLatitudeEditField
        function MinLatitudeEditFieldValueChanged(app, event)
            value = app.MinLatitudeEditField.Value;
            AOIBoundaryValueChanged(app, event);
        end

        % Value changed function: MaxLongitudeEditField
        function MaxLongitudeEditFieldValueChanged(app, event)
            value = app.MaxLongitudeEditField.Value;
            AOIBoundaryValueChanged(app, event);
        end

        % Value changed function: MinLongitudeEditField
        function MinLongitudeEditFieldValueChanged(app, event)
            value = app.MinLongitudeEditField.Value;
            AOIBoundaryValueChanged(app, event);
        end

        % Button pushed function: LoginButton
        function LoginButtonPushed(app, event)
            username = strtrim(string(app.EarthdataUsernameEditField.Value));
            password = strtrim(string(app.EarthdataPasswordEditField.Value));
        
            if username == "" || password == ""
                app.LoginFeedbackLabel.Text = ...
                    "Username and password are required.";
                return;
            end
        
            loginData.username = username;
            loginData.password = password;
        
            outputFolder = fullfile(pwd, "download");
        
            if ~exist(outputFolder, "dir")
                mkdir(outputFolder);
            end

            outputFile = fullfile(outputFolder, "login_request.json");

            fid = fopen(outputFile, "w");
            fprintf(fid, "%s", jsonencode(loginData, PrettyPrint=true));
            fclose(fid);
            app.LoginFeedbackLabel.Text = "Checking login...";
                
            appPath = fileparts(mfilename("fullpath"));

            controllerPath = fullfile(appPath, "download", "controller.py");

            pythonEnv = pyenv;

            pythonExe = string(pythonEnv.Executable);

            command = """" + pythonExe + """ """ + controllerPath + """ login";

            [status, cmdout] = system(command);


            % ==================================================
            % PARTNER BACKEND LOGIN CHECK WILL HAPPEN HERE
            % ==================================================
        
            pause(1);
        
            % TEMP TEST
            loginSuccess = true;
        
            if loginSuccess
        
                app.LoginFeedbackLabel.Text = "";
        
                app.EarthdataUsernameEditField.Visible = "off";
                app.EarthdataPasswordEditField.Visible = "off";
                app.LoginButton.Visible = "off";
        
                app.SignedInLabel.Text = "Signed in as " + username;
                app.SignedInLabel.Visible = "on";
        
                app.SignOutButton.Visible = "on";
        
            else
        
                app.LoginFeedbackLabel.Text = "Wrong username or password.";
        
            end
        end

        % Button pushed function: SignOutButton
        function SignOutButtonPushed(app, event)
        
        
            app.EarthdataUsernameEditField.Value = "";
            app.EarthdataPasswordEditField.Value = "";
        
            app.EarthdataUsernameEditField.Visible = "on";
            app.EarthdataPasswordEditField.Visible = "on";
        
            app.LoginButton.Visible = "on";
        
            app.SignOutButton.Visible = "off";
        
            app.SignedInLabel.Visible = "off";


        end

        % Callback function
        function MapTypeDropDownValueChanged(app, event)
           
        end

        % Button pushed function: LoadLastDownloadButton
        function LoadLastDownloadButtonPushed(app, event)
            inputFile = fullfile(pwd, "download", "last_download_request.json");

            if ~exist(inputFile, "file")
                uialert(app.UIFigure, ...
                    "No previous download parameters found.", ...
                    "No Last Download", ...
                    "Icon", "warning");
                return;
            end
        
            params = jsondecode(fileread(inputFile));
            applyDownloaderParams(app, params);
        
            disp("Loaded last download parameters.");
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ConstellationSwitchLabel = uilabel(app.UIFigure);
            app.ConstellationSwitchLabel.Text = 'Constellation';
            app.CosmoSkyMedPanel = uipanel(app.UIFigure);
            app.CosmoSkyMedPanel.Title = 'CosmoSkyMed';
            app.CosmoSkyMedPanel.Visible = 'off';
            app.TabGroup2 = uitabgroup(app.CosmoSkyMedPanel);
            app.TabGroup2.SelectionChangedFcn = createCallbackFcn(app, @TabGroup2SelectionChanged, true);
            app.GlobalVariablesTab_4 = uitab(app.TabGroup2);
            app.GlobalVariablesTab_4.Title = 'Global Variables';
            app.CustomPythonEnvironmentLabel = uilabel(app.GlobalVariablesTab_4);
            app.CustomPythonEnvironmentLabel.Text = 'Custom Python Environment:';
            app.CustomPythonEnvironmentEditField_2 = uieditfield(app.GlobalVariablesTab_4, 'text');
            app.CustomPythonEnvironmentEditField_2.ValueChangedFcn = createCallbackFcn(app, @CustomPythonEnvironmentEditField_2ValueChanged, true);
            app.CustomPythonEnvironmentEditField_2.Value = 'python';
            app.Label_4 = uilabel(app.GlobalVariablesTab_4);
            app.Label_4.Text = 'Name of the python 3.x environment in your os ';
            app.PythonEnvironmentDropDown_2Label = uilabel(app.GlobalVariablesTab_4);
            app.PythonEnvironmentDropDown_2Label.Text = 'Python Environment';
            app.PythonEnvironmentDropDown_2 = uidropdown(app.GlobalVariablesTab_4);
            app.PythonEnvironmentDropDown_2.Items = {'python', 'python3', 'python3.11', 'Other'};
            app.PythonEnvironmentDropDown_2.ValueChangedFcn = createCallbackFcn(app, @PythonEnvironmentDropDown_2ValueChanged, true);
            app.PythonEnvironmentDropDown_2.Value = 'python';
            app.AOITab = uitab(app.TabGroup2);
            app.AOITab.Title = 'AOI';
            app.Panel = uipanel(app.AOITab);
            app.DrawCSKAOIButton = uibutton(app.AOITab, 'push');
            app.DrawCSKAOIButton.ButtonPushedFcn = createCallbackFcn(app, @DrawCSKAOIButtonPushed, true);
            app.DrawCSKAOIButton.Text = 'Reset Map';
            app.MinlongitudeEditField_2Label = uilabel(app.AOITab);
            app.MinlongitudeEditField_2Label.Text = 'Min longitude';
            app.MinlongitudeEditField_2 = uieditfield(app.AOITab, 'numeric');
            app.MinlongitudeEditField_2.Limits = [-180 180];
            app.MinlongitudeEditField_2.ValueDisplayFormat = '%.3f';
            app.MinlongitudeEditField_2.ValueChangedFcn = createCallbackFcn(app, @MinlongitudeEditField_2ValueChanged, true);
            app.MinlongitudeEditField_2.Editable = 'off';
            app.MinlongitudeEditField_2.Value = -180;
            app.MaxlongitudeEditField_2Label = uilabel(app.AOITab);
            app.MaxlongitudeEditField_2Label.Text = 'Max longitude';
            app.MaxlongitudeEditField_2 = uieditfield(app.AOITab, 'numeric');
            app.MaxlongitudeEditField_2.Limits = [-180 180];
            app.MaxlongitudeEditField_2.ValueDisplayFormat = '%.3f';
            app.MaxlongitudeEditField_2.ValueChangedFcn = createCallbackFcn(app, @MaxlongitudeEditField_2ValueChanged, true);
            app.MaxlongitudeEditField_2.Editable = 'off';
            app.MaxlongitudeEditField_2.Value = 180;
            app.MaxlatitudeEditField_2Label = uilabel(app.AOITab);
            app.MaxlatitudeEditField_2Label.Text = 'Max latitude';
            app.MaxlatitudeEditField_2 = uieditfield(app.AOITab, 'numeric');
            app.MaxlatitudeEditField_2.Limits = [-90 90];
            app.MaxlatitudeEditField_2.ValueDisplayFormat = '%.3f';
            app.MaxlatitudeEditField_2.ValueChangedFcn = createCallbackFcn(app, @MaxlatitudeEditField_2ValueChanged, true);
            app.MaxlatitudeEditField_2.Editable = 'off';
            app.MaxlatitudeEditField_2.Value = 90;
            app.MinlatitudeEditField_2Label = uilabel(app.AOITab);
            app.MinlatitudeEditField_2Label.Text = 'Min latitude';
            app.MinlatitudeEditField_2 = uieditfield(app.AOITab, 'numeric');
            app.MinlatitudeEditField_2.Limits = [-90 90];
            app.MinlatitudeEditField_2.ValueDisplayFormat = '%.3f';
            app.MinlatitudeEditField_2.ValueChangedFcn = createCallbackFcn(app, @MinlatitudeEditField_2ValueChanged, true);
            app.MinlatitudeEditField_2.Editable = 'off';
            app.MinlatitudeEditField_2.Value = -90;
            app.AOIboxboundariesLabel_2 = uilabel(app.AOITab);
            app.AOIboxboundariesLabel_2.Text = 'AOI box boundaries';
            app.MasterProcessingTab_2 = uitab(app.TabGroup2);
            app.MasterProcessingTab_2.Title = 'Master Processing';
            app.MasterdateDatePicker_2Label = uilabel(app.MasterProcessingTab_2);
            app.MasterdateDatePicker_2Label.Text = 'Master date';
            app.MasterdateDatePicker_2 = uidatepicker(app.MasterProcessingTab_2);
            app.MasterdateDatePicker_2.ValueChangedFcn = createCallbackFcn(app, @MasterdateDatePicker_2ValueChanged, true);
            app.MasterdateDatePicker_2.Enable = 'off';
            app.MasterdateDatePicker_2.Value = datetime([2020 3 5]);
            app.MasterprocessingCheckBox_2 = uicheckbox(app.MasterProcessingTab_2);
            app.MasterprocessingCheckBox_2.ValueChangedFcn = createCallbackFcn(app, @MasterprocessingCheckBox_2ValueChanged, true);
            app.MasterprocessingCheckBox_2.Text = 'Master processing';
            app.MasterprocessingCheckBox_2.Value = true;
            app.AutoMasterCheckBox_2 = uicheckbox(app.MasterProcessingTab_2);
            app.AutoMasterCheckBox_2.ValueChangedFcn = createCallbackFcn(app, @AutoMasterCheckBox_2ValueChanged, true);
            app.AutoMasterCheckBox_2.Text = 'Auto-select master';
            app.AutoMasterCheckBox_2.Value = true;
            app.SlavesProcessingTab_2 = uitab(app.TabGroup2);
            app.SlavesProcessingTab_2.Title = 'Slaves Processing';
            app.SlavesremovalafterprocessingCheckBox_2 = uicheckbox(app.SlavesProcessingTab_2);
            app.SlavesremovalafterprocessingCheckBox_2.ValueChangedFcn = createCallbackFcn(app, @SlavesremovalafterprocessingCheckBox_2ValueChanged, true);
            app.SlavesremovalafterprocessingCheckBox_2.Text = 'Slaves removal after processing';
            app.DEMinterferogramDropDown_2Label = uilabel(app.SlavesProcessingTab_2);
            app.DEMinterferogramDropDown_2Label.Text = 'DEM interferogram';
            app.DEMinterferogramDropDown_2 = uidropdown(app.SlavesProcessingTab_2);
            app.DEMinterferogramDropDown_2.Items = {'SRTM 1Sec HGT', 'SRTM 3Sec', 'Copernicus 30m Global DEM', 'Copernicus 90m Global DEM', 'CDEM', 'GETASSE30', 'External DEM'};
            app.DEMinterferogramDropDown_2.ValueChangedFcn = createCallbackFcn(app, @DEMinterferogramDropDown_2ValueChanged, true);
            app.DEMinterferogramDropDown_2.Value = 'SRTM 1Sec HGT';
            app.DEMifgpathEditField_2Label = uilabel(app.SlavesProcessingTab_2);
            app.DEMifgpathEditField_2Label.Text = 'DEM ifg path';
            app.DEMifgpathEditField_2 = uieditfield(app.SlavesProcessingTab_2, 'text');
            app.DEMifgpathEditField_2.ValueChangedFcn = createCallbackFcn(app, @DEMifgpathEditField_2ValueChanged, true);
            app.onlyforExternalDEMLabel_3 = uilabel(app.SlavesProcessingTab_2);
            app.onlyforExternalDEMLabel_3.Text = '(only for External DEM)';
            app.FirststepDropDown_2Label = uilabel(app.SlavesProcessingTab_2);
            app.FirststepDropDown_2Label.Text = 'First step';
            app.FirststepDropDown_2 = uidropdown(app.SlavesProcessingTab_2);
            app.FirststepDropDown_2.Items = {'1', '2', '3', '4', '5', '6'};
            app.FirststepDropDown_2.ValueChangedFcn = createCallbackFcn(app, @FirststepDropDown_2ValueChanged, true);
            app.FirststepDropDown_2.Value = '1';
            app.CoregistrationGCPsnumberEditFieldLabel = uilabel(app.SlavesProcessingTab_2);
            app.CoregistrationGCPsnumberEditFieldLabel.Text = 'Coregistration GCPs number';
            app.CoregistrationGCPsnumberEditField = uieditfield(app.SlavesProcessingTab_2, 'numeric');
            app.CoregistrationGCPsnumberEditField.Limits = [0 Inf];
            app.CoregistrationGCPsnumberEditField.ValueDisplayFormat = '%.0f';
            app.CoregistrationGCPsnumberEditField.ValueChangedFcn = createCallbackFcn(app, @CoregistrationGCPsnumberEditFieldValueChanged, true);
            app.CoregistrationGCPsnumberEditField.Value = 10000;
            app.BrowseButton_3 = uibutton(app.SlavesProcessingTab_2, 'push');
            app.BrowseButton_3.ButtonPushedFcn = createCallbackFcn(app, @BrowseButton_3Pushed, true);
            app.BrowseButton_3.Text = 'Browse';
            app.CoherenceandLIATab_2 = uitab(app.TabGroup2);
            app.CoherenceandLIATab_2.Title = 'Coherence and LIA';
            app.TerraincorrectedCoherenceandLIACheckBox_2 = uicheckbox(app.CoherenceandLIATab_2);
            app.TerraincorrectedCoherenceandLIACheckBox_2.ValueChangedFcn = createCallbackFcn(app, @TerraincorrectedCoherenceandLIACheckBox_2ValueChanged, true);
            app.TerraincorrectedCoherenceandLIACheckBox_2.Text = 'Terrain-corrected Coherence and LIA';
            app.TerraincorrectedCoherenceandLIACheckBox_2.Value = true;
            app.EPSGcodeEditField_2Label = uilabel(app.CoherenceandLIATab_2);
            app.EPSGcodeEditField_2Label.Text = 'EPSG code';
            app.EPSGcodeEditField_2 = uieditfield(app.CoherenceandLIATab_2, 'numeric');
            app.EPSGcodeEditField_2.Limits = [0 99999];
            app.EPSGcodeEditField_2.ValueDisplayFormat = '%.0f';
            app.EPSGcodeEditField_2.ValueChangedFcn = createCallbackFcn(app, @EPSGcodeEditField_2ValueChanged, true);
            app.EPSGcodeEditField_2.Value = 32633;
            app.Label_5 = uilabel(app.CoherenceandLIATab_2);
            app.Label_5.Text = 'EPSG code of the cartographic projection of the terrain-corrected .TIFF files (choose accordingly to the location)';
            app.ComputationalResourcesTab_2 = uitab(app.TabGroup2);
            app.ComputationalResourcesTab_2.Title = 'Computational Resources';
            app.FullpathtoSNAPgptfolderLabel_2 = uilabel(app.ComputationalResourcesTab_2);
            app.FullpathtoSNAPgptfolderLabel_2.Text = 'Full path to SNAP gpt folder';
            app.PathEditField_2Label = uilabel(app.ComputationalResourcesTab_2);
            app.PathEditField_2Label.Text = 'Path';
            app.PathEditField_2 = uieditfield(app.ComputationalResourcesTab_2, 'text');
            app.PathEditField_2.ValueChangedFcn = createCallbackFcn(app, @PathEditField_2ValueChanged, true);
            app.PathEditField_2.Value = 'C:\Program Files\snap\bin\gpt';
            app.NumberofcorestobeusedintheprocessingLabel_2 = uilabel(app.ComputationalResourcesTab_2);
            app.NumberofcorestobeusedintheprocessingLabel_2.Text = 'Number of cores to be used in the processing';
            app.CPUEditField_2Label = uilabel(app.ComputationalResourcesTab_2);
            app.CPUEditField_2Label.Text = 'CPU';
            app.CPUEditField_2 = uieditfield(app.ComputationalResourcesTab_2, 'numeric');
            app.CPUEditField_2.Limits = [0 1000];
            app.CPUEditField_2.RoundFractionalValues = 'on';
            app.CPUEditField_2.ValueDisplayFormat = '%.0f';
            app.CPUEditField_2.ValueChangedFcn = createCallbackFcn(app, @CPUEditField_2ValueChanged, true);
            app.CPUEditField_2.Value = 8;
            app.RAMtobeusedintheprocessingGBformatnnGLabel_2 = uilabel(app.ComputationalResourcesTab_2);
            app.RAMtobeusedintheprocessingGBformatnnGLabel_2.Text = 'RAM to be used in the processing [GB] (format "nnG")';
            app.CacheEditField_2Label = uilabel(app.ComputationalResourcesTab_2);
            app.CacheEditField_2Label.Text = 'Cache';
            app.CacheEditField_2 = uieditfield(app.ComputationalResourcesTab_2, 'text');
            app.CacheEditField_2.ValueChangedFcn = createCallbackFcn(app, @CacheEditField_2ValueChanged, true);
            app.CacheEditField_2.Value = '26G';
            app.SaveLoadTab_2 = uitab(app.TabGroup2);
            app.SaveLoadTab_2.Title = 'Save/Load';
            app.SaveButton_2 = uibutton(app.SaveLoadTab_2, 'push');
            app.SaveButton_2.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton_2.Text = 'Save';
            app.SavetheconfiguredparametersfortheInSARpreprocessingLabel_2 = uilabel(app.SaveLoadTab_2);
            app.SavetheconfiguredparametersfortheInSARpreprocessingLabel_2.Text = 'Save the configured parameters for the InSAR pre-processing';
            app.StatusLamp_2Label = uilabel(app.SaveLoadTab_2);
            app.StatusLamp_2Label.Text = 'Status';
            app.StatusLamp_2 = uilamp(app.SaveLoadTab_2);
            app.LoadButton_2 = uibutton(app.SaveLoadTab_2, 'push');
            app.LoadButton_2.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton_2.Text = 'Load';
            app.SavetheconfiguredparametersfortheInSARpreprocessingLabel_4 = uilabel(app.SaveLoadTab_2);
            app.SavetheconfiguredparametersfortheInSARpreprocessingLabel_4.Text = 'Load the parameters for the InSAR pre-processing from the already existing .mat file';
            app.StatusLamp_4Label = uilabel(app.SaveLoadTab_2);
            app.StatusLamp_4Label.Text = 'Status';
            app.StatusLamp_4 = uilamp(app.SaveLoadTab_2);
            app.RunTab_2 = uitab(app.TabGroup2);
            app.RunTab_2.Title = 'Run';
            app.Label_8 = uilabel(app.RunTab_2);
            app.Label_8.Text = 'Press the button below to start the pre-processing of the SAR images for the PS analysis';
            app.StartButton_2 = uibutton(app.RunTab_2, 'push');
            app.StartButton_2.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton_2.Text = 'Start';
            app.Label_9 = uilabel(app.RunTab_2);
            app.Label_9.Text = 'Execution outputs:';
            app.PreprocessingstatusLamp_2Label = uilabel(app.RunTab_2);
            app.PreprocessingstatusLamp_2Label.Text = 'Preprocessing status';
            app.PreprocessingstatusLamp_2 = uilamp(app.RunTab_2);
            app.MessagesTextAreaLabel = uilabel(app.RunTab_2);
            app.MessagesTextAreaLabel.Text = 'Messages';
            app.MessagesTextArea_2 = uitextarea(app.RunTab_2);
            app.MessagesTextArea_2.Editable = 'off';
            app.StopButton_2 = uibutton(app.RunTab_2, 'push');
            app.StopButton_2.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton_2.Text = 'Stop';
            app.ThecodewillstopattheendofthecurrentstepLabel = uilabel(app.RunTab_2);
            app.ThecodewillstopattheendofthecurrentstepLabel.Text = 'The code will stop at the end of the current step';
            app.Sentinel1Panel = uipanel(app.UIFigure);
            app.Sentinel1Panel.Title = 'Sentinel1';
            app.Sentinel1Panel.Visible = 'off';
            app.TabGroup = uitabgroup(app.Sentinel1Panel);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.DownloadTab = uitab(app.TabGroup);
            app.DownloadTab.Title = 'Download';
            app.DownloaderMapPanel = uipanel(app.DownloadTab);
            app.FilterPanel = uipanel(app.DownloadTab);
            app.FilterPanel.Title = 'Filter';
            app.PathStartEditFieldLabel = uilabel(app.FilterPanel);
            app.PathStartEditFieldLabel.Text = 'Path Start';
            app.PathStartEditField = uieditfield(app.FilterPanel, 'numeric');
            app.PathStartEditField.AllowEmpty = 'on';
            app.PathStartEditField.Value = [];
            app.PathEndEditFieldLabel = uilabel(app.FilterPanel);
            app.PathEndEditFieldLabel.Text = 'Path End';
            app.PathEndEditField = uieditfield(app.FilterPanel, 'numeric');
            app.PathEndEditField.AllowEmpty = 'on';
            app.PathEndEditField.Value = [];
            app.FrameStartEditFieldLabel = uilabel(app.FilterPanel);
            app.FrameStartEditFieldLabel.Text = 'Frame Start';
            app.FrameStartEditField = uieditfield(app.FilterPanel, 'numeric');
            app.FrameStartEditField.AllowEmpty = 'on';
            app.FrameStartEditField.Value = [];
            app.FrameEndEditFieldLabel = uilabel(app.FilterPanel);
            app.FrameEndEditFieldLabel.Text = 'Frame End';
            app.FrameEndEditField = uieditfield(app.FilterPanel, 'numeric');
            app.FrameEndEditField.AllowEmpty = 'on';
            app.FrameEndEditField.Value = [];
            app.DatasetDropDownLabel = uilabel(app.FilterPanel);
            app.DatasetDropDownLabel.Text = 'Dataset';
            app.DatasetDropDown = uidropdown(app.FilterPanel);
            app.DatasetDropDown.Items = {'SENTINEL-1'};
            app.DatasetDropDown.Value = 'SENTINEL-1';
            app.StartdateDatePickerLabel = uilabel(app.FilterPanel);
            app.StartdateDatePickerLabel.Text = 'Start date';
            app.StartdateDatePicker = uidatepicker(app.FilterPanel);
            app.StartdateDatePicker.Limits = [datetime([0 1 1]) datetime([2026 12 31])];
            app.StartdateDatePicker.DisplayFormat = 'dd/MMM/uuuu';
            app.StartdateDatePicker.Placeholder = 'dd/mm/yyyy';
            app.EnddateDatePickerLabel = uilabel(app.FilterPanel);
            app.EnddateDatePickerLabel.Text = 'End date';
            app.EnddateDatePicker = uidatepicker(app.FilterPanel);
            app.EnddateDatePicker.Limits = [datetime([0 1 1]) datetime([2026 12 31])];
            app.EnddateDatePicker.DisplayFormat = 'dd/MMM/uuuu';
            app.EnddateDatePicker.Placeholder = 'dd/mm/yyyy';
            app.ClearPathFrameButton = uibutton(app.FilterPanel, 'push');
            app.ClearPathFrameButton.ButtonPushedFcn = createCallbackFcn(app, @ClearPathFrameButtonPushed, true);
            app.ClearPathFrameButton.Text = 'Clear Path / Frame';
            app.FileTypeLabel = uilabel(app.FilterPanel);
            app.FileTypeLabel.Text = 'File Type';
            app.L1DetectedHighResDualPolGRDHDCheckBox = uicheckbox(app.FilterPanel);
            app.L1DetectedHighResDualPolGRDHDCheckBox.Text = 'L1 Detected High-Res Dual-Pol (GRD-HD)';
            app.L1DetectedMidResDualPolGRDMDCheckBox = uicheckbox(app.FilterPanel);
            app.L1DetectedMidResDualPolGRDMDCheckBox.Text = 'L1 Detected Mid-Res Dual-Pol (GRD-MD)';
            app.L1DetectedMidResSinglePolGRDMSCheckBox = uicheckbox(app.FilterPanel);
            app.L1DetectedMidResSinglePolGRDMSCheckBox.Text = 'L1 Detected Mid-Res Single-Pol (GRD-MS)';
            app.L1DetectedHighResSinglePolGRDHSCheckBox = uicheckbox(app.FilterPanel);
            app.L1DetectedHighResSinglePolGRDHSCheckBox.Text = 'L1 Detected High-Res Single-Pol (GRD-HS)';
            app.L1SingleLookComplexSLCCheckBox = uicheckbox(app.FilterPanel);
            app.L1SingleLookComplexSLCCheckBox.Text = 'L1 Single Look Complex (SLC)';
            app.L2OceanOCNCheckBox = uicheckbox(app.FilterPanel);
            app.L2OceanOCNCheckBox.Text = 'L2 Ocean (OCN)';
            app.L0RawDataRAWCheckBox = uicheckbox(app.FilterPanel);
            app.L0RawDataRAWCheckBox.Text = 'L0 Raw Data (RAW)';
            app.XMLMetadataGRDMSCheckBox = uicheckbox(app.FilterPanel);
            app.XMLMetadataGRDMSCheckBox.Text = 'XML Metadata (GRD-MS)';
            app.XMLMetadataGRDHDCheckBox = uicheckbox(app.FilterPanel);
            app.XMLMetadataGRDHDCheckBox.Text = 'XML Metadata (GRD-HD)';
            app.XMLMetadataRAWCheckBox = uicheckbox(app.FilterPanel);
            app.XMLMetadataRAWCheckBox.Text = 'XML Metadata (RAW)';
            app.XMLMetadataGRDHSCheckBox = uicheckbox(app.FilterPanel);
            app.XMLMetadataGRDHSCheckBox.Text = 'XML Metadata (GRD-HS)';
            app.XMLMetadataGRDMDCheckBox = uicheckbox(app.FilterPanel);
            app.XMLMetadataGRDMDCheckBox.Text = 'XML Metadata (GRD-MD)';
            app.XMLMetadataSLCCheckBox = uicheckbox(app.FilterPanel);
            app.XMLMetadataSLCCheckBox.Text = 'XML Metadata (SLC)';
            app.XMLMetadataOCNCheckBox = uicheckbox(app.FilterPanel);
            app.XMLMetadataOCNCheckBox.Text = 'XML Metadata (OCN)';
            app.OrbitDirectionLabel = uilabel(app.FilterPanel);
            app.OrbitDirectionLabel.Text = 'Orbit Direction';
            app.ASCENDINGCheckBox = uicheckbox(app.FilterPanel);
            app.ASCENDINGCheckBox.Text = 'ASCENDING';
            app.DESCENDINGCheckBox = uicheckbox(app.FilterPanel);
            app.DESCENDINGCheckBox.Text = 'DESCENDING';
            app.BeamModeLabel = uilabel(app.FilterPanel);
            app.BeamModeLabel.Text = 'Beam Mode';
            app.IWCheckBox = uicheckbox(app.FilterPanel);
            app.IWCheckBox.Text = 'IW';
            app.EWCheckBox = uicheckbox(app.FilterPanel);
            app.EWCheckBox.Text = 'EW';
            app.S1CheckBox = uicheckbox(app.FilterPanel);
            app.S1CheckBox.Text = 'S1';
            app.S2CheckBox = uicheckbox(app.FilterPanel);
            app.S2CheckBox.Text = 'S2';
            app.S3CheckBox = uicheckbox(app.FilterPanel);
            app.S3CheckBox.Text = 'S3';
            app.S4CheckBox = uicheckbox(app.FilterPanel);
            app.S4CheckBox.Text = 'S4';
            app.S5CheckBox = uicheckbox(app.FilterPanel);
            app.S5CheckBox.Text = 'S5';
            app.S6CheckBox = uicheckbox(app.FilterPanel);
            app.S6CheckBox.Text = 'S6';
            app.WVCheckBox = uicheckbox(app.FilterPanel);
            app.WVCheckBox.Text = 'WV';
            app.PolarizationLabel = uilabel(app.FilterPanel);
            app.PolarizationLabel.Text = 'Polarization';
            app.VVCheckBox = uicheckbox(app.FilterPanel);
            app.VVCheckBox.Text = 'VV';
            app.HHCheckBox = uicheckbox(app.FilterPanel);
            app.HHCheckBox.Text = 'HH';
            app.VVHHCheckBox = uicheckbox(app.FilterPanel);
            app.VVHHCheckBox.Text = 'VV+HH';
            app.HHHVCheckBox = uicheckbox(app.FilterPanel);
            app.HHHVCheckBox.Text = 'HH+HV';
            app.DualHHCheckBox = uicheckbox(app.FilterPanel);
            app.DualHHCheckBox.Text = 'Dual HH';
            app.DualHVCheckBox = uicheckbox(app.FilterPanel);
            app.DualHVCheckBox.Text = 'Dual HV';
            app.DualVHCheckBox = uicheckbox(app.FilterPanel);
            app.DualVHCheckBox.Text = 'Dual VH';
            app.DualVVCheckBox = uicheckbox(app.FilterPanel);
            app.DualVVCheckBox.Text = 'Dual VV';
            app.SubtypeLabel = uilabel(app.FilterPanel);
            app.SubtypeLabel.Text = 'Subtype';
            app.SACheckBox = uicheckbox(app.FilterPanel);
            app.SACheckBox.Text = 'SA';
            app.SBCheckBox = uicheckbox(app.FilterPanel);
            app.SBCheckBox.Text = 'SB';
            app.SCCheckBox = uicheckbox(app.FilterPanel);
            app.SCCheckBox.Text = 'SC';
            app.SDCheckBox = uicheckbox(app.FilterPanel);
            app.SDCheckBox.Text = 'SD';
            app.GroupIDEditFieldLabel = uilabel(app.FilterPanel);
            app.GroupIDEditFieldLabel.Text = 'Group ID';
            app.GroupIDEditField = uieditfield(app.FilterPanel, 'numeric');
            app.GroupIDEditField.AllowEmpty = 'on';
            app.GroupIDEditField.Value = [];
            app.ResetallfiltersButton = uibutton(app.FilterPanel, 'push');
            app.ResetallfiltersButton.ButtonPushedFcn = createCallbackFcn(app, @ResetallfiltersButtonPushed, true);
            app.ResetallfiltersButton.Text = 'Reset all filters';
            app.SamplingRateEditFieldLabel = uilabel(app.FilterPanel);
            app.SamplingRateEditFieldLabel.Text = 'Sampling Rate';
            app.SamplingRateEditField = uieditfield(app.FilterPanel, 'numeric');
            app.SamplingUnitDropDown = uidropdown(app.FilterPanel);
            app.SamplingUnitDropDown.Items = {'Week', 'Month', 'Year'};
            app.SamplingUnitDropDown.Value = 'Month';
            app.MinLongitudeEditFieldLabel = uilabel(app.DownloadTab);
            app.MinLongitudeEditFieldLabel.Text = 'Min Longitude';
            app.MinLongitudeEditField = uieditfield(app.DownloadTab, 'numeric');
            app.MinLongitudeEditField.ValueChangedFcn = createCallbackFcn(app, @MinLongitudeEditFieldValueChanged, true);
            app.MaxLongitudeEditFieldLabel = uilabel(app.DownloadTab);
            app.MaxLongitudeEditFieldLabel.Text = 'Max Longitude';
            app.MaxLongitudeEditField = uieditfield(app.DownloadTab, 'numeric');
            app.MaxLongitudeEditField.ValueChangedFcn = createCallbackFcn(app, @MaxLongitudeEditFieldValueChanged, true);
            app.MinLatitudeEditFieldLabel = uilabel(app.DownloadTab);
            app.MinLatitudeEditFieldLabel.Text = 'Min Latitude';
            app.MinLatitudeEditField = uieditfield(app.DownloadTab, 'numeric');
            app.MinLatitudeEditField.ValueChangedFcn = createCallbackFcn(app, @MinLatitudeEditFieldValueChanged, true);
            app.MaxLatitudeEditFieldLabel = uilabel(app.DownloadTab);
            app.MaxLatitudeEditFieldLabel.Text = 'Max Latitude';
            app.MaxLatitudeEditField = uieditfield(app.DownloadTab, 'numeric');
            app.MaxLatitudeEditField.ValueChangedFcn = createCallbackFcn(app, @MaxLatitudeEditFieldValueChanged, true);
            app.EarthdataUsernameEditField = uieditfield(app.DownloadTab, 'text');
            app.EarthdataUsernameEditField.Placeholder = 'Username';
            app.EarthdataPasswordEditField = uieditfield(app.DownloadTab, 'text');
            app.EarthdataPasswordEditField.Placeholder = 'Password';
            app.LoginButton = uibutton(app.DownloadTab, 'push');
            app.LoginButton.ButtonPushedFcn = createCallbackFcn(app, @LoginButtonPushed, true);
            app.LoginButton.Text = 'Login';
            app.LoginFeedbackLabel = uilabel(app.DownloadTab);
            app.LoginFeedbackLabel.Text = '';
            app.SignedInLabel = uilabel(app.DownloadTab);
            app.SignedInLabel.Text = '';
            app.SignOutButton = uibutton(app.DownloadTab, 'push');
            app.SignOutButton.ButtonPushedFcn = createCallbackFcn(app, @SignOutButtonPushed, true);
            app.SignOutButton.Text = 'Sign Out';
            app.DownloaderToolbarPanel = uipanel(app.DownloadTab);
            app.ShowFiltersButton = uibutton(app.DownloaderToolbarPanel, 'push');
            app.ShowFiltersButton.ButtonPushedFcn = createCallbackFcn(app, @ShowFiltersButtonPushed, true);
            app.ShowFiltersButton.Text = 'Show Filters';
            app.DrawRectangleButton = uibutton(app.DownloaderToolbarPanel, 'push');
            app.DrawRectangleButton.ButtonPushedFcn = createCallbackFcn(app, @DrawRectangleButtonPushed, true);
            app.DrawRectangleButton.Text = 'Draw Rectangle';
            app.DrawPolygonButton = uibutton(app.DownloaderToolbarPanel, 'push');
            app.DrawPolygonButton.ButtonPushedFcn = createCallbackFcn(app, @DrawPolygonButtonPushed, true);
            app.DrawPolygonButton.Text = 'Draw Polygon';
            app.LoadShapefileButton = uibutton(app.DownloaderToolbarPanel, 'push');
            app.LoadShapefileButton.Text = 'Load Shapefile';
            app.ClearAOIButton = uibutton(app.DownloaderToolbarPanel, 'push');
            app.ClearAOIButton.ButtonPushedFcn = createCallbackFcn(app, @ClearAOIButtonPushed, true);
            app.ClearAOIButton.Text = 'Clear AOI';
            app.SearchASFButton = uibutton(app.DownloaderToolbarPanel, 'push');
            app.SearchASFButton.ButtonPushedFcn = createCallbackFcn(app, @SearchASFButtonPushed, true);
            app.SearchASFButton.Text = 'Search ASF';
            app.LoadLastDownloadButton = uibutton(app.DownloaderToolbarPanel, 'push');
            app.LoadLastDownloadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadLastDownloadButtonPushed, true);
            app.LoadLastDownloadButton.Text = 'Load Last Download';
            app.GlobalVariablesTab_3 = uitab(app.TabGroup);
            app.GlobalVariablesTab_3.Title = 'Global Variables';
            app.PythonEnvironmentLabel = uilabel(app.GlobalVariablesTab_3);
            app.PythonEnvironmentLabel.Text = 'Custom Python Environment:';
            app.CustomPythonEnvironmentEditField = uieditfield(app.GlobalVariablesTab_3, 'text');
            app.CustomPythonEnvironmentEditField.ValueChangedFcn = createCallbackFcn(app, @CustomPythonEnvironmentEditFieldValueChanged, true);
            app.CustomPythonEnvironmentEditField.Value = 'python';
            app.Label_2 = uilabel(app.GlobalVariablesTab_3);
            app.Label_2.Text = 'Name of the python 3.x environment in your os';
            app.PythonEnvironmentDropDownLabel = uilabel(app.GlobalVariablesTab_3);
            app.PythonEnvironmentDropDownLabel.Text = 'Python Environment';
            app.PythonEnvironmentDropDown = uidropdown(app.GlobalVariablesTab_3);
            app.PythonEnvironmentDropDown.Items = {'python', 'python3', 'python3.11', 'Other'};
            app.PythonEnvironmentDropDown.ValueChangedFcn = createCallbackFcn(app, @PythonEnvironmentDropDownValueChanged, true);
            app.PythonEnvironmentDropDown.Value = 'python';
            app.SARImagesDownloadCheckBox = uicheckbox(app.GlobalVariablesTab_3);
            app.SARImagesDownloadCheckBox.ValueChangedFcn = createCallbackFcn(app, @SARImagesDownloadCheckBoxValueChanged, true);
            app.SARImagesDownloadCheckBox.Text = 'SAR Images Download';
            app.AOITab_2 = uitab(app.TabGroup);
            app.AOITab_2.Title = 'AOI';
            app.MinlongitudeEditFieldLabel = uilabel(app.AOITab_2);
            app.MinlongitudeEditFieldLabel.Text = 'Min longitude';
            app.MinlongitudeEditField = uieditfield(app.AOITab_2, 'numeric');
            app.MinlongitudeEditField.Limits = [-180 180];
            app.MinlongitudeEditField.ValueDisplayFormat = '%.3f';
            app.MinlongitudeEditField.ValueChangedFcn = createCallbackFcn(app, @MinlongitudeEditFieldValueChanged, true);
            app.MinlongitudeEditField.Editable = 'off';
            app.MinlongitudeEditField.Value = -180;
            app.MaxlongitudeEditFieldLabel = uilabel(app.AOITab_2);
            app.MaxlongitudeEditFieldLabel.Text = 'Max longitude';
            app.MaxlongitudeEditField = uieditfield(app.AOITab_2, 'numeric');
            app.MaxlongitudeEditField.Limits = [-180 180];
            app.MaxlongitudeEditField.ValueDisplayFormat = '%.3f';
            app.MaxlongitudeEditField.ValueChangedFcn = createCallbackFcn(app, @MaxlongitudeEditFieldValueChanged, true);
            app.MaxlongitudeEditField.Editable = 'off';
            app.MaxlongitudeEditField.Value = 180;
            app.MinlatitudeEditFieldLabel = uilabel(app.AOITab_2);
            app.MinlatitudeEditFieldLabel.Text = 'Min latitude';
            app.MinlatitudeEditField = uieditfield(app.AOITab_2, 'numeric');
            app.MinlatitudeEditField.Limits = [-90 90];
            app.MinlatitudeEditField.ValueDisplayFormat = '%.3f';
            app.MinlatitudeEditField.ValueChangedFcn = createCallbackFcn(app, @MinlatitudeEditFieldValueChanged, true);
            app.MinlatitudeEditField.Editable = 'off';
            app.MinlatitudeEditField.Value = -90;
            app.MaxlatitudeEditFieldLabel = uilabel(app.AOITab_2);
            app.MaxlatitudeEditFieldLabel.Text = 'Max latitude';
            app.MaxlatitudeEditField = uieditfield(app.AOITab_2, 'numeric');
            app.MaxlatitudeEditField.Limits = [-90 90];
            app.MaxlatitudeEditField.ValueDisplayFormat = '%.3f';
            app.MaxlatitudeEditField.ValueChangedFcn = createCallbackFcn(app, @MaxlatitudeEditFieldValueChanged, true);
            app.MaxlatitudeEditField.Editable = 'off';
            app.MaxlatitudeEditField.Value = 90;
            app.AOIboxboundariesLabel_3 = uilabel(app.AOITab_2);
            app.AOIboxboundariesLabel_3.Text = 'AOI box boundaries';
            app.ResetSENMapButton = uibutton(app.AOITab_2, 'push');
            app.ResetSENMapButton.ButtonPushedFcn = createCallbackFcn(app, @ResetSENMapButtonPushed, true);
            app.ResetSENMapButton.Text = 'Reset Map';
            app.SENMapPanel = uipanel(app.AOITab_2);
            app.MasterProcessingTab = uitab(app.TabGroup);
            app.MasterProcessingTab.Title = 'Master Processing';
            app.MasterdateDatePickerLabel = uilabel(app.MasterProcessingTab);
            app.MasterdateDatePickerLabel.Text = 'Master date';
            app.MasterdateDatePicker = uidatepicker(app.MasterProcessingTab);
            app.MasterdateDatePicker.ValueChangedFcn = createCallbackFcn(app, @MasterdateDatePickerValueChanged, true);
            app.MasterdateDatePicker.Enable = 'off';
            app.MasterdateDatePicker.Value = datetime([2020 3 5]);
            app.MasterprocessingCheckBox = uicheckbox(app.MasterProcessingTab);
            app.MasterprocessingCheckBox.ValueChangedFcn = createCallbackFcn(app, @MasterprocessingCheckBoxValueChanged, true);
            app.MasterprocessingCheckBox.Text = 'Master processing';
            app.MasterprocessingCheckBox.Value = true;
            app.PolarisationDropDownLabel = uilabel(app.MasterProcessingTab);
            app.PolarisationDropDownLabel.Text = 'Polarisation';
            app.PolarisationDropDown = uidropdown(app.MasterProcessingTab);
            app.PolarisationDropDown.Items = {'VV', 'VH', 'HH'};
            app.PolarisationDropDown.ValueChangedFcn = createCallbackFcn(app, @PolarisationDropDownValueChanged, true);
            app.PolarisationDropDown.Value = 'VV';
            app.AutoMasterCheckBox = uicheckbox(app.MasterProcessingTab);
            app.AutoMasterCheckBox.ValueChangedFcn = createCallbackFcn(app, @AutoMasterCheckBoxValueChanged, true);
            app.AutoMasterCheckBox.Text = 'Auto-select master';
            app.AutoMasterCheckBox.Value = true;
            app.SlavesProcessingTab = uitab(app.TabGroup);
            app.SlavesProcessingTab.Title = 'Slaves Processing';
            app.SlavesremovalafterprocessingCheckBox = uicheckbox(app.SlavesProcessingTab);
            app.SlavesremovalafterprocessingCheckBox.ValueChangedFcn = createCallbackFcn(app, @SlavesremovalafterprocessingCheckBoxValueChanged, true);
            app.SlavesremovalafterprocessingCheckBox.Text = 'Slaves removal after processing';
            app.DEMinterferogramDropDownLabel = uilabel(app.SlavesProcessingTab);
            app.DEMinterferogramDropDownLabel.Text = 'DEM interferogram';
            app.DEMinterferogramDropDown = uidropdown(app.SlavesProcessingTab);
            app.DEMinterferogramDropDown.Items = {'SRTM 1Sec HGT', 'SRTM 3Sec', 'Copernicus 30m Global DEM', 'Copernicus 90m Global DEM', 'CDEM', 'GETASSE30', 'External DEM'};
            app.DEMinterferogramDropDown.ValueChangedFcn = createCallbackFcn(app, @DEMinterferogramDropDownValueChanged, true);
            app.DEMinterferogramDropDown.Value = 'SRTM 1Sec HGT';
            app.DEMcoregistrationDropDownLabel = uilabel(app.SlavesProcessingTab);
            app.DEMcoregistrationDropDownLabel.Text = 'DEM coregistration';
            app.DEMcoregistrationDropDown = uidropdown(app.SlavesProcessingTab);
            app.DEMcoregistrationDropDown.Items = {'SRTM 1Sec HGT', 'SRTM 3Sec', 'Copernicus 30m Global DEM', 'Copernicus 90m Global DEM', 'CDEM', 'GETASSE30', 'External DEM'};
            app.DEMcoregistrationDropDown.ValueChangedFcn = createCallbackFcn(app, @DEMcoregistrationDropDownValueChanged, true);
            app.DEMcoregistrationDropDown.Value = 'SRTM 1Sec HGT';
            app.DEMifgpathEditFieldLabel = uilabel(app.SlavesProcessingTab);
            app.DEMifgpathEditFieldLabel.Text = 'DEM ifg path';
            app.DEMifgpathEditField = uieditfield(app.SlavesProcessingTab, 'text');
            app.DEMifgpathEditField.ValueChangedFcn = createCallbackFcn(app, @DEMifgpathEditFieldValueChanged, true);
            app.DEMcoregpathEditFieldLabel = uilabel(app.SlavesProcessingTab);
            app.DEMcoregpathEditFieldLabel.Text = 'DEM coreg path';
            app.DEMcoregpathEditField = uieditfield(app.SlavesProcessingTab, 'text');
            app.DEMcoregpathEditField.ValueChangedFcn = createCallbackFcn(app, @DEMcoregpathEditFieldValueChanged, true);
            app.DEMresamplingmethodDropDownLabel = uilabel(app.SlavesProcessingTab);
            app.DEMresamplingmethodDropDownLabel.Text = 'DEM resampling method';
            app.DEMresamplingmethodDropDown = uidropdown(app.SlavesProcessingTab);
            app.DEMresamplingmethodDropDown.Items = {'NEAREST_NEIGHBOUR', 'BILINEAR_INTERPOLATION', 'CUBIC_CONVOLUTION', 'BISINC_5_POINT_INTERPOLATION', 'BISINC_11_POINT_INTERPOLATION', 'BISINC_21_POINT_INTERPOLATION', 'BICUBIC_INTERPOLATION'};
            app.DEMresamplingmethodDropDown.ValueChangedFcn = createCallbackFcn(app, @DEMresamplingmethodDropDownValueChanged, true);
            app.DEMresamplingmethodDropDown.Value = 'NEAREST_NEIGHBOUR';
            app.onlyforExternalDEMLabel = uilabel(app.SlavesProcessingTab);
            app.onlyforExternalDEMLabel.Text = '(only for External DEM)';
            app.onlyforExternalDEMLabel_2 = uilabel(app.SlavesProcessingTab);
            app.onlyforExternalDEMLabel_2.Text = '(only for External DEM)';
            app.FirststepDropDownLabel = uilabel(app.SlavesProcessingTab);
            app.FirststepDropDownLabel.Text = 'First step';
            app.FirststepDropDown = uidropdown(app.SlavesProcessingTab);
            app.FirststepDropDown.Items = {'1', '2', '3', '4', '5', '6'};
            app.FirststepDropDown.ValueChangedFcn = createCallbackFcn(app, @FirststepDropDownValueChanged, true);
            app.FirststepDropDown.Value = '1';
            app.BrowseButton = uibutton(app.SlavesProcessingTab, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse';
            app.BrowseButton_2 = uibutton(app.SlavesProcessingTab, 'push');
            app.BrowseButton_2.ButtonPushedFcn = createCallbackFcn(app, @BrowseButton_2Pushed, true);
            app.BrowseButton_2.Text = 'Browse';
            app.CoherenceandLIATab = uitab(app.TabGroup);
            app.CoherenceandLIATab.Title = 'Coherence and LIA';
            app.TerraincorrectedCoherenceandLIACheckBox = uicheckbox(app.CoherenceandLIATab);
            app.TerraincorrectedCoherenceandLIACheckBox.ValueChangedFcn = createCallbackFcn(app, @TerraincorrectedCoherenceandLIACheckBoxValueChanged, true);
            app.TerraincorrectedCoherenceandLIACheckBox.Text = 'Terrain-corrected Coherence and LIA';
            app.TerraincorrectedCoherenceandLIACheckBox.Value = true;
            app.EPSGcodeEditFieldLabel = uilabel(app.CoherenceandLIATab);
            app.EPSGcodeEditFieldLabel.Text = 'EPSG code';
            app.EPSGcodeEditField = uieditfield(app.CoherenceandLIATab, 'numeric');
            app.EPSGcodeEditField.Limits = [0 99999];
            app.EPSGcodeEditField.ValueDisplayFormat = '%.0f';
            app.EPSGcodeEditField.ValueChangedFcn = createCallbackFcn(app, @EPSGcodeEditFieldValueChanged, true);
            app.EPSGcodeEditField.Value = 32633;
            app.Label_3 = uilabel(app.CoherenceandLIATab);
            app.Label_3.Text = 'EPSG code of the cartographic projection of the terrain-corrected .TIFF files (choose accordingly to the location)';
            app.ComputationalResourcesTab = uitab(app.TabGroup);
            app.ComputationalResourcesTab.Title = 'Computational Resources';
            app.FullpathtoSNAPgptfolderLabel = uilabel(app.ComputationalResourcesTab);
            app.FullpathtoSNAPgptfolderLabel.Text = 'Full path to SNAP gpt folder';
            app.PathEditFieldLabel = uilabel(app.ComputationalResourcesTab);
            app.PathEditFieldLabel.Text = 'Path';
            app.PathEditField = uieditfield(app.ComputationalResourcesTab, 'text');
            app.PathEditField.ValueChangedFcn = createCallbackFcn(app, @PathEditFieldValueChanged, true);
            app.PathEditField.Value = 'C:\Program Files\snap\bin\gpt';
            app.NumberofcorestobeusedintheprocessingLabel = uilabel(app.ComputationalResourcesTab);
            app.NumberofcorestobeusedintheprocessingLabel.Text = 'Number of cores to be used in the processing';
            app.CPUEditFieldLabel = uilabel(app.ComputationalResourcesTab);
            app.CPUEditFieldLabel.Text = 'CPU';
            app.CPUEditField = uieditfield(app.ComputationalResourcesTab, 'numeric');
            app.CPUEditField.Limits = [0 1000];
            app.CPUEditField.RoundFractionalValues = 'on';
            app.CPUEditField.ValueDisplayFormat = '%.0f';
            app.CPUEditField.ValueChangedFcn = createCallbackFcn(app, @CPUEditFieldValueChanged, true);
            app.CPUEditField.Value = 8;
            app.RAMtobeusedintheprocessingGBformatnnGLabel = uilabel(app.ComputationalResourcesTab);
            app.RAMtobeusedintheprocessingGBformatnnGLabel.Text = 'RAM to be used in the processing [GB] (format "nnG")';
            app.CacheEditFieldLabel = uilabel(app.ComputationalResourcesTab);
            app.CacheEditFieldLabel.Text = 'Cache';
            app.CacheEditField = uieditfield(app.ComputationalResourcesTab, 'text');
            app.CacheEditField.ValueChangedFcn = createCallbackFcn(app, @CacheEditFieldValueChanged, true);
            app.CacheEditField.Value = '26G';
            app.SaveLoadTab = uitab(app.TabGroup);
            app.SaveLoadTab.Title = 'Save/Load';
            app.SaveButton = uibutton(app.SaveLoadTab, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.SavetheconfiguredparametersfortheInSARpreprocessingLabel = uilabel(app.SaveLoadTab);
            app.SavetheconfiguredparametersfortheInSARpreprocessingLabel.Text = 'Save the configured parameters for the InSAR pre-processing';
            app.StatusLampLabel = uilabel(app.SaveLoadTab);
            app.StatusLampLabel.Text = 'Status';
            app.StatusLamp = uilamp(app.SaveLoadTab);
            app.SavetheconfiguredparametersfortheInSARpreprocessingLabel_3 = uilabel(app.SaveLoadTab);
            app.SavetheconfiguredparametersfortheInSARpreprocessingLabel_3.Text = 'Load the parameters for the InSAR pre-processing from the already existing .mat file';
            app.LoadButton = uibutton(app.SaveLoadTab, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.StatusLamp_3Label = uilabel(app.SaveLoadTab);
            app.StatusLamp_3Label.Text = 'Status';
            app.StatusLamp_3 = uilamp(app.SaveLoadTab);
            app.RunTab = uitab(app.TabGroup);
            app.RunTab.Title = 'Run';
            app.Label_6 = uilabel(app.RunTab);
            app.Label_6.Text = 'Press the button below to start the pre-processing of the SAR images for the PS analysis';
            app.StartButton = uibutton(app.RunTab, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.Label_7 = uilabel(app.RunTab);
            app.Label_7.Text = 'Execution outputs:';
            app.PreprocessingstatusLampLabel = uilabel(app.RunTab);
            app.PreprocessingstatusLampLabel.Text = 'Preprocessing status';
            app.PreprocessingstatusLamp = uilamp(app.RunTab);
            app.MessagesTextArea_3Label = uilabel(app.RunTab);
            app.MessagesTextArea_3Label.Text = 'Messages';
            app.MessagesTextArea = uitextarea(app.RunTab);
            app.StopButton = uibutton(app.RunTab, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.ThecodewillstopattheendofthecurrentstepLabel_2 = uilabel(app.RunTab);
            app.ThecodewillstopattheendofthecurrentstepLabel_2.Text = 'The code will stop at the end of the current step';
            app.ConstellationSwitch = uiswitch(app.UIFigure, 'slider');
            app.ConstellationSwitch.Items = {'Sentinel1', 'CosmoSkyMed'};
            app.ConstellationSwitch.ValueChangedFcn = createCallbackFcn(app, @ConstellationSwitchValueChanged, true);
            app.ConstellationSwitch.Value = 'Sentinel1';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'Move the switch to match the satellite constellation you want to process the data';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'PHASE_logo.png');
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'PHASE_mod1a.png');
            app.ContextMenu = uicontextmenu(app.UIFigure);
            app.Menu = uimenu(app.ContextMenu);
            app.Menu.Text = 'Menu';
            app.Menu2 = uimenu(app.ContextMenu);
            app.Menu2.Text = 'Menu2';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PHASE_Preprocessing

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end